classdef CCFregister < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExportCCFButton             matlab.ui.control.Button
        WarpsPanel                  matlab.ui.container.Panel
        bsplineWarpButton           matlab.ui.control.Button
        AffineWarpButton            matlab.ui.control.Button
        RotateCCF1stStepPanel       matlab.ui.container.Panel
        RotateCCFVolumeButton       matlab.ui.control.Button
        YawSpinner                  matlab.ui.control.Spinner
        BlockingcutyawLabel         matlab.ui.control.Label
        PitchSpinner                matlab.ui.control.Spinner
        BlockingcutpitchLabel       matlab.ui.control.Label
        FiducialsPanel              matlab.ui.container.Panel
        SavePointsButton            matlab.ui.control.Button
        DeletePointButton           matlab.ui.control.Button
        AddPointButton              matlab.ui.control.Button
        LoadStateButton             matlab.ui.control.Button
        SaveStateButton             matlab.ui.control.Button
        MessageBoxTextArea          matlab.ui.control.TextArea
        MessageBoxTextAreaLabel     matlab.ui.control.Label
        CCFSettingsPanel            matlab.ui.container.Panel
        OverlayAnnoCheckBox         matlab.ui.control.CheckBox
        CCFtt                       matlab.ui.control.TextArea
        SelectCCFVolumeButtonGroup  matlab.ui.container.ButtonGroup
        AverageTemplateButton       matlab.ui.control.RadioButton
        AnnotationBoundaryButton    matlab.ui.control.RadioButton
        AnnotationButton            matlab.ui.control.RadioButton
        ColorCCFbyButtonGroup       matlab.ui.container.ButtonGroup
        SubstructureButton          matlab.ui.control.RadioButton
        StructureButton             matlab.ui.control.RadioButton
        DivisionButton              matlab.ui.control.RadioButton
        CategoryButton              matlab.ui.control.RadioButton
        OrganButton                 matlab.ui.control.RadioButton
        CCFPanel                    matlab.ui.container.Panel
        DisplayWarpCheckBox         matlab.ui.control.CheckBox
        CropCCFPanel                matlab.ui.container.Panel
        UndoCropButton              matlab.ui.control.Button
        CROPButton                  matlab.ui.control.Button
        ZMaxLamp                    matlab.ui.control.Lamp
        ZMinLamp                    matlab.ui.control.Lamp
        XYLamp                      matlab.ui.control.Lamp
        SetZMaxButton               matlab.ui.control.Button
        SetZMinButton               matlab.ui.control.Button
        SetXYButton                 matlab.ui.control.Button
        CCFZLabel                   matlab.ui.control.Label
        IncCCFMinusButton           matlab.ui.control.Button
        IncCCFPlusButton            matlab.ui.control.Button
        CCFZSlider                  matlab.ui.control.Slider
        CCFaxes                     matlab.ui.control.UIAxes
        pointsTable                 matlab.ui.control.Table
        SamplePanel                 matlab.ui.container.Panel
        LRshouldbeAPLabel           matlab.ui.control.Label
        FlipSampleZButton           matlab.ui.control.Button
        MatchZCCFButton             matlab.ui.control.Button
        OverlayCCFCheckBox          matlab.ui.control.CheckBox
        ProcessPanel                matlab.ui.container.Panel
        FiducialsLamp               matlab.ui.control.Lamp
        FiducialsLampLabel          matlab.ui.control.Label
        CropLamp                    matlab.ui.control.Lamp
        CropLampLabel               matlab.ui.control.Label
        RotateLamp                  matlab.ui.control.Lamp
        RotateLabel                 matlab.ui.control.Label
        ChannelsListBox             matlab.ui.control.ListBox
        ChannelsListBoxLabel        matlab.ui.control.Label
        SampleZLabel                matlab.ui.control.Label
        IncSampleMinusButton        matlab.ui.control.Button
        IncSamplePlusButton         matlab.ui.control.Button
        SampleZSlider               matlab.ui.control.Slider
        SampleImageParamsPanel      matlab.ui.container.Panel
        AutoSetLimitsCheckBox       matlab.ui.control.CheckBox
        MaxEditField                matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        MinEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        SampleAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        abcDir % ABC atlas directory
        imgDir % Allen CCF directory
        colorDir % Allen CCF colors directory
        ccfColors % path to Allen CCF colors csv
        ccfTerms % path to Allen CCF parcellation terms csv
        Tcolors % table that stores Allen CCF color data
        Tterms % table that stores Allen CCF parcellation terms data
        colorsArray % current Allen CCF colormap

        anno_orig
        anno_bound_orig
        avg_temp_orig
        anno
        anno_bound
        avg_temp
        anno_trans
        anno_bound_trans
        avg_temp_trans

        ttOrgan
        ttCategory
        ttDivision
        ttStructure
        ttSubstructure
        CCFmarker
        
        CropXYRect
        CropZMin
        CropZMax
        cuboid
        
        sampleDir
        im
        im_info
        samp_im % sample image object
        CCF_im  % CCF image object

        zCCF
        zSamp

        pointsROI           % struct containing ROI objects for each movable point
        selectedPointsRow   % index of selected row in points table

        fiducialPlottingRangeSamp = 5 % maximum number of z-planes away previously marked
                                      % fiducials will be plotted in
                                      % sample image
        fiducialPlottingRangeCCF = 0  % maximum number of z-planes away previously marked
                                      % fiducials will be plotted in CCF

        sampFids % scatter object that corresponds to previously marked fiducials currently displayed in sample image
        ccfFids  % scatter object that corresponds to previously marked fiducials currently displayed ccf image
        sampOverlayFids % scatter object that corresponds to CCF fiducials currently displayed in sample image
        
        appState
        pointsData          % struct containing coordinates of each fiducial, 3 fields

        hasRotate
        hasCrop
        hasAf

        af % affine transformation matrix

        parent_app % (HCRprocess)
        
    end
    
    methods (Access = private)
        
        function loadCCFvol(app)

            function vol = rotateVol(vol)
                vol = imrotate3(vol,-90,[0 0 1]);
                vol = imrotate3(vol,-90,[0 1 0]);
            end
            
            app.anno = niftiread(fullfile(app.imgDir,'annotation_10.nii'));
            app.anno_bound = niftiread(fullfile(app.imgDir,'annotation_boundary_10.nii'));
            app.avg_temp = niftiread(fullfile(app.imgDir,'average_template_10.nii'));

            app.anno = rotateVol(app.anno);
            app.anno_bound = rotateVol(app.anno_bound);
            app.avg_temp = rotateVol(app.avg_temp);

            app.anno_orig = app.anno;
            app.anno_bound_orig = app.anno_bound;
            app.avg_temp_orig = app.avg_temp;
            
        end
        
        % The Allen CCF annotation volume is an indexed image that is
        % missing a lot of indices. This builds an appropriately sized
        % colormap with the missing indices filled in with black
        function buildColorsArray(app, level)

            maxParcInd = max(app.Tcolors.parcellation_index);
            app.colorsArray = zeros(maxParcInd + 1,3);

            for i=1:size(app.Tcolors,1)
                colorsArrayInd = uint16(app.Tcolors.parcellation_index(i)) + 1;
                app.colorsArray(colorsArrayInd,:) = hex2rgb(app.Tcolors{i,level});
            end

        end

        function setCCFcolormap(app)
            
            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    switch app.ColorCCFbyButtonGroup.SelectedObject.Text
                        case 'Organ'
                            app.buildColorsArray('organ_color');
                        case 'Category'
                            app.buildColorsArray('category_color');
                        case 'Division'
                            app.buildColorsArray('division_color');
                        case 'Structure'
                            app.buildColorsArray('structure_color');
                        case 'Substructure'
                            app.buildColorsArray('substructure_color');
                    end

                case 'Annotation Boundary'

                    app.colorsArray = gray(255);

                case 'Average Template'

                    app.colorsArray = gray(517);

            end

        end

        function initializeCCFvol(app)

            if app.DisplayWarpCheckBox.Value == true
                X = app.anno_trans(:,:,app.zCCF); 
            else
                X = app.anno(:,:,app.zCCF); 
            end

            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            RGB = ind2rgb(X,app.colorsArray);

            if app.OverlayAnnoCheckBox.Value == true
                if app.DisplayWarpCheckBox.Value == true
                    BW = imbinarize(app.anno_bound_trans(:,:,app.zCCF),0);
                else
                    BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                end
                RGB = imoverlay(RGB,BW,"black");
            end
            
            app.CCF_im = imshow(RGB,Parent=app.CCFaxes);
            set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)
            app.CCF_im.AlphaData = AlphaData;
            
            if app.DisplayWarpCheckBox.Value == true
                app.updateZLabel(app.CCFZLabel,app.zCCF,app.anno_trans)
            else
                app.updateZLabel(app.CCFZLabel,app.zCCF,app.anno) 
            end
            

        end

        function updateCCFvol(app)

            if app.DisplayWarpCheckBox.Value == true
                X = app.anno_trans(:,:,app.zCCF); 
            else
                X = app.anno(:,:,app.zCCF); 
            end

            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    RGB = ind2rgb(X,app.colorsArray);

                    if app.OverlayAnnoCheckBox.Value == true
                        if app.DisplayWarpCheckBox.Value == true
                            BW = imbinarize(app.anno_bound_trans(:,:,app.zCCF),0);
                        else
                            BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                        end
                        RGB = imoverlay(RGB,BW,"black");
                    end
                    
                    app.CCF_im.CData = RGB;

                case 'Annotation Boundary'

                    if app.DisplayWarpCheckBox.Value == true
                        app.CCF_im.CData = app.anno_bound_trans(:,:,app.zCCF);
                    else
                        app.CCF_im.CData = app.anno_bound(:,:,app.zCCF);
                    end

                case 'Average Template'

                    if app.DisplayWarpCheckBox.Value == true
                        app.CCF_im.CData = app.avg_temp_trans(:,:,app.zCCF);
                    else
                        app.CCF_im.CData = app.avg_temp(:,:,app.zCCF);
                    end

            end

            app.CCF_im.AlphaData = AlphaData;

        end

        function clickCCFCallback(app,~,hit)

            x = uint16(hit.IntersectionPoint(2));
            y = uint16(hit.IntersectionPoint(1));
            delete(app.CCFmarker)
            app.CCFmarker = scatter(app.CCFaxes,y,x,40,"r+");

            if app.DisplayWarpCheckBox.Value == true
                parc_ind = app.anno_trans(x,y,app.zCCF);
            else
                parc_ind = app.anno(x,y,app.zCCF);
            end
            
            rows = (app.Tterms.parcellation_index == parc_ind);
            row = app.Tterms(rows,:);
            app.ttOrgan = row.organ{1};
            app.ttCategory = row.category{1};
            app.ttDivision = row.division{1};
            app.ttStructure = row.structure{1};
            app.ttSubstructure = row.substructure{1};
            app.updateCCFtt()

        end
        
        function updateCCFtt(app)

            formatSpec = "Organ: %s | Category: %s | Division: %s | Structure: %s | Substructure: %s";
            str = sprintf(formatSpec,app.ttOrgan,app.ttCategory, ...
                app.ttDivision,app.ttStructure,app.ttSubstructure);
            app.CCFtt.Value = str;

        end

        function calibrateZSlider(~, ZSlider, vol)

            numZ = uint16(size(vol,3));
            ZSlider.Limits = [double(1) double(numZ)];
            ZSlider.MajorTicks = [1 numZ];
            ZSlider.MajorTickLabels = {'1' num2str(numZ)};
            ZSlider.MinorTicks = [];
            ZSlider.Value = double(median(1:numZ));

        end
        
        % update z positions of each volume using slider values
        function fetchZ(app)
            app.zCCF = uint16(app.CCFZSlider.Value);
            app.zSamp = uint16(app.SampleZSlider.Value);
        end

        function populateChannels(app)
            sampleDirListing = dir(app.sampleDir);
            fNames = {sampleDirListing.name};
            tifsPat = 'r' + digitsPattern(1) + '_' + alphanumericsPattern + digitsPattern + '.tif';
            TF = contains(fNames,tifsPat);
            tifs = fNames(TF);
            app.ChannelsListBox.Items = tifs;
            firstTifPat = 'r1_neur';
            TF = contains(tifs,firstTifPat);
            if sum(TF) == 1
                app.ChannelsListBox.Value = tifs{TF};
            else
                app.ChannelsListBox.Value = tifs{randi(length(tifs))};
            end 
        end

        function updateSampleImage(app)
            
            if app.OverlayCCFCheckBox.Value == true
                BW = imbinarize(app.anno_bound_trans(:,:,app.zCCF),0);
                C = imfuse(app.im(:,:,app.zSamp), BW);
                app.samp_im.CData = C;
            else
                app.samp_im.CData = app.im(:,:,app.zSamp);
            end
            
        end

        function autoSetLims(app, value)

            switch value

                case true

                    app.MaxEditField.Editable = false;
                    app.MinEditField.Editable = false;

                    P = prctile(app.im(:,:,app.zSamp),[0.1, 99.9],"all");
                    app.MaxEditField.Value = double(P(2));
                    app.MinEditField.Value = double(P(1));
                    app.SampleAxes.CLim = P;
                    
                case false

                    app.MaxEditField.Editable = true;
                    app.MinEditField.Editable = true;
            end

        end

        function loadSampleImage(app, options)

            arguments
                app
                options.init
            end

            [app.im, app.im_info] = read_tiff(fullfile(app.sampleDir,app.ChannelsListBox.Value));
            
            switch options.init 

                case true

                    app.calibrateZSlider(app.SampleZSlider,app.im)
                    app.fetchZ()
                    app.updateZLabel(app.SampleZLabel,app.zSamp,app.im)
                    app.samp_im = imshow(app.im(:,:,app.zSamp), ...
                        Parent=app.SampleAxes, ...
                        InitialMagnification="fit");
            
                case false

                    app.updateSampleImage()

            end

            app.autoSetLims(app.AutoSetLimitsCheckBox.Value)
            
        end

        function updateZLabel(~, label, z, vol)

            formatSpec = "Current plane: %u / %u";
            str = sprintf(formatSpec,z,size(vol,3));
            label.Text = str;

        end

        function plotNearbyFiducials(app, vol)

            switch vol

                case 'Sample'

                    delete(app.sampFids)
                    delete(app.sampOverlayFids)

                    if ~isempty(app.pointsData.samp)

                        zFid = double(app.pointsData.samp(:,3));
                        zVec = double(repelem(app.zSamp,size(app.pointsData.samp,1)).');
                        toPlot = (abs(zFid - zVec) <= app.fiducialPlottingRangeSamp);
                        ptsToPlot = app.pointsData.samp(toPlot,1:2);
                        if app.OverlayCCFCheckBox.Value == true
                            app.sampFids = scatter(app.SampleAxes, ptsToPlot(:,1), ptsToPlot(:,2),200,'g','pentagram','filled');
                        else
                            app.sampFids = scatter(app.SampleAxes, ptsToPlot(:,1), ptsToPlot(:,2),200,'r','pentagram','filled');
                        end
                        app.sampFids.HitTest = 'off';
                        app.sampFids.SelectionHighlight = 'off';
                        
                        if app.OverlayCCFCheckBox.Value == true
                            zFid = double(app.pointsData.warp_ccf(:,3));
                            zVec = double(repelem(app.zCCF,size(app.pointsData.warp_ccf,1)).');
                            toPlot = (abs(zFid - zVec) <= app.fiducialPlottingRangeSamp);
                            ptsToPlot = app.pointsData.warp_ccf(toPlot,1:2);
                            app.sampOverlayFids = scatter(app.SampleAxes, ptsToPlot(:,1), ptsToPlot(:,2),200,'m','pentagram','filled');
                            app.sampOverlayFids.HitTest = 'off';
                            app.sampOverlayFids.SelectionHighlight = 'off';
                        end

                        app.pointsROI.sampleROI.bringToFront()

                    end

                case 'CCF'

                    delete(app.ccfFids)

                    if ~isempty(app.pointsData.ccf)

                        if app.DisplayWarpCheckBox.Value == true
                            zFid = double(app.pointsData.warp_ccf(:,3));
                            zVec = double(repelem(app.zCCF,size(app.pointsData.warp_ccf,1)).');
                            toPlot = (abs(zFid - zVec) <= app.fiducialPlottingRangeSamp);
                            ptsToPlot = app.pointsData.warp_ccf(toPlot,1:2);
                        else
                            zFid = double(app.pointsData.ccf(:,3));
                            zVec = double(repelem(app.zCCF,size(app.pointsData.ccf,1)).');
                            toPlot = (abs(zFid - zVec) <= app.fiducialPlottingRangeCCF);
                            ptsToPlot = app.pointsData.ccf(toPlot,1:2);
                        end
                        
                        app.ccfFids = scatter(app.CCFaxes, ptsToPlot(:,1), ptsToPlot(:,2),200,'r','pentagram','filled');
                        app.ccfFids.HitTest = 'off';
                        app.ccfFids.SelectionHighlight = 'off';
                        app.pointsROI.ccfROI.bringToFront()

                    end

            end
            
        end

        function incVol(app, vol, dir)

            switch vol

                case 'Sample'

                    switch dir
                        case 'plus'
                            if app.zSamp < size(app.im,3)
                                app.SampleZSlider.Value = double(app.zSamp + 1);
                                app.SampleZSliderValueChanged()
                            end
                        case 'minus'
                            if app.zSamp > 1
                                app.SampleZSlider.Value = double(app.zSamp - 1);
                                app.SampleZSliderValueChanged()
                            end
                    end

                case 'CCF'
                    
                    if app.DisplayWarpCheckBox.Value == true
                        maxZCCF = size(app.anno_trans,3);
                    else
                        maxZCCF = size(app.anno,3);
                    end

                    switch dir
                        case 'plus'
                            if app.zCCF < maxZCCF
                                app.CCFZSlider.Value = double(app.zCCF + 1);
                                app.CCFZSliderValueChanged()
                            end
                        case 'minus'
                            if app.zCCF > 1
                                app.CCFZSlider.Value = double(app.zCCF - 1);
                                app.CCFZSliderValueChanged()
                            end
                    end

            end

        end

        function afIm = preprocessVolAf(app,vol)

            fixImSize = size(app.im);
            movImSize = size(vol);

            afIm = zeros(max(fixImSize(1),movImSize(1)), ...
                max(fixImSize(2),movImSize(2)), ...
                max(fixImSize(3),movImSize(3)));
            afIm(1:movImSize(1), 1:movImSize(2), 1:movImSize(3)) = vol;

        end

        function afIm = postprocessVolAf(app,vol)
            
            fixImSize = size(app.im);
            movImSize = size(vol);

            padx = round(fixImSize(2) - movImSize(2));
            pady = round(fixImSize(1) - movImSize(1));
            padz = round(fixImSize(3) - movImSize(3));
            
            dxn = round(padx.*(padx<0));
            dyn = round(pady.*(pady<0));
            dzn = round(padz.*(padz<0));
            
            afIm = vol(1:end+dyn, 1:end+dxn, 1:end+dzn);

        end
        
        % calculate locations of CCF fiducials in warped CCF volume
        function updateWarpCCFPoints(app)
            if ~isempty(app.af)
                points = app.pointsData.ccf;
                [u,v,w] = deal(points(:,1), points(:,2), points(:,3));
                [x,y,z] = transformPointsForward(app.af,u,v,w);
                app.pointsData.warp_ccf = [x,y,z];
            end
            % if ~isempty(app.af)
            %     a = [app.pointsData.ccf, ones(size(app.pointsData.ccf,1),1)]; % append a column of ones to points matrix
            %     a = a*app.af;
            %     app.pointsData.warp_ccf = a(:,1:3);
            % end
        end

        function doAfWarp(app)

            movingPoints = app.pointsData.ccf;
            fixedPoints = app.pointsData.samp;
            app.af = fitgeotform3d(movingPoints,fixedPoints,"affine");
            Rfixed = imref3d(size(app.im));

            app.anno_trans = imwarp(app.anno, app.af, 'nearest', 'OutputView', Rfixed);
            app.anno_bound_trans = imwarp(app.anno_bound, app.af, 'nearest', 'OutputView', Rfixed);

        
            % afIm = app.preprocessVolAf(app.anno);
            % 
            % pointsMov = app.pointsData.ccf;
            % pointsFix = app.pointsData.samp;
            % 
            % pointsMov = [pointsMov(:, [2 1 3]) ones(size(pointsMov, 1), 1)];
            % pointsFix = [pointsFix(:, [2 1 3]) ones(size(pointsFix, 1), 1)];
            % 
            % mid = [size(afIm)./2 1];
            % 
            % pointsMov(:,1) = pointsMov(:,1) - mid(1);
            % pointsMov(:,2) = pointsMov(:,2) - mid(2);
            % pointsMov(:,3) = pointsMov(:,3) - mid(3);
            % 
            % app.af = pointsMov\pointsFix;
            % 
            % app.af(4,1) = app.af(4,1) - mid(1);
            % app.af(4,2) = app.af(4,2) - mid(2);
            % app.af(4,3) = app.af(4,3) - mid(3);
            % 
            % afIm = affine_transform(afIm, inv(app.af'),4);
            % afIm = app.postprocessVolAf(afIm);
            % app.anno_trans = uint16(afIm);
            % 
            % afIm = app.preprocessVolAf(app.anno_bound);
            % afIm = affine_transform(afIm, inv(app.af'),4);
            % afIm = app.postprocessVolAf(afIm);
            % app.anno_bound_trans = uint16(afIm);
            % 
            % afIm = app.preprocessVolAf(app.avg_temp);
            % afIm = affine_transform(afIm, inv(app.af'),4);
            % afIm = app.postprocessVolAf(afIm);
            % app.avg_temp_trans = uint16(afIm);
            % 
            % pointsFix = app.pointsData.samp;
            % pointsMov = app.pointsData.ccf;
            % 
            % pointsMov = [pointsMov ones(size(pointsMov, 1), 1)];
            % pointsFix = [pointsFix ones(size(pointsFix, 1), 1)];
            % 
            % app.af = pointsMov\pointsFix;
            % app.af(abs(app.af)<1e-9) = 0;
            % app.af(abs(app.af-1)<1e-9) = 1;
            % app.hasAf = true;

            app.updateWarpCCFPoints()

            app.DisplayWarpCheckBox.Value = true;
            app.calibrateZSlider(app.CCFZSlider,app.anno_trans)
            app.fetchZ()
            delete(app.CCF_im)
            app.initializeCCFvol()
            app.plotNearbyFiducials('CCF')

            delete(app.pointsROI.ccfROI)
            app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                Position=[round(size(app.anno_trans, 2)./2), round(size(app.anno_trans, 1)./2)]);

            
        end

        function newPoints = transformPointsResize(~,points,origImSize,newImSize)

            pointsPercentage = points ./ origImSize;
            newPoints = round(pointsPercentage .* newImSize);
            
        end

        function doBSWarp(app)

            % pointsMov = app.pointsData.ccf;
            % pointsFix = app.pointsData.samp;
            % 
            % pointsMov = app.transformPointsResize(pointsMov,size(app.anno),size(app.im));
            % anno_resize = imresize3(app.anno,size(app.im),'nearest');
            % 
            % pointsMov = pointsMov(:, [2 1 3]);
            % pointsFix = pointsFix(:, [2 1 3]);
            % 
            % options.Verbose=true;
            % options.MaxRef = 3;
            % 
            % % [O_trans,Spacing]         = point_registration(size(app.im),pointsFix, pointsMov, options);
            % [O_trans,Spacing,Xreg]         = point_registration(size(anno_resize),pointsMov,pointsFix,options);
            % % [O_trans_inv,Spacing_inv] = point_registration(size(app.anno),pointsMov, pointsFix, options);
            % 
            % [Iout,~] = bspline_transform(O_trans,double(anno_resize),Spacing,3);
            % 
            % Options.Verbose=2;
            % Options.MaxRef = 3;
            % Options.Registration = 'Nonrigid';
            % Options.Points1 = pointsMov;
            % Options.Points2 = pointsFix;
            % [Ireg,O_trans,Spacing,M,B,F] = image_registration(double(anno_resize),double(app.im),Options);

        end

        function saveState(app)

            app.appState.pointsData = app.pointsData;
            app.appState.sampSize = size(app.im);
            app.appState.sampSpacing = [0.33, 0.33, 2];
            app.appState.sampOrigin = [0, 0, 0];
            app.appState.sampDirection = eye(3);

            if app.hasRotate == true
                app.appState.hasRotate = true;
                app.appState.Pitch = app.PitchSpinner.Value;
                app.appState.Yaw = app.YawSpinner.Value;
            else
                app.appState.hasRotate = false;
            end
            if app.hasCrop == true
                app.appState.hasCrop = true;
                app.appState.cuboid = app.cuboid;
            else
                app.appState.hasCrop = false;
            end
            if app.hasAf == true
                app.appState.hasAf = true;
                app.appState.af = app.af;
            else
                app.appState.hasAf = false;
            end
            appStateStruct = app.appState;
            
            newStr = split(app.sampleDir,'\');
            [filepath,~,~] = fileparts(app.sampleDir);
            [filepath,~,~] = fileparts(filepath);
            filename = fullfile(filepath,'CCF_vols',newStr{end},append(newStr{end},'CCFReg.mat'));
            save(filename,'appStateStruct')

            app.MessageBoxTextArea.Value = 'Saving complete';

        end

        function loadState(app)

            newStr = split(app.sampleDir,'\');
            [filepath,~,~] = fileparts(app.sampleDir);
            [filepath,~,~] = fileparts(filepath);
            filename = fullfile(filepath,'CCF_vols',newStr{end},append(newStr{end},'CCFReg.mat'));
            load(filename, "appStateStruct")

            if appStateStruct.hasRotate == true
                app.PitchSpinner.Value = appStateStruct.Pitch;
                app.YawSpinner.Value = appStateStruct.Yaw;
                app.RotateCCFVolumeButtonPushed()
            else
                app.hasRotate = false;
                app.RotateLamp.Color = 'w';
            end

            if appStateStruct.hasCrop == true
                app.cuboid = appStateStruct.cuboid;
                app.CropXYRect = [app.cuboid(1), app.cuboid(2), app.cuboid(4), app.cuboid(5)];
                app.CropZMin = app.cuboid(3);
                app.CropZMax = app.cuboid(6) + app.CropZMin;
                app.CROPButtonPushed()
            else
                app.hasCrop = false;
                app.CropLamp.Color = 'w';
            end

            app.pointsData = appStateStruct.pointsData;
            app.pointsTable.Data = horzcat(transpose(1:size(app.pointsData.samp,1)), ...
                app.pointsData.samp); 
            app.selectedPointsRow = [];

            if appStateStruct.hasAf == true
                app.hasAf = true;
                app.af = appStateStruct.af;
                app.doAfWarp()
            else
                app.hasAf = false;
            end

            app.plotNearbyFiducials('Sample')
            app.plotNearbyFiducials('CCF')

            app.MessageBoxTextArea.Value = 'Loading complete';

        end

        function flip_sample_z(app)

            all_tifs = fetch_dir(app.sampleDir,'file');
            all_tifs = filt_kw(all_tifs, {'.tif','.tiff'}, 'keep');

            max_files = length(all_tifs);
            file_frac = strcat('(1/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Z-Flipping Progress',...
                    'Message',strcat(file_frac,' Flipping files in Z...'), ...
                    'Indeterminate','on');
            
            for i=1:length(all_tifs)
                file_frac = strcat('(',string(i),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Flipping files in Z...');
                flip_z(fullfile(app.sampleDir,all_tifs{i}))
            end

        end

        function exportCCFVol(app, vol)

            switch vol
                case 'Annotation'
                    vol = app.anno_trans;
                    f = 'annotation.tif';
                case 'Annotation Boundary'
                    vol = app.anno_bound_trans;
                    f = 'annotation_boundary.tif';
                case 'Average Template'
                    Rfixed = imref3d(size(app.im));
                    app.avg_temp_trans = imwarp(app.avg_temp, app.af, 'nearest', 'OutputView', Rfixed);
                    vol = app.avg_temp_trans;
                    f = 'average_template.tif';
            end

            newStr = split(app.sampleDir,'\');
            [filepath,~,~] = fileparts(app.sampleDir);
            [filepath,~,~] = fileparts(filepath);
            filename = fullfile(filepath,'CCF_vols',newStr{end},append(newStr{end},f));
            
            t = Tiff(filename, 'w8');
            dims = size(vol);

            tagstruct.ImageLength = dims(1);
            tagstruct.ImageWidth = dims(2);
            tagstruct.SampleFormat = Tiff.SampleFormat.UInt;
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.BitsPerSample = 16;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.ExtraSamples = Tiff.ExtraSamples.Unspecified;

            for i=1:dims(3)
                setTag(t,tagstruct);
                warning('off','MATLAB:imagesci:Tiff:missingExtraSamples')
                write(t,uint16(vol(:,:,i)));
                writeDirectory(t);
            end

            close(t)

        end

        function exportCroppedCCFVol(app)
            
            f = '_cropped_ccf_vol.mat';
            newStr = split(app.sampleDir,'\');
            [filepath,~,~] = fileparts(app.sampleDir);
            [filepath,~,~] = fileparts(filepath);
            filename = fullfile(filepath,'CCF_vols',newStr{end},append(newStr{end},f));
            
            anno = app.anno;
            save(filename, 'anno')

        end

        function [rbf_weights, affine_coeffs] = tps_solve(app, landmark_coordinates, target_displacements)
            % tps_solve
            %
            % Solves for the coefficients of a 3D Thin Plate Spline (TPS) that
            % exactly interpolates a set of displacement values at given landmark
            % (fiducial) locations, while minimizing bending energy everywhere else.
            %
            % This function handles ONE axis of displacement at a time (e.g. just
            % the x-displacements). Call it three times total (once each for x, y,
            % and z) to build a full 3D warp field.
            %
            % INPUTS:
            %   landmark_coordinates : N x 3 matrix
            %       Each row is the [x, y, z] location of one fiducial point in the
            %       moving volume.
            %
            %   target_displacements : N x 1 vector
            %       The displacement value (along ONE axis) that each corresponding
            %       landmark needs to move by. Row i here must correspond to row i
            %       of landmark_coordinates.
            %
            % OUTPUTS:
            %   rbf_weights : N x 1 vector
            %       The weight assigned to each landmark's radial basis function.
            %       These control the "local bending" contribution of the warp.
            %
            %   affine_coeffs : 4 x 1 vector [constant_term, x_term, y_term, z_term]
            %       The coefficients of the global linear (affine) part of the warp.

            number_of_landmarks = size(landmark_coordinates, 1);

            % -----------------------------------------------------------------
            % STEP 1: Build the pairwise distance matrix between all landmarks.
            %
            % distance_matrix(i,j) = Euclidean distance between landmark i and
            % landmark j. In 3D, the TPS kernel function U(r) is simply r itself
            % (no log term -- that's only used in the 2D version of TPS).
            %
            % This matrix describes how much influence each landmark's "bend"
            % has on every other point in space.
            % -----------------------------------------------------------------
            distance_matrix = pdist2(landmark_coordinates, landmark_coordinates);

            % -----------------------------------------------------------------
            % STEP 2: Build the affine design matrix.
            %
            % Each row is [1, x, y, z] for a landmark. This matrix is used twice:
            %   (a) to represent the global linear/affine trend of the warp
            %   (b) to build constraint equations that keep the bending part of
            %       the solution "orthogonal" to the affine part (see Step 3)
            % -----------------------------------------------------------------
            affine_design_matrix = [ones(number_of_landmarks, 1), landmark_coordinates];

            % -----------------------------------------------------------------
            % STEP 3: Assemble the full linear system to solve.
            %
            % We need to solve for two sets of unknowns simultaneously:
            %   - rbf_weights (N unknowns): how much each landmark bends space
            %   - affine_coeffs (4 unknowns): the overall linear trend
            %
            % The system has two stacked sets of equations:
            %
            %   Block 1 (top N rows): "Interpolation conditions"
            %       Forces the warp to exactly reproduce the target displacement
            %       at every landmark location.
            %
            %   Block 2 (bottom 4 rows): "Zero-moment constraints"
            %       Forces the bending weights to sum to zero in a way that
            %       prevents them from absorbing any translation or linear
            %       trend -- that trend is handled entirely by affine_coeffs.
            %       Without this, the system would be underdetermined.
            %
            % In block-matrix form:
            %
            %   [ distance_matrix   affine_design_matrix ] [rbf_weights ]   [target_displacements]
            %   [ affine_design_matrix'      0           ] [affine_coeffs] = [        0          ]
            % -----------------------------------------------------------------
            top_block    = [distance_matrix, affine_design_matrix];
            bottom_block = [affine_design_matrix', zeros(4, 4)];

            system_matrix = [top_block; bottom_block];

            right_hand_side = [target_displacements; zeros(4, 1)];

            % -----------------------------------------------------------------
            % STEP 4: Solve the linear system for all unknowns at once.
            % -----------------------------------------------------------------
            full_solution = system_matrix \ right_hand_side;

            % -----------------------------------------------------------------
            % STEP 5: Split the solution vector back into its two meaningful
            % pieces.
            % -----------------------------------------------------------------
            rbf_weights    = full_solution(1 : number_of_landmarks);
            affine_coeffs  = full_solution(number_of_landmarks + 1 : end);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, sample_dir, pitch, yaw)
            
            app.parent_app = parent_app;
            app.sampleDir = sample_dir;
            app.PitchSpinner.Value = pitch;
            app.YawSpinner.Value = yaw;

            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            addpath(['./' 'reg_utils/functions_affine'])

            movegui(app.UIFigure,'north')
            
            % setting up Allen CCF stuff
            app.abcDir = params('abcDir');
            app.imgDir = fullfile(app.abcDir,'image_volumes', ...
                'Allen-CCF-2020','20230630');
            app.colorDir = fullfile(app.abcDir,'metadata', ...
                'Allen-CCF-2020','20230630','views');

            app.ccfColors = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_color.csv');
            app.ccfTerms = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_acronym.csv');

            app.Tcolors = readtable(app.ccfColors);
            app.Tterms = readtable(app.ccfTerms);

            axis(app.CCFaxes,"off")
            axis(app.CCFaxes,"image")
            hold(app.CCFaxes,"on")
            axis(app.SampleAxes,"off")
            axis(app.SampleAxes,"image")
            hold(app.SampleAxes,"on")

            app.populateChannels()
            app.loadSampleImage(init=true)
            
            app.loadCCFvol()
            app.setCCFcolormap()
            app.calibrateZSlider(app.CCFZSlider,app.anno)
            app.fetchZ()
            app.initializeCCFvol()
            
            app.pointsROI.sampleROI = drawpoint(app.SampleAxes, Color='g', ...
                Position=[round(size(app.im, 2)./2), round(size(app.im, 1)./2)]);
            app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                Position=[round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);

            app.pointsData.samp = [];
            app.pointsData.ccf = [];
            app.pointsData.warp_ccf = [];
            app.af = [];
            app.anno_trans = [];
            app.anno_bound_trans = [];
            app.avg_temp_trans = [];
        end

        % Selection changed function: SelectCCFVolumeButtonGroup
        function SelectCCFVolumeButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text
                case 'Annotation'
                    app.CCF_im.Parent.Colormap = app.colorsArray;
                case 'Annotation Boundary'
                    app.CCF_im.Parent.Colormap = app.colorsArray;
                case 'Average Template'
                    app.CCF_im.Parent.Colormap = app.colorsArray;
                    app.CCF_im.Parent.CLim = [0,516];
            end
            app.updateCCFvol()
        end

        % Selection changed function: ColorCCFbyButtonGroup
        function ColorCCFbyButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Value changed function: OverlayAnnoCheckBox
        function OverlayAnnoCheckBoxValueChanged(app, event)
            app.updateCCFvol()
        end

        % Value changed function: SampleZSlider
        function SampleZSliderValueChanged(app, event)
            app.zSamp = uint16(app.SampleZSlider.Value);
            app.updateSampleImage()
            app.autoSetLims(app.AutoSetLimitsCheckBox.Value)
            app.plotNearbyFiducials('Sample')
            app.updateZLabel(app.SampleZLabel,app.zSamp,app.im)
        end

        % Value changed function: CCFZSlider
        function CCFZSliderValueChanged(app, event)
            app.zCCF = uint16(app.CCFZSlider.Value);
            app.updateCCFvol()
            app.plotNearbyFiducials('CCF')
            if app.DisplayWarpCheckBox.Value == true
                app.updateZLabel(app.CCFZLabel,app.zCCF,app.anno_trans)
                if app.OverlayCCFCheckBox.Value == true
                    app.updateSampleImage()
                    app.plotNearbyFiducials('Sample')
                end
            else
                app.updateZLabel(app.CCFZLabel,app.zCCF,app.anno)
            end
        end

        % Value changed function: AutoSetLimitsCheckBox
        function AutoSetLimitsCheckBoxValueChanged(app, event)
            value = app.AutoSetLimitsCheckBox.Value;
            app.autoSetLims(value)
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            pSample = app.pointsROI.sampleROI.Position;
            pCCF = app.pointsROI.ccfROI.Position;

            app.pointsData.samp(end+1, :) = [pSample app.zSamp];
            if app.DisplayWarpCheckBox.Value == true
                point = [pCCF app.zCCF];
                app.pointsData.warp_ccf(end+1, :) = [pCCF app.zCCF];
                [u,v,w] = transformPointsInverse(app.af,point(1),point(2),point(3));
                app.pointsData.ccf(end+1, :) = [u,v,w];
            else
                app.pointsData.ccf(end+1, :) = [pCCF app.zCCF];
                app.pointsData.warp_ccf(end+1, :) = [pCCF app.zCCF];
                app.updateWarpCCFPoints()
            end
            
            app.pointsTable.Data = horzcat(transpose(1:size(app.pointsData.samp,1)), ...
                app.pointsData.samp); 

            app.plotNearbyFiducials('Sample')
            app.plotNearbyFiducials('CCF')
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            
            % special case where there's only one fiducial left
            if size(app.pointsData.samp,1) == 1
                app.pointsData.samp = [];
                app.pointsData.ccf = [];
                app.pointsData.warp_ccf = [];
                app.pointsTable.Data = [];
                app.selectedPointsRow = [];
                return
            end
            
            if ~isempty(app.selectedPointsRow)

                indices = setxor(1:size(app.pointsData.samp,1), app.selectedPointsRow);

                app.pointsData.samp = app.pointsData.samp(indices,:);
                app.pointsData.ccf = app.pointsData.ccf(indices,:);
                app.pointsData.warp_ccf = app.pointsData.warp_ccf(indices,:);

                app.pointsTable.Data = horzcat(transpose(1:size(app.pointsData.samp,1)), ...
                    app.pointsData.samp); 

                app.selectedPointsRow = [];

            end

        end

        % Cell selection callback: pointsTable
        function pointsTableCellSelection(app, event)
            
            indices = event.Indices;
            if isempty(indices)
                app.selectedPointsRow = app.selectedPointsRow;
            else
                app.selectedPointsRow = unique(indices(:,1));
            end

            app.SampleZSlider.Value = app.pointsData.samp(app.selectedPointsRow,3);
            if app.DisplayWarpCheckBox.Value == true
                app.CCFZSlider.Value = app.pointsData.warp_ccf(app.selectedPointsRow,3);
            else
                app.CCFZSlider.Value = app.pointsData.ccf(app.selectedPointsRow,3);
            end
            
            app.SampleZSliderValueChanged()
            app.CCFZSliderValueChanged()

            app.pointsROI.sampleROI.Position = [app.pointsData.samp(app.selectedPointsRow,1), ...
                app.pointsData.samp(app.selectedPointsRow,2)];

            if app.DisplayWarpCheckBox.Value
                app.pointsROI.ccfROI.Position = [app.pointsData.warp_ccf(app.selectedPointsRow,1), ...
                    app.pointsData.warp_ccf(app.selectedPointsRow,2)];
            else
                app.pointsROI.ccfROI.Position = [app.pointsData.ccf(app.selectedPointsRow,1), ...
                    app.pointsData.ccf(app.selectedPointsRow,2)];
            end
            
        end

        % Button pushed function: IncSamplePlusButton
        function IncSamplePlusButtonPushed(app, event)
            app.incVol('Sample','plus')
        end

        % Button pushed function: IncSampleMinusButton
        function IncSampleMinusButtonPushed(app, event)
            app.incVol('Sample','minus')
        end

        % Button pushed function: IncCCFPlusButton
        function IncCCFPlusButtonPushed(app, event)
            app.incVol('CCF','plus')
        end

        % Button pushed function: IncCCFMinusButton
        function IncCCFMinusButtonPushed(app, event)
            app.incVol('CCF','minus')
        end

        % Button pushed function: AffineWarpButton
        function AffineWarpButtonPushed(app, event)
            
            numFid = size(app.pointsData.samp,1);
            if numFid == 0
                str = 'Cannot perform affine warp without fiducials';
            elseif (numFid > 0 && numFid < 3)
                str = 'At least 3 fiducials needed to perform an affine warp';
            elseif numFid >= 3
                str = 'Performing affine warp...';
            end
            app.MessageBoxTextArea.Value = str;
            drawnow()

            app.doAfWarp()

            app.MessageBoxTextArea.Value = 'Affine warp complete!';
        end

        % Button pushed function: bsplineWarpButton
        function bsplineWarpButtonPushed(app, event)
            app.doBSWarp()
        end

        % Value changed function: ChannelsListBox
        function ChannelsListBoxValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow()
            app.loadSampleImage(init=false)
            app.UIFigure.Pointer = 'arrow';
        end

        % Callback function
        function FlipSampleZCheckBoxValueChanged(app, event)
            app.im = flip(app.im,3);
            app.updateSampleImage()
            app.flipSampleZ = app.FlipSampleZCheckBox.Value;
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)
            app.saveState()
            app.parent_app.finish_ccf_register_settings(app.PitchSpinner.Value,app.YawSpinner.Value,app.sampleDir)
        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            app.loadState()
        end

        % Button pushed function: SetXYButton
        function SetXYButtonPushed(app, event)
            app.XYLamp.Color = 'y';
            str = ['Move or resize the crop rectangle using the mouse. ' ...
                'When you are finished sizing and positioning the crop rectangle, ' ...
                'create the cropped image by either double-clicking the left mouse button or ' ...
                'choosing Crop Image from the right-click context menu.'];
            app.MessageBoxTextArea.Value = str;
            [~,app.CropXYRect] = imcrop(app.CCFaxes);
            app.CropXYRect = round(app.CropXYRect);
            app.XYLamp.Color = 'g';
        end

        % Button pushed function: SetZMinButton
        function SetZMinButtonPushed(app, event)
            app.ZMinLamp.Color = 'g';
            app.CropZMin = app.zCCF;
        end

        % Button pushed function: SetZMaxButton
        function SetZMaxButtonPushed(app, event)
            app.ZMaxLamp.Color = 'g';
            app.CropZMax = app.zCCF;
        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            if all([~isempty(app.CropXYRect),~isempty(app.CropZMin),~isempty(app.CropZMax)])
                
                app.cuboid = [app.CropXYRect(1), app.CropXYRect(2), app.CropZMin, ...
                    app.CropXYRect(3), app.CropXYRect(4), app.CropZMax - app.CropZMin];
                app.anno = imcrop3(app.anno,app.cuboid);
                app.anno_bound = imcrop3(app.anno_bound,app.cuboid);
                app.avg_temp = imcrop3(app.avg_temp,app.cuboid);
                
                hold(app.CCFaxes,"off")
                app.calibrateZSlider(app.CCFZSlider,app.anno)
                app.fetchZ()
                app.initializeCCFvol()
                hold(app.CCFaxes,"on")

                app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                    Position=[round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);

                app.XYLamp.Color = 'w';
                app.ZMinLamp.Color = 'w';
                app.ZMaxLamp.Color = 'w';
                app.CropLamp.Color = 'g';
                app.hasCrop = true;
            else
                str = ['Must set XY crop rectangle, Z crop minimum, and Z crop maximum ' ...
                    'before cropping'];
                app.MessageBoxTextArea.Value = str;
            end
            
        end

        % Button down function: CropCCFPanel
        function CropCCFPanelButtonDown(app, event)
            if app.hasRotate == false
                str = 'Must rotate CCF volume before cropping';
                app.MessageBoxTextArea.Value = str;
            end
        end

        % Button pushed function: RotateCCFVolumeButton
        function RotateCCFVolumeButtonPushed(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow()
            
            app.anno = app.anno_orig;
            app.anno_bound = app.anno_bound_orig;
            app.avg_temp = app.avg_temp_orig;

            if app.PitchSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.PitchSpinner.Value,[1 0 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.PitchSpinner.Value,[1 0 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.PitchSpinner.Value,[1 0 0],"nearest");
            end
            
            if app.YawSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.YawSpinner.Value,[0 1 0],"nearest");
            end
            
            delete(app.CCF_im)
            app.calibrateZSlider(app.CCFZSlider,app.anno)
            app.fetchZ()
            app.initializeCCFvol()

            app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                Position=[round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);

            app.hasRotate = true;
            app.RotateLamp.Color = 'g';

            app.UIFigure.Pointer = 'arrow';

        end

        % Value changed function: OverlayCCFCheckBox
        function OverlayCCFCheckBoxValueChanged(app, event)
            if app.DisplayWarpCheckBox.Value == true
                app.updateSampleImage()
                app.plotNearbyFiducials('Sample')
                app.plotNearbyFiducials('CCF')
            else
                app.OverlayCCFCheckBox.Value = false;
                str = ['You must first display a warped version of the CCF ' ...
                    'before trying to overlay it on your sample image'];
                app.MessageBoxTextArea.Value = str;
            end
            
        end

        % Value changed function: DisplayWarpCheckBox
        function DisplayWarpCheckBoxValueChanged(app, event)


            if app.DisplayWarpCheckBox.Value == true
                app.calibrateZSlider(app.CCFZSlider,app.anno_trans)
                delete(app.pointsROI.ccfROI)
                app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                    Position=[round(size(app.anno_trans, 2)./2), round(size(app.anno_trans, 1)./2)]);
            else
                app.calibrateZSlider(app.CCFZSlider,app.anno)
                if app.OverlayCCFCheckBox.Value == true
                    app.OverlayCCFCheckBox.Value = false;
                    app.updateSampleImage()
                    app.autoSetLims(app.AutoSetLimitsCheckBox.Value)
                    app.plotNearbyFiducials('Sample')
                end
                delete(app.pointsROI.ccfROI)
                app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                    Position=[round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);
            end

            app.fetchZ()
            delete(app.CCF_im)
            app.initializeCCFvol()
            
            app.plotNearbyFiducials('CCF')

        end

        % Button pushed function: MatchZCCFButton
        function MatchZCCFButtonPushed(app, event)
            app.CCFZSlider.Value = double(app.zSamp);
            app.CCFZSliderValueChanged()
        end

        % Button pushed function: ExportCCFButton
        function ExportCCFButtonPushed(app, event)
            
            app.saveState()
            
            if isempty(app.anno_trans)
                str = ['You must first transform the CCF with either an ' ...
                    'affine or b-spline warp before exporting'];
                app.MessageBoxTextArea.Value = str;
                return
            end

            app.exportCCFVol('Annotation')
            app.exportCCFVol('Annotation Boundary')
            app.exportCCFVol('Average Template')
            
            app.parent_app.finish_ccf_register(app.PitchSpinner.Value,app.YawSpinner.Value,app.sampleDir)
            delete(app)

        end

        % Button pushed function: FlipSampleZButton
        function FlipSampleZButtonPushed(app, event)
            
            message = "Are you sure you want to flip all the images for " + ...
                "this sample in Z? Remember that the leftmost, top of the " + ...
                "z-stack should be the most anterior portion of the slice, " + ...
                "while the rightmost, bottom of the z-stack should be the " + ...
                "most posterior portion. This operation will take a while.";
            title = ("Flip sample in Z?");
            selection = uiconfirm(app.UIFigure,message,title, ...
                'Icon','question', ...
                "Options",["Continue","Cancel"], ...
                "DefaultOption",1, ...
                "CancelOption",2);

            switch selection
                case 'Continue'
                    app.flip_sample_z()
                case 'Cancel'
                    return
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SamplePanel = uipanel(app.UIFigure);
            app.SamplePanel.Title = 'Sample';
            app.SampleAxes = uiaxes(app.SamplePanel);
            app.SampleImageParamsPanel = uipanel(app.SamplePanel);
            app.SampleImageParamsPanel.Title = 'Sample Image Display Parameters';
            app.MinEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.AutoSetLimitsCheckBox = uicheckbox(app.SampleImageParamsPanel);
            app.AutoSetLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSetLimitsCheckBoxValueChanged, true);
            app.AutoSetLimitsCheckBox.Text = 'Auto set limits';
            app.AutoSetLimitsCheckBox.Value = true;
            app.SampleZSlider = uislider(app.SamplePanel);
            app.SampleZSlider.ValueChangedFcn = createCallbackFcn(app, @SampleZSliderValueChanged, true);
            app.IncSamplePlusButton = uibutton(app.SamplePanel, 'push');
            app.IncSamplePlusButton.ButtonPushedFcn = createCallbackFcn(app, @IncSamplePlusButtonPushed, true);
            app.IncSamplePlusButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.IncSamplePlusButton.Text = '';
            app.IncSampleMinusButton = uibutton(app.SamplePanel, 'push');
            app.IncSampleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @IncSampleMinusButtonPushed, true);
            app.IncSampleMinusButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.IncSampleMinusButton.Text = '';
            app.SampleZLabel = uilabel(app.SamplePanel);
            app.SampleZLabel.Text = 'SampleZLabel';
            app.ChannelsListBoxLabel = uilabel(app.SamplePanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.SamplePanel);
            app.ChannelsListBox.Items = {};
            app.ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBoxValueChanged, true);
            app.ChannelsListBox.Value = {};
            app.ProcessPanel = uipanel(app.SamplePanel);
            app.ProcessPanel.Title = 'Process';
            app.RotateLabel = uilabel(app.ProcessPanel);
            app.RotateLabel.Text = '1. Rotate';
            app.RotateLamp = uilamp(app.ProcessPanel);
            app.CropLampLabel = uilabel(app.ProcessPanel);
            app.CropLampLabel.Text = '2. Crop';
            app.CropLamp = uilamp(app.ProcessPanel);
            app.FiducialsLampLabel = uilabel(app.ProcessPanel);
            app.FiducialsLampLabel.Text = '3. Fiducials';
            app.FiducialsLamp = uilamp(app.ProcessPanel);
            app.OverlayCCFCheckBox = uicheckbox(app.SamplePanel);
            app.OverlayCCFCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCCFCheckBoxValueChanged, true);
            app.OverlayCCFCheckBox.Text = 'Overlay CCF?';
            app.MatchZCCFButton = uibutton(app.SamplePanel, 'push');
            app.MatchZCCFButton.ButtonPushedFcn = createCallbackFcn(app, @MatchZCCFButtonPushed, true);
            app.MatchZCCFButton.Text = 'Match CCF Z to Sample Z';
            app.FlipSampleZButton = uibutton(app.SamplePanel, 'push');
            app.FlipSampleZButton.ButtonPushedFcn = createCallbackFcn(app, @FlipSampleZButtonPushed, true);
            app.FlipSampleZButton.Text = 'Flip Sample Z';
            app.LRshouldbeAPLabel = uilabel(app.SamplePanel);
            app.LRshouldbeAPLabel.Text = '(L → R should be A → P)';
            app.pointsTable = uitable(app.UIFigure);
            app.pointsTable.ColumnName = {''; 'x fixed'; 'y fixed'; 'z fixed'};
            app.pointsTable.RowName = {};
            app.pointsTable.SelectionType = 'row';
            app.pointsTable.CellSelectionCallback = createCallbackFcn(app, @pointsTableCellSelection, true);
            app.pointsTable.Multiselect = 'off';
            app.CCFPanel = uipanel(app.UIFigure);
            app.CCFPanel.Title = 'Allen CCF';
            app.CCFaxes = uiaxes(app.CCFPanel);
            app.CCFZSlider = uislider(app.CCFPanel);
            app.CCFZSlider.ValueChangedFcn = createCallbackFcn(app, @CCFZSliderValueChanged, true);
            app.IncCCFPlusButton = uibutton(app.CCFPanel, 'push');
            app.IncCCFPlusButton.ButtonPushedFcn = createCallbackFcn(app, @IncCCFPlusButtonPushed, true);
            app.IncCCFPlusButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.IncCCFPlusButton.Text = '';
            app.IncCCFMinusButton = uibutton(app.CCFPanel, 'push');
            app.IncCCFMinusButton.ButtonPushedFcn = createCallbackFcn(app, @IncCCFMinusButtonPushed, true);
            app.IncCCFMinusButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.IncCCFMinusButton.Text = '';
            app.CCFZLabel = uilabel(app.CCFPanel);
            app.CCFZLabel.Text = 'CCFZLabel';
            app.CropCCFPanel = uipanel(app.CCFPanel);
            app.CropCCFPanel.Title = 'Crop CCF';
            app.CropCCFPanel.ButtonDownFcn = createCallbackFcn(app, @CropCCFPanelButtonDown, true);
            app.SetXYButton = uibutton(app.CropCCFPanel, 'push');
            app.SetXYButton.ButtonPushedFcn = createCallbackFcn(app, @SetXYButtonPushed, true);
            app.SetXYButton.Text = 'Set XY';
            app.SetZMinButton = uibutton(app.CropCCFPanel, 'push');
            app.SetZMinButton.ButtonPushedFcn = createCallbackFcn(app, @SetZMinButtonPushed, true);
            app.SetZMinButton.Text = 'Set Z Min';
            app.SetZMaxButton = uibutton(app.CropCCFPanel, 'push');
            app.SetZMaxButton.ButtonPushedFcn = createCallbackFcn(app, @SetZMaxButtonPushed, true);
            app.SetZMaxButton.Text = 'Set Z Max';
            app.XYLamp = uilamp(app.CropCCFPanel);
            app.ZMinLamp = uilamp(app.CropCCFPanel);
            app.ZMaxLamp = uilamp(app.CropCCFPanel);
            app.CROPButton = uibutton(app.CropCCFPanel, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.UndoCropButton = uibutton(app.CropCCFPanel, 'push');
            app.UndoCropButton.Text = 'Undo Crop';
            app.DisplayWarpCheckBox = uicheckbox(app.CCFPanel);
            app.DisplayWarpCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayWarpCheckBoxValueChanged, true);
            app.DisplayWarpCheckBox.Text = 'Display warped CCF volume?';
            app.ColorCCFbyButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorCCFbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorCCFbyButtonGroupSelectionChanged, true);
            app.ColorCCFbyButtonGroup.Title = 'Color CCF by:';
            app.OrganButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.OrganButton.Text = 'Organ';
            app.CategoryButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.CategoryButton.Text = 'Category';
            app.DivisionButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.StructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SubstructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.SubstructureButton.Text = 'Substructure';
            app.SelectCCFVolumeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectCCFVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectCCFVolumeButtonGroupSelectionChanged, true);
            app.SelectCCFVolumeButtonGroup.Title = 'Select CCF Volume:';
            app.AnnotationButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationButton.Text = 'Annotation';
            app.AnnotationButton.Value = true;
            app.AnnotationBoundaryButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationBoundaryButton.Text = 'Annotation Boundary';
            app.AverageTemplateButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AverageTemplateButton.Text = 'Average Template';
            app.CCFtt = uitextarea(app.UIFigure);
            app.CCFtt.Value = {'Click anywhere in the CCF to view metadata'};
            app.CCFSettingsPanel = uipanel(app.UIFigure);
            app.CCFSettingsPanel.Title = 'CCF Settings';
            app.OverlayAnnoCheckBox = uicheckbox(app.CCFSettingsPanel);
            app.OverlayAnnoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayAnnoCheckBoxValueChanged, true);
            app.OverlayAnnoCheckBox.Text = {'Overlay annotation boundaries'; 'on annotation volume?'};
            app.OverlayAnnoCheckBox.Value = true;
            app.MessageBoxTextAreaLabel = uilabel(app.UIFigure);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.UIFigure);
            app.MessageBoxTextArea.Editable = 'off';
            app.SaveStateButton = uibutton(app.UIFigure, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Text = 'Save State';
            app.LoadStateButton = uibutton(app.UIFigure, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Text = 'Load State';
            app.FiducialsPanel = uipanel(app.UIFigure);
            app.FiducialsPanel.Title = 'Fiducials';
            app.AddPointButton = uibutton(app.FiducialsPanel, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add point';
            app.DeletePointButton = uibutton(app.FiducialsPanel, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete point';
            app.SavePointsButton = uibutton(app.FiducialsPanel, 'push');
            app.SavePointsButton.Text = 'Save points';
            app.RotateCCF1stStepPanel = uipanel(app.UIFigure);
            app.RotateCCF1stStepPanel.Title = 'Rotate CCF (1st Step)';
            app.BlockingcutpitchLabel = uilabel(app.RotateCCF1stStepPanel);
            app.BlockingcutpitchLabel.Text = {'Blocking cut'; 'pitch (°):'};
            app.PitchSpinner = uispinner(app.RotateCCF1stStepPanel);
            app.BlockingcutyawLabel = uilabel(app.RotateCCF1stStepPanel);
            app.BlockingcutyawLabel.Text = {'Blocking cut'; 'yaw (°):'};
            app.YawSpinner = uispinner(app.RotateCCF1stStepPanel);
            app.RotateCCFVolumeButton = uibutton(app.RotateCCF1stStepPanel, 'push');
            app.RotateCCFVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCCFVolumeButtonPushed, true);
            app.RotateCCFVolumeButton.Text = 'Rotate CCF Volume';
            app.WarpsPanel = uipanel(app.UIFigure);
            app.WarpsPanel.Title = 'Warps';
            app.AffineWarpButton = uibutton(app.WarpsPanel, 'push');
            app.AffineWarpButton.ButtonPushedFcn = createCallbackFcn(app, @AffineWarpButtonPushed, true);
            app.AffineWarpButton.Text = 'Affine Warp';
            app.bsplineWarpButton = uibutton(app.WarpsPanel, 'push');
            app.bsplineWarpButton.ButtonPushedFcn = createCallbackFcn(app, @bsplineWarpButtonPushed, true);
            app.bsplineWarpButton.Text = 'b-spline Warp';
            app.ExportCCFButton = uibutton(app.UIFigure, 'push');
            app.ExportCCFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCCFButtonPushed, true);
            app.ExportCCFButton.Text = 'Export CCF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCFregister(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end