classdef HCRprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FunctionsPanel                 matlab.ui.container.Panel
        memMonitorButton               matlab.ui.control.Button
        RedoCheckBox                   matlab.ui.control.CheckBox
        DoAllButton                    matlab.ui.control.Button
        TableFileSwitch                matlab.ui.control.Switch
        RegRoundDropDown               matlab.ui.control.DropDown
        RegistrationRoundLabel         matlab.ui.control.Label
        SettingsPanel                  matlab.ui.container.Panel
        DeleteIntermediatesCheckBox    matlab.ui.control.CheckBox
        PostprocessingPanel            matlab.ui.container.Panel
        ManualMasksSwitch              matlab.ui.control.ToggleSwitch
        CCFFilteringSwitch             matlab.ui.control.ToggleSwitch
        CellposeMasksSwitch            matlab.ui.control.ToggleSwitch
        ManualMasksLabel               matlab.ui.control.Label
        ManualMasksLamp                matlab.ui.control.Lamp
        CellposeMasksLamp              matlab.ui.control.Lamp
        CellposeMasksLabel             matlab.ui.control.Label
        CCFFilteringLabel              matlab.ui.control.Label
        CCFFilteringLamp               matlab.ui.control.Lamp
        CoreProcessingPipelinePanel    matlab.ui.container.Panel
        FinishSwitch                   matlab.ui.control.ToggleSwitch
        FinishingLabel                 matlab.ui.control.Label
        FinishLamp                     matlab.ui.control.Lamp
        Image_4                        matlab.ui.control.Image
        UnmixSwitch                    matlab.ui.control.ToggleSwitch
        RegSwitch                      matlab.ui.control.ToggleSwitch
        Image_2                        matlab.ui.control.Image
        UnmixingLabel                  matlab.ui.control.Label
        StitchSwitch                   matlab.ui.control.ToggleSwitch
        RegistrationLabel              matlab.ui.control.Label
        StitchingLabel                 matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UnmixLamp                      matlab.ui.control.Lamp
        RegLamp                        matlab.ui.control.Lamp
        StitchLamp                     matlab.ui.control.Lamp
        DirTree                        matlab.ui.container.Tree
        ExperimentDirectoryNode        matlab.ui.container.TreeNode
        ExpDirButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        CoreprocessingpipelineroundspecificLabel  matlab.ui.control.Label
        ManualsettingsroundspecificLabel  matlab.ui.control.Label
        PostprocessingroundindependentLabel  matlab.ui.control.Label
        PostTable                      matlab.ui.control.Table
        SettingsTable                  matlab.ui.control.Table
        FilesTable                     matlab.ui.control.Table
        StitchingTab                   matlab.ui.container.Tab
        StitchingFunctionsPanel        matlab.ui.container.Panel
        StripeCorrectionExplanationLabel  matlab.ui.control.Label
        StripeCorrectionEditField      matlab.ui.control.EditField
        StripecorrectionLabel          matlab.ui.control.Label
        FusionMethodDropDown           matlab.ui.control.DropDown
        FusionmethodDropDownLabel      matlab.ui.control.Label
        ShadingCorrectionCheckBox      matlab.ui.control.CheckBox
        StitchAllButton                matlab.ui.control.Button
        StitchButton                   matlab.ui.control.Button
        StitchTable                    matlab.ui.control.Table
        SpectralUnmixingTab            matlab.ui.container.Tab
        UnmixFunctionsPanel            matlab.ui.container.Panel
        UnmixLinearCheckBox            matlab.ui.control.CheckBox
        UnmixAllButton                 matlab.ui.control.Button
        UnmixButton                    matlab.ui.control.Button
        UnmixParametersFunctionsPanel  matlab.ui.container.Panel
        UnmixParamButton               matlab.ui.control.Button
        UnmixTable                     matlab.ui.control.Table
        UnmixParamTable                matlab.ui.control.Table
        RegTab                         matlab.ui.container.Tab
        RegistrationFunctionsPanel     matlab.ui.container.Panel
        RegAllButton                   matlab.ui.control.Button
        RegButton                      matlab.ui.control.Button
        FiducialsFunctionsPanel        matlab.ui.container.Panel
        FiducialsButton                matlab.ui.control.Button
        RegTable                       matlab.ui.control.Table
        FiducialsTable                 matlab.ui.control.Table
        FinalTab                       matlab.ui.container.Tab
        FinishingFunctionsPanel        matlab.ui.container.Panel
        SubtractbackgroundCheckBox     matlab.ui.control.CheckBox
        ProcessAllButton               matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        RadiusEditField                matlab.ui.control.EditField
        RollingballradiusEditFieldLabel  matlab.ui.control.Label
        FinishTable                    matlab.ui.control.Table
        Histology                      matlab.ui.container.Tab
        HistologyFunctionsPanel        matlab.ui.container.Panel
        HistoProcessAllButton          matlab.ui.control.Button
        HistoProcessButton             matlab.ui.control.Button
        HistoTable                     matlab.ui.control.Table
        CCFRegistrationTab             matlab.ui.container.Tab
        IpsiContraLabel                matlab.ui.control.Label
        CCFRegisteredTable             matlab.ui.control.Table
        CCFRegistrationFunctionsPanel  matlab.ui.container.Panel
        CCFRegisterButton              matlab.ui.control.Button
        LaunchBlockCutButton           matlab.ui.control.Button
        PitchYawTable                  matlab.ui.control.Table
        SegmentationTab                matlab.ui.container.Tab
        SegmentationfunctionsPanel     matlab.ui.container.Panel
        ManualSegButton                matlab.ui.control.Button
        DoMaskAddInButton              matlab.ui.control.Button
        SeparatemultiplestructuresusingasinglecommawithnospaceLabel  matlab.ui.control.Label
        CCFStructuresEditField         matlab.ui.control.EditField
        CCFStructuresEditFieldLabel    matlab.ui.control.Label
        FilterMasksCCFButton           matlab.ui.control.Button
        MaskQCButton                   matlab.ui.control.Button
        AnisotropyEditField            matlab.ui.control.NumericEditField
        AnisotropyEditFieldLabel       matlab.ui.control.Label
        MinSizeEditField               matlab.ui.control.NumericEditField
        MinsizeEditFieldLabel          matlab.ui.control.Label
        StitchThreshEditField          matlab.ui.control.NumericEditField
        Stitchthreshold0to1EditFieldLabel  matlab.ui.control.Label
        GPUSegCheckBox                 matlab.ui.control.CheckBox
        SegAllButton                   matlab.ui.control.Button
        SegButton                      matlab.ui.control.Button
        SegTable                       matlab.ui.control.Table
        FinalQCTab                     matlab.ui.container.Tab
        FinalQCButton                  matlab.ui.control.Button
        FinalQCTable                   matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        HCR_kw = "HCR";                    % keyword that marks HCR image file
        histo_kw = "histo";                % keyword that marks histo file
        reg_round_token = 'r1'             % token that marks the registration round
        reg_channel_token = ["NT", ...
            "Snap25", "Snap", "neur", ...
            "nt"]                          % token(s) that mark registration channel

        exp_dir                            % experiment directory
        round_dirs                         % HCR round subdirectories
        histo_dir = ''                     % histology directory

        g_box                              % image holder for green check
        r_box                              % image holder for red x
        gray_box                           % image holder for gray skip

        uniq_ids_HCR = {}                  % cell array of strings containing all uniq_ids for HCR in the experiment
        uniq_ids_histo = {}                % cell array of strings containing all uniq_ids for histo in the experiment
        
        first_time_flag                    % whether this experiment has ever been opened before
        do_all_flag = false;               % is app currently running in 'do all' mode
        do_all_cache = {}                  % cell array of warnings stored by 'do all' mode

        stitch_sel                         % selected row in stitch table
        fiducials_sel                      % selected row in fiducials table
        reg_sel                            % selected row in registration table
        unmix_param_sel                    % selected row in unmix parameters table
        unmix_sel                          % selected row in unmixing table
        process_sel                        % selected row in final processing table
        histo_sel                          % selected row in histology table
        seg_sel                            % selected row in segmentation table
        ccf_reg_sel                        % selected row in ccf registration table
        final_qc_sel                       % selected row in final qc table
        post_processing_sel                % selected row in post processing table

        reg_round                          % registration round

        round_folder                       % currently selected round folder

        % Round tables (round-dependent, 1 for each round)
        T1                                 % table 1 (files table)
        T2                                 % table 2 (settings table)

        % Experiment tables (round-independent, 1 for whole experiment)
        post_processing_table              % postprocessing table
        post_processing_pipelines_table    % pipelines table for postprocessing operations
        pipelines_table                    % pipelines table for core operations
        histology_table                    % histology table
        pitch_yaw_table                    % pitch and yaw of each slice
        ccf_registered_table               % ccf registration table
        meta                               % metadata dictionary

        T1_literal                         % files table, but for actual files that can be found
        T2_literal                         % settings table, but for actual settings files that can be found 
        T3_literal                         % postprocessing table, but for actual files that can be found
        T5_literal                         % histology table, but for actual files that can be found

        tables                             % cell array of all tables throughout the app 

        pipeline                           % cell array of strings describing processing steps to do

        num_uniq                           % number of unique images (slices typically)
        num_process_steps                  % total number of possible processing steps
        num_manual_steps                   % total number of possible manual steps
        num_post_steps                     % total number of possible post-processing steps
        
    end
    
    methods (Access = private)

        function setPaths(~)

            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            folderName = params('fijiScriptsDir');
            addpath(folderName)

        end

        function prepareAssets(app)

            app.g_box = uistyle('BackgroundColor','green', ...
                'Icon','success', ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            app.r_box = uistyle('BackgroundColor','red', ...
                'Icon',fullfile('./','assets','Cross_red_circle.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            app.gray_box = uistyle('BackgroundColor',[0.7 0.7 0.7], ...
                'Icon',fullfile('./','assets','Twemoji2_1f5ff.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');

        end

        function setExperimentDirectory(app)

            filename = fullfile(pwd,'internal','previous_exp_dir.mat');
            load(filename, 'previous_exp_dir')
            list_dir = dir(previous_exp_dir);
            
            % ask for a new experiment directory if the previous one no
            % longer exists, otherwise use the previous one
            if isempty(list_dir)
                app.exp_dir = uigetdir();
                previous_exp_dir = app.exp_dir;
                save(fullfile(pwd,'internal','previous_exp_dir.mat'), 'previous_exp_dir')
            else
                app.exp_dir = previous_exp_dir;
            end

            app.ExperimentDirectoryNode.Text = app.exp_dir;

        end

        function setHCRSubdirectories(app)
            
            % identify HCR subdirectories and write them to the directory
            % tree
            listing = fetch_dir(app.exp_dir, 'dir');
            listing = filt_kw(listing,app.HCR_kw,'keep');
            for i=1:length(listing)
                node = uitreenode(app.ExperimentDirectoryNode,'Text',listing{i}); %#ok<NASGU>
            end

            % also store HCR subdirectories in app.round_dirs
            num_rounds = length(app.ExperimentDirectoryNode.Children);
            app.round_dirs = cell(1,num_rounds);
            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.round_dirs{i} = app.ExperimentDirectoryNode.Children(i).Text;
            end

            if isempty(app.round_dirs)
                message = ['No HCR round directories found. Make sure your experiment' ...
                    ' directory contains at least 1 round directory.'];
                uialert(app.UIFigure, message, 'No rounds')
            end

        end

        function setHistoSubdirectory(app)
            
            listing = fetch_dir(app.exp_dir,'dir');
            listing = filt_kw(listing,app.histo_kw,'keep');
            if ~isempty(listing)
                app.histo_dir = listing{1};
            else
                app.histo_dir = {};
            end

        end

        function uniq_ids = getUniqueIDs(app, sub_dir, kw)
            
            % make sure this subdirectory actually exists
            if isempty(fullfile(app.exp_dir,sub_dir))
                uniq_ids = {};
                return
            end

            % get all the files from the 'raw' subdirectory of the
            % requested subdirectory; if that doesn't exist just get them
            % straight from the requested subdirectory
            listing = fetch_dir(fullfile(app.exp_dir,sub_dir,'raw'), 'file');
            if isempty(listing)
                listing = fetch_dir(fullfile(app.exp_dir,sub_dir), 'file');
            end
            
            % filter by keyword
            listing = filt_kw(listing, kw, 'keep');

            % iterate through uniq_ids
            if ~isempty(listing)
                for i=1:length(listing)
                    split_str = split(listing{i},'__');
                    listing{i} = split_str{1};
                end
            end

            uniq_ids = listing;

        end

        function setFirstTimeFlag(app)

            % whether this experiment has been opened before
            app.first_time_flag = ~isfolder(fullfile(app.exp_dir,app.round_dirs{1},'internal'));

        end
        
        % populate internal folders, only needs to run once on startup
        function populateInternalFolders(app)
            
            % big confirmation dialog box to make sure everything is named
            % and organized appropriately the first time an experiment
            % directory is opened
            if app.first_time_flag == true

                txt_prompt = "It looks like this is your first time opening " + ...
                    "this experiment directory. Please verify that the " + ...
                    "following details are correct. If so, press 'Continue', " + ...
                    "otherwise press 'Cancel' and reorganize the input " + ...
                    "experiment directory appropriately.";

                txt_prompt = txt_prompt + newline + newline + sprintf('%u HCR subdirectories were found:',length(app.round_dirs));

                for i=1:length(app.round_dirs)
                    txt_prompt = txt_prompt + newline + app.round_dirs{i};
                end

                txt_prompt = txt_prompt + newline + newline + sprintf('%u HCR files were found:',length(app.uniq_ids_HCR));
                for i=1:length(app.uniq_ids_HCR)
                    txt_prompt = txt_prompt + newline + app.uniq_ids_HCR{i};
                end

                if ~isempty(app.uniq_ids_histo)
                    txt_prompt = txt_prompt + newline +newline + sprintf("A histology subdirectory was found, it contains %u files:",length(app.uniq_ids_histo));
                    for i=1:length(app.uniq_ids_histo)
                        txt_prompt = txt_prompt + newline + app.uniq_ids_histo{i};
                    end
                else
                    txt_prompt = txt_prompt + newline + newline + "No histology subdirectory was found";
                end
                
                fig = app.UIFigure;
                choice = uiconfirm(fig,txt_prompt, ...
                    'First time opening experiment','Icon','question', ...
                    "Options",["Continue","Cancel"], ...
                    "DefaultOption",1, ...
                    "CancelOption",2);

                switch choice
                    case 'Continue'
                        % do nothing
                    case 'Cancel'
                        app.delete
                end
                
            end

            % iterate through rounds
            for i=1:length(app.round_dirs)

                roundf = app.round_dirs{i};

                % move raw images to a raw subfolder if this round folder
                % has never been opened before
                check_internal = fullfile(app.exp_dir,roundf,'internal');
                if exist(check_internal,'dir') == false
                    app.moveToRaw(roundf)
                end
                
                % make sure all the round specific subdirectories are
                % there, and make them if they're not
                check_build_dir(check_internal)
                check_stitch = fullfile(app.exp_dir,roundf,'stitch');
                check_build_dir(check_stitch)
                check_reg = fullfile(app.exp_dir,roundf,'reg');
                check_build_dir(check_reg)
                check_unmix = fullfile(app.exp_dir,roundf,'unmix');
                check_build_dir(check_unmix)
                check_fiducials = fullfile(app.exp_dir,roundf,'internal','fiducials');
                check_build_dir(check_fiducials)
                check_unmix_param = fullfile(app.exp_dir,roundf,'internal','uparams');
                check_build_dir(check_unmix_param)

            end
            
            % make sure all the experiment-wide, non-round-specific
            % subdirectories are there, and make them if they're not
            check_post = fullfile(app.exp_dir,'post');
            check_build_dir(check_post)
            check_internal = fullfile(app.exp_dir,'internal');
            check_build_dir(check_internal)
            check_core_output = fullfile(app.exp_dir,'post','core_output');
            check_build_dir(check_core_output)
            check_masks = fullfile(app.exp_dir,'post','masks');
            check_build_dir(check_masks)
            check_CCF_vols = fullfile(app.exp_dir,'post','CCF_vols');
            check_build_dir(check_CCF_vols)
            
            % add slice-specific subdirectories to the previously made
            % folders that need them
            for i=1:length(app.uniq_ids_HCR)
                check_build_dir(fullfile(check_core_output,app.uniq_ids_HCR{i}))
                check_build_dir(fullfile(check_masks,app.uniq_ids_HCR{i}))
                check_build_dir(fullfile(check_CCF_vols,app.uniq_ids_HCR{i}))
            end
            
            % histology folders (if the histology subdirectory exists)
            if ~isempty(app.histo_dir)

                check_internal = fullfile(app.exp_dir,app.histo_dir,'internal');
                
                % if first time opening histology subdirectory
                if ~exist(check_internal,'dir')
                    app.moveToRaw(app.histo_dir)
                end
                
                check_build_dir(check_internal)
                check_processed = fullfile(app.exp_dir,app.histo_dir,'processed');
                check_build_dir(check_processed)

            end

        end

        function prepareMeta(app)
            
            if app.first_time_flag == true

                app.createMeta()
                
                % registration round shenanigans 
                for i=1:length(app.round_dirs)
                    if contains(app.round_dirs{i}, app.reg_round_token)
                        app.reg_round = app.round_dirs{i};
                    end
                end

                if ~isempty(app.reg_round)
                    app.meta.reg_round = app.reg_round;
                else
                    msgbox("No round containing the registration token found. Registration round will have to be set manually later", "Registration warning", "warn")
                    app.meta.reg_round = [];
                end
                
                app.createStatusTablesExperiment()
                check_internal = fullfile(app.exp_dir,'internal');
                check_build_dir(check_internal)
                app.saveMeta();

            else

                app.loadMeta()
                app.createStatusTablesExperiment()
                
                % checking for new rounds and adding rows accordingly
                known_rounds = app.pipelines_table.Properties.RowNames;
                current_rounds = app.round_dirs;
                Lia = ismember(current_rounds,known_rounds);
                new_rounds = current_rounds(~Lia);

                if ~isempty(new_rounds)
                    for i=1:length(new_rounds)
                        T = table(1,1,1,1, ...
                            'VariableNames',{'stitch' 'unmix' 'reg' 'finish'}, ...
                            'RowNames',new_rounds(i));
                        app.pipelines_table = [app.pipelines_table;T];
                    end
                    app.saveMeta()
                end

            end

        end

        function prepareRoundTables(app)

            % set row names of each table to uniq_ids
            row_names = app.uniq_ids_HCR;
            app.FilesTable.RowName = row_names;
            app.SettingsTable.RowName = row_names;
            app.StitchTable.RowName = row_names;
            app.RegTable.RowName = row_names;
            app.FiducialsTable.RowName = row_names;
            app.UnmixTable.RowName = row_names;
            app.UnmixParamTable.RowName = row_names;
            app.FinishTable.RowName = row_names;

        end

        function prepareExperimentTables(app)

            row_names = app.uniq_ids_HCR;
            app.PostTable.RowName = row_names;
            app.SegTable.RowName = row_names;
            app.PitchYawTable.RowName = row_names;
            app.CCFRegisteredTable.RowName = row_names;
            app.FinalQCTable.RowName = row_names;

            row_names = app.uniq_ids_histo;
            app.HistoTable.RowName = row_names;

        end

        function prepareTables(app)
            app.prepareRoundTables()
            app.prepareExperimentTables()
        end

        % populate the reg round dropdown
        function initializeRegistrationRound(app)

            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.RegRoundDropDown.Items{end+1} = app.ExperimentDirectoryNode.Children(i).Text;
            end

            if ~isempty(app.reg_round)
                app.RegRoundDropDown.Value = app.reg_round;
            else
                app.RegRoundDropDown.Items{end+1} = '';
                app.RegRoundDropDown.Value = '';
            end

        end
        
        % only ever called once in prepareMeta(), when an experimental
        % directory is opened for the first time
        function createMeta(app)
            app.meta = struct;
            app.meta.reg_round = [];
            app.meta.post_processing_table = [];
            app.meta.pipelines_table = [];
            app.meta.post_processing_pipelines_table = [];
            app.meta.histology_table = [];
            app.meta.pitch_yaw_table = [];
            app.meta.ccf_registered_table = [];
            app.meta.uniq_ids_HCR = app.uniq_ids_HCR;
            app.meta.uniq_ids_histo = app.uniq_ids_histo;
        end

        function saveMeta(app)
            meta_fn = fullfile(app.exp_dir,'internal','metadata.mat');
            meta = app.meta; 
            save(meta_fn,"meta")
        end

        function loadMeta(app)
            load(fullfile(app.exp_dir,'internal','metadata.mat'),"meta")
            app.meta = meta; 
            app.reg_round = app.meta.reg_round;
            app.post_processing_table = app.meta.post_processing_table;
            app.pipelines_table = app.meta.pipelines_table;
            app.post_processing_pipelines_table = app.meta.post_processing_pipelines_table;
            app.histology_table = app.meta.histology_table;
            app.pitch_yaw_table = app.meta.pitch_yaw_table;
            app.ccf_registered_table = app.meta.ccf_registered_table;
            app.uniq_ids_HCR = app.meta.uniq_ids_HCR;
            app.uniq_ids_histo = app.meta.uniq_ids_histo;
        end
        
        % move all the raw files to a raw subdirectory, runs when a round
        % folder is opened for the first time
        function moveToRaw(app, roundf)
            
            mkdir(fullfile(app.exp_dir,roundf,'raw'))

            listing = fetch_dir(fullfile(app.exp_dir,roundf),'file');
            
            if ~isempty(listing)
                for i=1:length(listing)
                    movefile(fullfile(app.exp_dir,roundf,listing{i}), fullfile(app.exp_dir,roundf,'raw'))
                end
            end

            listing = fetch_dir(fullfile(app.exp_dir,roundf),'dir');
            listing(ismember(listing,'raw')) = [];
            
            if ~isempty(listing)
                for i=1:length(listing)
                    movefile(fullfile(app.exp_dir,roundf,listing{i}), fullfile(app.exp_dir,roundf,'raw'))
                end
            end

        end
        
        function updateRound(app)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder, ...
                'internal'), 'file');

            % inherit pre-existing round status tables if they exist
            if any(strcmp(listing,'T1.txt'))
                app.loadRoundTables()

            % or else make new ones
            else
                app.createStatusTablesRound()
            end

            app.num_uniq = size(app.T1,1);
            app.num_process_steps = size(app.T1,2);
            app.num_manual_steps = size(app.T2,2);
            
            app.updateTablesRound()
            app.loadPipeline()
            app.writeTablesRound()

        end

        function updateTablesRound(app)
            
            % sets the Data for each table to an empty cell array
            app.FilesTable.Data = cell(app.num_uniq,app.num_process_steps);
            app.SettingsTable.Data = cell(app.num_uniq,app.num_manual_steps);
            app.StitchTable.Data = cell(app.num_uniq,1);
            app.RegTable.Data = cell(app.num_uniq,1);
            app.FiducialsTable.Data = cell(app.num_uniq,1);
            app.FinishTable.Data = cell(app.num_uniq,1);
            app.UnmixTable.Data = cell(app.num_uniq,1);
            app.UnmixParamTable.Data = cell(app.num_uniq,1);

            switch app.TableFileSwitch.Value

                case "Status Tables"
                    
                    app.updateTableVisual(table2array(app.T1),app.FilesTable)
                    app.updateTableVisual(table2array(app.T2),app.SettingsTable)
                    app.updateTableVisual(app.T1.Stitched,app.StitchTable)
                    app.updateTableVisual(app.T1.Registered,app.RegTable)
                    app.updateTableVisual(app.T2.Fiducials,app.FiducialsTable)
                    app.updateTableVisual(app.T1.Finished,app.FinishTable)
                    app.updateTableVisual(app.T1.Unmixed,app.UnmixTable)
                    app.updateTableVisual(app.T2.("Unmix Settings"),app.UnmixParamTable)

                case "Files"

                    app.update_literal_tables()
                    app.updateTableVisual(table2array(app.T1_literal),app.FilesTable)
                    app.updateTableVisual(table2array(app.T2_literal),app.SettingsTable)
                    app.updateTableVisual(app.T1_literal.Stitched,app.StitchTable)
                    app.updateTableVisual(app.T1_literal.Registered,app.RegTable)
                    app.updateTableVisual(app.T2_literal.Fiducials,app.FiducialsTable)
                    app.updateTableVisual(app.T1_literal.Finished,app.FinishTable)
                    app.updateTableVisual(app.T1_literal.Unmixed,app.UnmixTable)
                    app.updateTableVisual(app.T2_literal.("Unmix Settings"),app.UnmixParamTable)
                    app.updateTableVisual(table2array(app.T3_literal),app.PostTable)
                    app.updateTableVisual(app.T5_literal.Processed,app.HistoTable)
                    
            end

            app.updateTablesPipeline()
            
        end

        function loadRoundTables(app)

            app.T1 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'));
            app.T2 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'));

        end

        function updateExperiment(app)

            app.num_post_steps = size(app.post_processing_table,2);

            app.updateTablesExperiment()
            app.writeTablesExperiment()

        end

        function updateTablesExperiment(app)

            app.PostTable.Data = cell(app.num_uniq,app.num_post_steps);
            app.SegTable.Data = cell(app.num_uniq,5);
            app.PitchYawTable.Data = cell(app.num_uniq,2);
            app.CCFRegisteredTable.Data = cell(app.num_uniq,2);
            app.FinalQCTable.Data = cell(app.num_uniq,1);

            % histology table is special because it may or may not be there
            if ~isempty(app.histo_dir)
                num_uniq_histo = length(app.uniq_ids_histo);
                app.HistoTable.Data = cell(num_uniq_histo,1);
            end

            app.updateTableVisual(table2array(app.post_processing_table),app.PostTable)
            app.updateTableVisual(table2array(app.post_processing_table(:,["Manual Masks" "Cellpose Masks" "Integration" "CCF Filtering", "Mask QC"])),app.SegTable)
            app.updateTableVisual(table2array(app.ccf_registered_table),app.CCFRegisteredTable)
            app.updateTableVisual(table2array(app.post_processing_table(:,"Final QC")),app.FinalQCTable)
            app.PitchYawTable.Data = app.pitch_yaw_table;

            if ~isempty(app.histo_dir)
                app.updateTableVisual(app.histology_table.Processed,app.HistoTable)
            end

            app.updateTablesPostProcessingPipeline()

        end

        function updateTableVisual(app, backend_arr, frontend_table)

            nrows = size(frontend_table.Data,1);
            ncols = size(frontend_table.Data,2);

            for j=1:nrows
                for k=1:ncols
                    switch backend_arr(j,k)
                        case 0
                            addStyle(frontend_table,app.r_box,'cell',[j,k])
                        case 1
                            addStyle(frontend_table,app.g_box,'cell',[j,k])
                        otherwise
                            continue
                    end
                end
            end
               
        end

        % go back at the end and blur out the steps that aren't being done
        function updateTablesPipeline(app)

            if app.pipelines_table(app.round_folder,:).stitch == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,app.gray_box,'cell',[i,1])
                    addStyle(app.StitchTable,app.gray_box,'cell',[i,1])
                end
            end

            if app.pipelines_table(app.round_folder,:).unmix == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,app.gray_box,'cell',[i,2])
                    addStyle(app.UnmixTable,app.gray_box,'cell',[i,1])
                    addStyle(app.UnmixParamTable,app.gray_box,'cell',[i,1])
                    addStyle(app.SettingsTable,app.gray_box,'cell',[i,1])
                end
            end

            if app.pipelines_table(app.round_folder,:).reg == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,app.gray_box,'cell',[i,3])
                    addStyle(app.RegTable,app.gray_box,'cell',[i,1])
                    addStyle(app.FiducialsTable,app.gray_box,'cell',[i,1])
                    addStyle(app.SettingsTable,app.gray_box,'cell',[i,2])
                end
            end
            
            if app.pipelines_table(app.round_folder,:).finish == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,app.gray_box,'cell',[i,4])
                    addStyle(app.FinishTable,app.gray_box,'cell',[i,1])
                end
            end

        end

        % go back at the end and blur out the steps that aren't being done
        function updateTablesPostProcessingPipeline(app)

            for i=1:length(app.uniq_ids_HCR)
                if app.post_processing_pipelines_table{i,1} == 0
                    addStyle(app.PostTable,app.gray_box,'cell',[i,2])
                    addStyle(app.PostTable,app.gray_box,'cell',[i,4])
                    addStyle(app.SegTable,app.gray_box,'cell',[i,1])
                    addStyle(app.SegTable,app.gray_box,'cell',[i,3])
                end
                if app.post_processing_pipelines_table{i,2} == 0
                    addStyle(app.PostTable,app.gray_box,'cell',[i,3])
                    addStyle(app.PostTable,app.gray_box,'cell',[i,4])
                    addStyle(app.SegTable,app.gray_box,'cell',[i,2])
                    addStyle(app.SegTable,app.gray_box,'cell',[i,3])
                end
                if app.post_processing_pipelines_table{i,3} == 0
                    addStyle(app.PostTable,app.gray_box,'cell',[i,5])
                    addStyle(app.SegTable,app.gray_box,'cell',[i,4])
                end
            end
            
        end

        function update_literal_tables(app)
            
            % check for existence of uniq ids as either files or folders
            % in a folder, type = 'file' or 'dir', returns column vector 
            % of length N, where N is the number of uniq_ids, composed of
            % 1s and 0s
            function bin_col = assay_folder(folder, uniq_ids, type)

                bin_col = zeros(length(uniq_ids),1);
                listing = fetch_dir(folder, type);
                for i=1:length(uniq_ids)
                    if any(contains(listing,uniq_ids(i)))
                        bin_col(i) = 1;
                    end
                end

            end

            app.T1_literal = app.T1;
            app.T2_literal = app.T2;
            app.T3_literal = app.post_processing_table;
            app.T5_literal = app.histology_table;

            app.T1_literal.Stitched = assay_folder(fullfile(app.exp_dir,app.round_folder,'stitch'),app.uniq_ids_HCR,'file');
            app.T1_literal.Registered = assay_folder(fullfile(app.exp_dir,app.round_folder,'reg'),app.uniq_ids_HCR,'file');
            app.T1_literal.Unmixed = assay_folder(fullfile(app.exp_dir,app.round_folder,'unmix'),app.uniq_ids_HCR,'file');
            app.T2_literal.Fiducials = assay_folder(fullfile(app.exp_dir,app.round_folder,'internal','fiducials'),app.uniq_ids_HCR,'file');
            app.T2_literal.('Unmix Settings') = assay_folder(fullfile(app.exp_dir,app.round_folder,'internal','uparams'),app.uniq_ids_HCR,'file');
            app.T5_literal.Processed = assay_folder(fullfile(app.exp_dir,app.histo_dir,'processed'),app.uniq_ids_histo,'file');
            
            bin_col = zeros(length(app.uniq_ids_HCR),1);
            split_str = split(app.round_folder,'_');
            round_id = split_str{1};

            % iterate through slices, checking for the current round
            for j=1:length(app.uniq_ids_HCR)
                listing = fetch_dir(fullfile(app.exp_dir,'post','core_output',app.uniq_ids_HCR{j}),'file');
                if any(contains(listing,round_id))
                        bin_col(j) = 1;
                end
            end

            app.T1_literal.Finished = bin_col;

        end

        function writeTablesRound(app)

            writetable(app.T1, fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'), ...
                    'WriteRowNames',true);
            writetable(app.T2, fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'), ...
                    'WriteRowNames',true);

        end

        function writeTablesExperiment(app)

            app.meta.post_processing_table = app.post_processing_table;
            app.meta.pipelines_table = app.pipelines_table;
            app.meta.post_processing_pipelines_table = app.post_processing_pipelines_table;
            app.meta.pitch_yaw_table = app.pitch_yaw_table;
            app.meta.ccf_registered_table = app.ccf_registered_table;
            if ~isempty(app.histo_dir)
                app.meta.histology_table = app.histology_table;
            end

            app.saveMeta()

        end
        
        % build the full file name of an image given its unique identifier
        % and subfolder
        function img_f = build_img_f(app, img, subfolder, roundf, mode, linear)
            
            arguments
                app
                img
                subfolder
                roundf = app.round_folder
                mode = 'HCR'
                linear = false
            end

            listing = fetch_dir(fullfile(app.exp_dir,roundf,subfolder),'file');
            
            switch mode
                case 'HCR'
                    listing = filt_kw(listing, app.HCR_kw, 'keep');
                case 'histo'
                    listing = filt_kw(listing, app.histo_kw, 'keep');
            end

            if sum(contains(listing, img)) == 0
                img_f = [];
                return
            end

            if linear
                full_img = listing{contains(listing, img) & contains(listing, 'lin')};
            else
                full_img = listing{contains(listing, img)};
            end
            
            img_f = fullfile(app.exp_dir,roundf,subfolder,full_img);
           
        end
        
        % create new status tables
        function createStatusTablesRound(app)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder,'raw'), 'file');
            listing = filt_kw(listing, app.HCR_kw, 'keep');
            
            % make sure there are raw files in this round folder
            if length(listing) < 1
                app.T1 = [];
                app.T2 = [];
                updateTablesRound(app)
                msgbox("No raw files or status tables found in this round folder")
                return
            end

            nfiles = length(app.uniq_ids_HCR);
            
            app.T1 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'Stitched' 'Unmixed' 'Registered' 'Finished'}, ...
                'RowNames',app.uniq_ids_HCR);

            app.T2 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'Unmix Settings' 'Fiducials'}, ...
                'RowNames',app.uniq_ids_HCR);

            app.writeTablesRound()

        end

        function createStatusTablesExperiment(app)
            
            nslices_HCR = length(app.uniq_ids_HCR);
            nslices_histo = length(app.uniq_ids_histo);
            nrounds = length(app.round_dirs);

            if isempty(app.post_processing_table) || app.first_time_flag
                app.post_processing_table = table(zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    'VariableNames',{'CCF Registered' 'Manual Masks' 'Cellpose Masks' 'Integration' 'CCF Filtering' 'Mask QC', 'Final QC'}, ...
                    'RowNames',app.uniq_ids_HCR);
            end

            if isempty(app.post_processing_pipelines_table) || app.first_time_flag
                app.post_processing_pipelines_table = table(ones(nslices_HCR,1), ...
                    ones(nslices_HCR,1), ...
                    ones(nslices_HCR,1), ...
                    'VariableNames',{'Manual Masks', 'Cellpose Masks', 'CCF Filtering'}, ...
                    'RowNames',app.uniq_ids_HCR);
            end

            if isempty(app.pipelines_table) || app.first_time_flag
                app.pipelines_table = table(ones(nrounds,1), ...
                    ones(nrounds,1), ...
                    ones(nrounds,1), ...
                    ones(nrounds,1), ...
                    'VariableNames',{'stitch' 'unmix' 'reg' 'finish'}, ...
                    'RowNames',app.round_dirs);
            end

            if isempty(app.ccf_registered_table) || app.first_time_flag
                app.ccf_registered_table = table(zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    'VariableNames',{'CCF Registration Settings' 'CCF Registered'}, ...
                    'RowNames',app.uniq_ids_HCR);
            end

            if isempty(app.pitch_yaw_table) || app.first_time_flag
                app.pitch_yaw_table = table(zeros(nslices_HCR,1), ...
                    zeros(nslices_HCR,1), ...
                    repelem({'i'},nslices_HCR)', ...
                    'VariableNames',{'Pitch' 'Yaw' 'Ipsi/Contra'}, ...
                    'RowNames',app.uniq_ids_HCR);
            end

            if (isempty(app.histology_table) && ~isempty(app.histo_dir)) || app.first_time_flag
                app.histology_table = table(zeros(nslices_histo,1), ...
                    'VariableNames',{'Processed'}, ...
                    'RowNames',app.uniq_ids_histo);
            end

        end
        
        % takes in file name and returns index of registration channel
        % according to preset registration channel token
        function ind = id_reg_channel(app,fn)

            split_fn = split(fn,'__');
            chans = split_fn{2};
            split_chans = split(chans,'_');

            for i=1:length(split_chans)

                if contains(split_chans{i},app.reg_channel_token,'IgnoreCase',true)
                    ind = i;
                    return
                end
            end
        end

        function updatePipelineInternal(app)

            d = dictionary('On',1,'Off',0);
            new_table = app.pipelines_table(app.round_folder,:);
            new_table.stitch = d(app.StitchSwitch.Value);
            new_table.unmix = d(app.UnmixSwitch.Value);
            new_table.reg = d(app.RegSwitch.Value);
            new_table.finish = d(app.FinishSwitch.Value);
            app.pipelines_table(app.round_folder,:) = new_table;

            app.updateTablesRound()
            app.writeTablesExperiment()

        end

        function updatePostProcessingPipelineInternal(app)

            if isempty(app.post_processing_sel)
                app.post_processing_sel = app.uniq_ids_HCR{1};
            end

            d = dictionary('On',1,'Off',0);
            new_table = app.post_processing_pipelines_table(app.post_processing_sel,:);
            new_table.('Manual Masks') = d(app.ManualMasksSwitch.Value);
            new_table.('Cellpose Masks') = d(app.CellposeMasksSwitch.Value);
            new_table.('CCF Filtering') = d(app.CCFFilteringSwitch.Value);
            app.post_processing_pipelines_table(app.post_processing_sel,:) = new_table;
            
            app.updateTablesExperiment()
            app.writeTablesExperiment()

        end
            
        % MATLAB, in its infinite wisdom, doesn't allow dictionary keys
        % of type double
        function output = non_shitty_dict(~, input)

            if input == 1
                output = 'On';
            elseif input == 0
                output = 'Off';
            end

        end
        
        % called on startup and when round is changed
        function loadPipeline(app)

            app.StitchSwitch.Value = app.non_shitty_dict(app.pipelines_table(app.round_folder,:).stitch);
            app.RegSwitch.Value = app.non_shitty_dict(app.pipelines_table(app.round_folder,:).reg);
            app.UnmixSwitch.Value = app.non_shitty_dict(app.pipelines_table(app.round_folder,:).unmix);
            app.updatePipelineLamps()

        end

        function loadPostProcessingPipeline(app)

            if isempty(app.post_processing_sel)
                app.post_processing_sel = app.uniq_ids_HCR{1};
            end

            app.ManualMasksSwitch.Value = app.non_shitty_dict(app.post_processing_pipelines_table(app.post_processing_sel,:).('Manual Masks'));
            app.CellposeMasksSwitch.Value = app.non_shitty_dict(app.post_processing_pipelines_table(app.post_processing_sel,:).('Cellpose Masks'));
            app.CCFFilteringSwitch.Value = app.non_shitty_dict(app.post_processing_pipelines_table(app.post_processing_sel,:).('CCF Filtering'));
            app.updatePipelineLamps()

        end

        % called when switches change, purely visual updates
        function updatePipelineLamps(app)

            function check_switch(pipeline_switch, lamp)
                if strcmp(pipeline_switch.Value,'On')
                    lamp.Color = [0 1 0];
                else
                    lamp.Color = [0.8 0.8 0.8];
                end
            end
            
            check_switch(app.StitchSwitch,app.StitchLamp)
            check_switch(app.RegSwitch,app.RegLamp)
            check_switch(app.UnmixSwitch,app.UnmixLamp)
            check_switch(app.FinishSwitch,app.FinishLamp)
            check_switch(app.ManualMasksSwitch,app.ManualMasksLamp)
            check_switch(app.CellposeMasksSwitch,app.CellposeMasksLamp)
            check_switch(app.CCFFilteringSwitch,app.CCFFilteringLamp)

        end
        
        % returns input directory for a given operation "op" and round
        % folder "roundf"
        % i.e. for a given round and operation, where should you look for
        % the input file
        function input_dir = calc_core_IO(app,op,roundf)

            arguments
                app
                op
                roundf = app.round_folder
            end

            round_T4 = app.pipelines_table(roundf,:);
            op_idx = find(strcmp(round_T4.Properties.VariableNames,op));

            input_idx = op_idx - 1;

            while input_idx > 0
                input_dir = round_T4.Properties.VariableNames{input_idx};
                if round_T4{:,input_dir} == 1
                    return
                else
                    input_idx = input_idx - 1;
                end
            end

        end
        
        % dialog box called when user attempts to process a file that has
        % already been processed
        function choice = verif_dialog(app,txt_prompt)
            
            fig = app.UIFigure;
            choice = uiconfirm(fig,txt_prompt, ...
                'File already processed','Icon','warning', ...
                "Options",["Yes","No"], ...
                "DefaultOption",1, ...
                "CancelOption",2);

        end
        
        % delete unnecessary files saved by registration 
        function clear_reg_trash(~)
            
            delete('result.0.nii')
            delete('result.1.nii')
            delete('TransformParameters.0.txt')
            delete('TransformParameters.1.txt')

        end

        function fixed_fn = find_fixed_im(app,sel)

            fixed_fn = app.build_img_f(sel, 'stitch', app.reg_round, 'HCR', false);

            if isempty(fixed_fn)
                fixed_fn = app.build_img_f(sel,'unmix',app.reg_round, 'HCR', false);
            end

        end

        
        function report_do_all_cache(app)

            if ~isempty(app.do_all_cache)
                for i=1:length(app.do_all_cache)
                    disp(app.do_all_cache{i})
                end
            else
                disp('All operations completed successfully.')
            end
            
            app.do_all_cache = {};

        end

        function seg_image(app, input_f, end_tag)

            arguments
                app
                input_f
                end_tag = '_cp_masks.tif'
            end

            command1 = 'activate cellpose && ';
            
            switch app.GPUSegCheckBox.Value
                case 1
                    str1 = 'python -m cellpose --verbose --use_gpu --image_path ';
                case 0
                    str1 = 'python -m cellpose --verbose --image_path ';
            end

            str2 = [' --pretrained_model cpsam ' ...
                sprintf('--stitch_threshold %g ',app.StitchThreshEditField.Value) ...
                sprintf('--min_size %u ',app.MinSizeEditField.Value) ...
                sprintf('--anisotropy %u ',app.AnisotropyEditField.Value) ...
                '--save_tif ' ...
                '--no_npy'];
            
            command2 = [str1, input_f, str2];

            system([command1 command2]);

            % fetch the uniq_id of the image being segmented
            [filepath,~,~] = fileparts(input_f);
            [~,name,~] = fileparts(filepath);
            uniq_id = name;
            
            % masks tif that was just generated
            [filepath,name,~] = fileparts(input_f);
            source = [fullfile(filepath,name) '_cp_masks.tif'];
            
            % destination in the masks folder
            [filepath,~,~] = fileparts(input_f);
            [filepath,~,~] = fileparts(filepath);
            [filepath,~,~] = fileparts(filepath);
            destination = fullfile(filepath,'masks',uniq_id,[uniq_id end_tag]);

            movefile(source,destination)

        end

    end
    
    
    methods (Access = public)
        
        function finish_fiducials(app)
            
            fid_sel_idx = find(strcmp(app.FiducialsTable.RowName, app.fiducials_sel));
            app.T2(fid_sel_idx,:).Fiducials = 1;
            updateTablesRound(app)
            writeTablesRound(app)
            
        end

        function finish_unmix_params(app)

            unmix_param_sel_idx = find(strcmp(app.UnmixParamTable.RowName, app.unmix_param_sel));
            app.T2(unmix_param_sel_idx,:).("Unmix Settings") = 1;
            updateTablesRound(app)
            writeTablesRound(app)

        end

        function finish_unmix(app)

            unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, app.unmix_sel));
            app.T1(unmix_sel_idx,:).Unmixed = 1;
            updateTablesRound(app)
            writeTablesRound(app)

        end

        function finish_block_cut(app,pitch,yaw)
            num_files = size(app.pitch_yaw_table,1);
            for i=1:num_files
                app.pitch_yaw_table(i,{'Pitch','Yaw'}) = {pitch,yaw};
            end
            app.updateTablesExperiment()
            app.writeTablesExperiment()
        end

        function finish_ccf_register_settings(app,pitch,yaw,sample_dir)
            
            [~,sample_name,~] = fileparts(sample_dir);
            app.pitch_yaw_table(sample_name,1:2) = {pitch,yaw};
            app.ccf_registered_table(sample_name,'CCF Registration Settings') = {1};

            app.updateTablesExperiment()
            app.writeTablesExperiment()

        end

        function finish_ccf_register(app,pitch,yaw,sample_dir)
            
            [~,sample_name,~] = fileparts(sample_dir);
            app.pitch_yaw_table(sample_name,1:2) = {pitch,yaw};
            app.ccf_registered_table(sample_name,'CCF Registration Settings') = {1};
            app.ccf_registered_table(sample_name,'CCF Registered') = {1};

            app.updateTablesExperiment()
            app.writeTablesExperiment()

        end

        function finish_mask_qc(app,uniq_id)
            app.post_processing_table(uniq_id,'Mask QC') = {1};
            app.updateTablesExperiment()
            app.writeTablesExperiment()
        end


        function finish_manual_masks(app,uniq_id)
            app.post_processing_table(uniq_id, 'Manual Masks') = {1};
            app.updateTablesExperiment()
            app.writeTablesExperiment()
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.UIFigure.Name = 'HCRprocess';
            app.tables = {app.FilesTable, app.SettingsTable, app.StitchTable, ...
                app.RegTable, app.FiducialsTable, app.UnmixTable, app.UnmixParamTable, ...
                app.FinishTable};

            app.setPaths()
            app.prepareAssets()
            app.setExperimentDirectory()
            app.setHCRSubdirectories()
            app.setHistoSubdirectory()
            app.setFirstTimeFlag()

            if app.first_time_flag == true
                app.uniq_ids_HCR = app.getUniqueIDs(app.round_dirs{1}, app.HCR_kw);
                app.uniq_ids_histo = app.getUniqueIDs(app.histo_dir,app.histo_kw);
            end
            
            app.prepareMeta()
            app.populateInternalFolders();
            app.prepareTables()
            app.initializeRegistrationRound();
            
            % expand experiment directory and select first round (r0)
            expand(app.ExperimentDirectoryNode)
            app.DirTree.SelectedNodes = app.ExperimentDirectoryNode.Children(1);
            app.round_folder = app.DirTree.SelectedNodes(1).Text;
            app.updateRound();
            app.updateExperiment()
            
        end

        % Button pushed function: ExpDirButton
        function ExpDirButtonPushed(app, event)
            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.ExperimentDirectoryNode.Children(1).delete
            end
            previous_exp_dir = '';
            save(fullfile(pwd,'internal','previous_exp_dir.mat'), 'previous_exp_dir')
            app.startupFcn()
        end

        % Selection changed function: DirTree
        function DirTreeSelectionChanged(app, event)
            
            selectedNodes = app.DirTree.SelectedNodes;
            
            % don't do anything if experiment directory supernode was
            % accidentally clicked
            if ~strcmp(selectedNodes(1).Text,app.exp_dir)
                app.round_folder = selectedNodes(1).Text;
                updateRound(app);
            end  
            
        end

        % Button pushed function: StitchButton
        function StitchButtonPushed(app, event)
            
            img_f = build_img_f(app, app.stitch_sel, 'raw');
            stitch_sel_idx = find(strcmp(app.StitchTable.RowName, app.stitch_sel));
            
            if app.T1(stitch_sel_idx,:).Stitched == 1
                choice = app.verif_dialog('This file has already been stitched. Stitch anyway? (this will overwrite previous stitched file)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end
            
            shadingCorrection = app.ShadingCorrectionCheckBox.Value;
            fusionMethod = app.FusionMethodDropDown.Value;
            stripeCorrection = app.StripeCorrectionEditField.Value;

            d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                'Message','(0/1 files) Stitching...');
            d.Value = 0;
            file_frac = '(0/1 files)';
            stitch(img_f, shadingCorrection, fusionMethod, stripeCorrection, d, file_frac)

            app.T1(stitch_sel_idx,:).Stitched = 1;
            updateTablesRound(app)
            writeTablesRound(app)

            d.Value = 1;
            close(d)

           
        end

        % Button pushed function: StitchAllButton
        function StitchAllButtonPushed(app, event)
            to_stitch = ~app.T1.Stitched;

            % app is not running in 'do all' mode
            if (sum(to_stitch) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been stitched. Stitch anyway? (this will overwrite previous stitched files)');
                switch choice
                    case 'Yes'
                        to_stitch = ~to_stitch;
                    case 'No'
                        return
                end
            end

            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_stitch) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            stitch_sels = app.StitchTable.RowName(to_stitch);
            
            max_files = sum(to_stitch);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                    'Message',strcat(file_frac,' Stitching...'));
            d.Value = 0;

            shadingCorrection = app.ShadingCorrectionCheckBox.Value;
            fusionMethod = app.FusionMethodDropDown.Value;
            stripeCorrection = app.StripeCorrectionEditField.Value;
            
            for i=1:length(stitch_sels)

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Stitching...');

                img_f = build_img_f(app, stitch_sels(i), 'raw');
                stitch_sel_idx = find(strcmp(app.StitchTable.RowName, stitch_sels(i)));
                
                stitch(img_f, shadingCorrection, fusionMethod, stripeCorrection, d, file_frac)

                app.T1(stitch_sel_idx,:).Stitched = 1;
                updateTablesRound(app)
                writeTablesRound(app)

                d.Value = i/max_files;
                
            end

        end

        % Button pushed function: UnmixParamButton
        function UnmixParamButtonPushed(app, event)
            
            input_dir = app.calc_core_IO('unmix',app.round_folder);

            % row index of selected cell in unmix params table
            unmix_param_sel_idx = find(strcmp(app.UnmixParamTable.RowName, app.unmix_param_sel));
            if app.T2(unmix_param_sel_idx,:).("Unmix Settings") == 1
                load_params = 1;
            else
                load_params = 0;
            end
            
            img_f = app.build_img_f(app.unmix_param_sel, input_dir, app.round_folder, 'HCR', false);
            
            if isempty(img_f)
                msgbox(strcat("Unable to locate file: (", app.unmix_param_sel,") in folder: (", input_dir,")"))
                return
            end

            setUnmixParams(img_f, app, 'find_params', load_params)
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)
            unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, app.unmix_sel));

            if app.T1(unmix_sel_idx,:).Unmixed == 1
                choice = app.verif_dialog('This file has already been unmixed. Unmix anyway? (this will overwrite previous unmixed file)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end

            input_dir = app.calc_core_IO('unmix',app.round_folder);
            img = app.UnmixTable.RowName{unmix_sel_idx};

            uparams_fn = find_fn_uniq_id(app.unmix_sel,fullfile(app.exp_dir,app.round_folder,'internal','uparams'));

            if isempty(uparams_fn)
                msgbox("No unmix parameters found for this file","Unmix parameters error","error")
                return
            end

            if app.UnmixLinearCheckBox.Value
                img_f = build_img_f(app, img, input_dir, app.round_folder, 'HCR', true);
            else
                img_f = build_img_f(app, img, input_dir, app.round_folder, 'HCR', false);
            end

            setUnmixParams(img_f, app, 'write',0)

            if app.DeleteIntermediatesCheckBox.Value == 1
                delete(img_f)
            end
        end

        % Button pushed function: UnmixAllButton
        function UnmixAllButtonPushed(app, event)
            to_unmix = ~app.T1.Unmixed;

            % app is not running in 'do all' mode
            if (sum(to_unmix) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been unmixed. Unmix anyway? (this will overwrite previous unmixed files)');
                switch choice
                    case 'Yes'
                        to_unmix = ~to_unmix;
                    case 'No'
                        return
                end
            end

            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_unmix) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            unmix_sels = app.UnmixTable.RowName(to_unmix);
            
            max_files = sum(to_unmix);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Unmixing Progress',...
                    'Message',strcat(file_frac,' Unmixing...'));
            d.Value = 0;
            
            for i=1:length(unmix_sels)

                app.unmix_sel = unmix_sels(i);

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Unmixing...');
                
                input_dir = app.calc_core_IO('unmix');

                if app.UnmixLinearCheckBox.Value
                    img_f = build_img_f(app, unmix_sels(i), input_dir, app.round_folder, 'HCR', true);
                else
                    img_f = build_img_f(app, unmix_sels(i), input_dir, app.round_folder, 'HCR', false);
                end

                if isempty(img_f)
                    app.do_all_cache{end+1} = strcat("Unmix failed, unable to locate file: (", app.unmix_sel,") in folder: (",fullfile(app.round_folder,input_dir),")");
                    continue
                end

                unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, unmix_sels(i)));

                uparams_fn = find_fn_uniq_id(app.unmix_sel,fullfile(app.exp_dir,app.round_folder,'internal','uparams'));

                if isempty(uparams_fn)
                    app.do_all_cache{end+1} = strcat("Unmix failed, unable to find unmix parameters for: ", app.unmix_sel, ", skipped this file.");
                    continue
                end
                
                setUnmixParams(img_f, app, 'write',0)

                if app.DeleteIntermediatesCheckBox.Value == 1
                    delete(img_f)
                end

                app.T1(unmix_sel_idx,:).Unmixed = 1;
                updateTablesRound(app)
                writeTablesRound(app)

                d.Value = i/max_files;
                
            end

            if app.do_all_flag == false
                app.report_do_all_cache();
            end
        end

        % Button pushed function: FiducialsButton
        function FiducialsButtonPushed(app, event)
            
            moving_input_dir = app.calc_core_IO('reg',app.round_folder);

            % row index of selected cell in fiducials table
            fid_sel_idx = find(strcmp(app.FiducialsTable.RowName, app.fiducials_sel));
            
            % if there's previous fiducials to be automatically loaded
            if app.T2(fid_sel_idx,:).Fiducials == 1
                load_fid = 1;
            else
                load_fid = 0;
            end

            moving_fn = app.build_img_f(app.fiducials_sel, moving_input_dir, app.round_folder, 'HCR', false);

            if isempty(moving_fn)
                msgbox(strcat("Unable to locate file: (", app.fiducials_sel,") in folder: (",fullfile(app.round_folder,moving_input_dir),")"))
                return
            end

            fixed_fn = app.find_fixed_im(app.fiducials_sel);

            if isempty(fixed_fn)
                msgbox(strcat("Unable to find fixed image for: ", app.fiducials_sel, "."))
                return
            end

            [~,name,~] = fileparts(moving_fn);
            moving_reg_channel = app.id_reg_channel(name);
            [~,name,~] = fileparts(fixed_fn);
            fixed_reg_channel = app.id_reg_channel(name);

            findFiducials(fixed_fn, moving_fn, fixed_reg_channel, moving_reg_channel, app, load_fid)
            
        end

        % Button pushed function: RegButton
        function RegButtonPushed(app, event)
            
            moving_input_dir = app.calc_core_IO('reg',app.round_folder);

            reg_sel_idx = find(strcmp(app.RegTable.RowName, app.reg_sel));

            if app.T1(reg_sel_idx,:).Registered == 1
                choice = app.verif_dialog('This file has already been registered. Register anyway? (this will overwrite previous registered file)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end

            dlg = uiprogressdlg(app.UIFigure,'Title','Registration Progress',...
                'Message','(0/1 files) Registering...');
            dlg.Value = 0;
            file_frac = '(0/1 files)';
            
            moving_fn = app.build_img_f(app.reg_sel, moving_input_dir, app.round_folder);
            fixed_fn = app.find_fixed_im(app.reg_sel);

            fids_fn = find_fn_uniq_id(app.reg_sel,fullfile(app.exp_dir,app.round_folder,'internal','fiducials'));

            if isempty(fids_fn)
                msgbox("No fiducials found for this file","Fiducials error","error")
                return
            end

            itxHandle = itxBspline2D(moving_fn, fixed_fn, dlg, file_frac);
            delete(itxHandle)

            app.clear_reg_trash()

            if app.DeleteIntermediatesCheckBox.Value == 1
                delete(moving_fn)
            end

            app.T1(reg_sel_idx,:).Registered = 1;
            updateTablesRound(app)
            writeTablesRound(app)

            dlg.Value = 1;
            close(dlg)


        end

        % Button pushed function: RegAllButton
        function RegAllButtonPushed(app, event)
            
            to_reg = ~app.T1.Registered;

            % app is not running in 'do all' mode
            if (sum(to_reg) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been registered. Register anyway? (this will overwrite previous registered files)');
                switch choice
                    case 'Yes'
                        to_reg = ~to_reg;
                    case 'No'
                        return
                end
            end

            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_reg) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            reg_sels = app.RegTable.RowName(to_reg);
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Registration Progress',...
                    'Message','(0/1 files) Registering...');
            dlg.Value = 0;
            file_frac = '(0/1 files)';

            for i=1:length(reg_sels)

                app.reg_sel = reg_sels(i);

                moving_input_dir = app.calc_core_IO('reg',app.round_folder);

                reg_sel_idx = find(strcmp(app.RegTable.RowName, app.reg_sel));

                moving_fn = app.build_img_f(app.reg_sel, moving_input_dir, app.round_folder);
                fixed_fn = app.find_fixed_im(app.reg_sel);

                fids_fn = find_fn_uniq_id(app.reg_sel,fullfile(app.exp_dir,app.round_folder,'internal','fiducials'));

                if isempty(fids_fn)
                    app.do_all_cache{end+1} = strcat("Registration failed, unable to find fiducials for: ", app.reg_sel, ", skipped this file.");
                    continue
                end

                if isempty(moving_fn)
                    app.do_all_cache{end+1} = strcat("Unable to find moving image for: ", app.reg_sel, ", skipped this file.");
                    continue
                end

                if isempty(fixed_fn)
                    app.do_all_cache{end+1} = strcat("Unable to find fixed image for: ", app.reg_sel, ", skipped this file.");
                    continue
                end

                itxHandle = itxBspline2D(moving_fn, fixed_fn, dlg, file_frac);
                delete(itxHandle)

                app.clear_reg_trash()

                if app.DeleteIntermediatesCheckBox.Value == 1
                    delete(moving_fn)
                end
    
                app.T1(reg_sel_idx,:).Registered = 1;
                updateTablesRound(app)
                writeTablesRound(app)

            end

            if app.do_all_flag == false
                app.report_do_all_cache();
            end


        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            input_dir = app.calc_core_IO('finish',app.round_folder);

            process_sel_idx = find(strcmp(app.FinishTable.RowName, app.process_sel));

            if app.T1(process_sel_idx,:).Finished == 1
                choice = app.verif_dialog('This file has already been finished. Finish anyway? (this will overwrite previous finished files)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end
            
            img_f = build_img_f(app, app.process_sel, input_dir);

            if isempty(img_f)
                msgbox(strcat("Unable to locate file: (", app.process_sel,") in folder: (",fullfile(app.round_folder,input_dir),")"))
                return
            end

            process_final(img_f, app.SubtractbackgroundCheckBox.Value, app.RadiusEditField.Value)

            if app.DeleteIntermediatesCheckBox.Value == 1
                delete(img_f)
            end

            app.T1(process_sel_idx,:).Finished = 1;
            updateTablesRound(app)
            writeTablesRound(app)


        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
            
            to_process = ~app.T1.Finished;
            
            % app is not running in 'do all' mode
            if (sum(to_process) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been finished. Finish anyway? (this will overwrite previous finished files)');
                switch choice
                    case 'Yes'
                        to_process = ~to_process;
                    case 'No'
                        return
                end
            end
            
            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_process) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            process_sels = app.FinishTable.RowName(to_process);
            
            for i=1:length(process_sels)

                app.process_sel = process_sels(i);
                process_sel_idx = find(strcmp(app.FinishTable.RowName, app.process_sel));
                
                input_dir = app.calc_core_IO('finish',app.round_folder);
                img_f = build_img_f(app, app.process_sel, input_dir);
    
                if isempty(img_f)
                    app.do_all_cache{end+1} = strcat("Finishing error, unable to locate file: (", app.process_sel,") in folder: (",fullfile(app.round_folder,input_dir),"), skipped this file.");
                    continue
                end
    
                process_final(img_f, app.SubtractbackgroundCheckBox.Value, app.RadiusEditField.Value)

                if app.DeleteIntermediatesCheckBox.Value == 1
                    delete(img_f)
                end
    
                app.T1(process_sel_idx,:).Finished = 1;
                updateTablesRound(app)
                writeTablesRound(app)

            end

            if app.do_all_flag == false
                app.report_do_all_cache();
            end

        end

        % Button pushed function: HistoProcessButton
        function HistoProcessButtonPushed(app, event)
            
            if isempty(app.histo_dir)
                msgbox("No histology directory and/or files found. Make sure to include the appropriate tokens in file and directory names.")
                return
            end

            histo_sel_idx = find(strcmp(app.HistoTable.RowName, app.histo_sel));

            if app.histology_table(histo_sel_idx,:).Processed == 1
                choice = app.verif_dialog('This file has already been processed. Process anyway? (this will overwrite previous processed files)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end
            
            img_f = build_img_f(app, app.histo_sel, 'raw', app.histo_dir, 'histo');

            if isempty(img_f)
                msgbox(strcat("Unable to locate file: (", app.histo_sel,") in folder: (",fullfile(app.histo_dir,'raw'),")"))
                return
            end

            process_histo(img_f)

            app.histology_table(histo_sel_idx,:).Processed = 1;
            app.updateTablesExperiment()
            app.writeTablesExperiment()
        end

        % Button pushed function: HistoProcessAllButton
        function HistoProcessAllButtonPushed(app, event)
            
            if isempty(app.histo_dir)
                app.do_all_cache{end+1} = "No histology directory and/or files found. Make sure to include the appropriate tokens in file and directory names.";
                if app.do_all_flag == false
                    app.report_do_all_cache();
                end
                return
            end
            
            to_histo = ~app.histology_table.Processed;
            
            % app is not running in 'do all' mode
            if (sum(to_histo) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been processed. Process anyway? (this will overwrite previous processed files)');
                switch choice
                    case 'Yes'
                        to_histo = ~to_histo;
                    case 'No'
                        return
                end
            end
            
            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_histo) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            histo_sels = app.HistoTable.RowName(to_histo);
            
            for i=1:length(histo_sels)

                app.histo_sel = histo_sels(i);
                histo_sel_idx = find(strcmp(app.HistoTable.RowName, app.histo_sel));
                
                img_f = build_img_f(app, app.histo_sel, 'raw', app.histo_dir, 'histo');
    
                if isempty(img_f)
                    app.do_all_cache{end+1} = strcat("Unable to locate file: (", app.histo_sel,") in folder: (",fullfile(app.histo_dir,'raw'),")");
                    continue
                end
    
                process_histo(img_f)
    
                app.histology_table(histo_sel_idx,:).Processed = 1;
                app.updateTablesExperiment()
                app.writeTablesExperiment()

            end

        end

        % Button pushed function: DoAllButton
        function DoAllButtonPushed(app, event)
            
            app.do_all_flag = true;
            
            if app.pipelines_table(app.round_folder,:).stitch == 1
                app.StitchAllButtonPushed();
            end

            if app.pipelines_table(app.round_folder,:).unmix == 1
                app.UnmixAllButtonPushed();
            end

            if app.pipelines_table(app.round_folder,:).reg == 1
                app.RegAllButtonPushed();
            end

            if app.pipelines_table(app.round_folder,:).finish == 1
                app.ProcessAllButtonPushed();
            end
            
            app.report_do_all_cache();

            app.do_all_flag = false;

        end

        % Cell selection callback: StitchTable
        function StitchTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.stitch_sel = app.StitchTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: FiducialsTable
        function FiducialsTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.fiducials_sel = app.FiducialsTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: UnmixParamTable
        function UnmixParamTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.unmix_param_sel = app.UnmixParamTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: RegTable
        function RegTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.reg_sel = app.RegTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: UnmixTable
        function UnmixTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.unmix_sel = app.UnmixTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: FinishTable
        function FinishTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.process_sel = app.FinishTable.RowName{row_idx};
            else
                return
            end
        end

        % Cell selection callback: HistoTable
        function HistoTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.histo_sel = app.HistoTable.RowName{row_idx};
            else
                return
            end
        end

        % Cell selection callback: SegTable
        function SegTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.seg_sel = app.SegTable.RowName{row_idx};
                app.post_processing_sel = app.SegTable.RowName{row_idx};
            else
                return
            end
        end

        % Cell selection callback: PostTable
        function PostTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.post_processing_sel = app.PostTable.RowName{row_idx};
                app.loadPostProcessingPipeline()
            else
                return
            end
        end

        % Selection changed function: CCFRegisteredTable
        function CCFRegisteredTableSelectionChanged(app, event)
            selection = app.CCFRegisteredTable.Selection;
            if ~isempty(selection)
                app.ccf_reg_sel = app.CCFRegisteredTable.RowName{selection};
            else
                return
            end
        end

        % Cell selection callback: FinalQCTable
        function FinalQCTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.final_qc_sel = app.FinalQCTable.RowName{row_idx};
            else
                return
            end
        end

        % Button pushed function: memMonitorButton
        function memMonitorButtonPushed(app, event)
            memMonitor()
        end

        % Value changed function: FinishSwitch, RegSwitch, StitchSwitch, 
        % ...and 1 other component
        function ProcessingSwitchValueChanged(app, event)
            app.updatePipelineLamps()
            app.updatePipelineInternal()
        end

        % Value changed function: CCFFilteringSwitch, CellposeMasksSwitch, 
        % ...and 1 other component
        function PostProcessingSwitchValueChanged(app, event)
            app.updatePipelineLamps()
            app.updatePostProcessingPipelineInternal()
        end

        % Value changed function: TableFileSwitch
        function TableFileSwitchValueChanged(app, event)
            app.updateTablesRound()
        end

        % Value changed function: RegRoundDropDown
        function RegRoundDropDownValueChanged(app, event)
            
            value = app.RegRoundDropDown.Value;
            app.reg_round = value;
            app.meta.("reg_round") = app.reg_round;
            app.saveMeta()

            app.pipelines_table(value,:).reg = 0;
            app.loadPipeline()
            app.updatePipelineInternal()

        end

        % Button pushed function: SegButton
        function SegButtonPushed(app, event)
            
            if isempty(app.seg_sel)
                message = ['No file selected for segmentation. Click on the ' ...
                    'table to select.'];
                title = 'No segmentation file selection';
                uialert(app.UIFigure,message,title)
                return
            end

            input_dir = fullfile(app.exp_dir,'post','core_output',app.seg_sel);
            listing = fetch_dir(input_dir,'file');
            split_str = split(app.reg_round,'_');
            roundstr = split_str{2};
            strs_filt = filt_kw(listing,roundstr,'keep');
            strs_filt = filt_kw(strs_filt,app.reg_channel_token,'keep');
            input_f = fullfile(input_dir,strs_filt{1});
            
            app.seg_image(input_f)

            app.post_processing_table(app.seg_sel,'Segmented') = {1};
            app.updateTablesExperiment()
            app.writeTablesExperiment()
            
        end

        % Button pushed function: SegAllButton
        function SegAllButtonPushed(app, event)
            
            for i=1:length(app.uniq_ids_HCR)

                app.seg_sel = app.uniq_ids_HCR{i};

                if app.post_processing_table{app.seg_sel,'Segmented'} == 1
                    app.do_all_cache{end+1} = strcat("Image file: (", app.seg_sel,") already segmented, skipped this file.");
                    continue
                end

                input_dir = fullfile(app.exp_dir,'post','core_output',app.seg_sel);
                listing = fetch_dir(input_dir,'file');
                split_str = split(app.reg_round,'_');
                roundstr = split_str{2};
                strs_filt = filt_kw(listing,roundstr,'keep');
                strs_filt = filt_kw(strs_filt,app.reg_channel_token,'keep');
                if isempty(strs_filt)
                    app.do_all_cache{end+1} = strcat("Unable to locate registration file for: (", app.seg_sel,") in folder: (",input_dir,"), skipped this file.");
                    continue
                end

                input_f = fullfile(input_dir,strs_filt{1});
                app.seg_image(input_f)

                app.post_processing_table(app.seg_sel,'Segmented') = {1};
                app.updateTablesExperiment()
                app.writeTablesExperiment()

            end

            app.report_do_all_cache();

        end

        % Button pushed function: ManualSegButton
        function ManualSegButtonPushed(app, event)
            
            if isempty(app.seg_sel)
                message = ['No file selected for segmentation. Click on the ' ...
                    'table to select.'];
                title = 'No segmentation file selection';
                uialert(app.UIFigure,message,title)
                return
            end
            
            % fetch the tifs from the core output folder for the
            % segmentation selection
            im_dir = fullfile(app.exp_dir,'post','core_output',app.seg_sel);
            listing = dir(im_dir);
            f_names = {listing.name};
            TF_im = contains(f_names,{'.tif','.tiff'},'IgnoreCase',true);
            im_files = f_names(TF_im);

            % figure out which are registration images
            TF_reg_channels = contains(im_files,app.reg_channel_token,'IgnoreCase',true);
            TF_bin = ~TF_reg_channels;


            f_label_channels_ims = im_files(TF_bin);
            f_reg_channels = im_files(TF_reg_channels);
            
            [indx,~] = listdlg('ListString',f_label_channels_ims, ...
                    'SelectionMode','multiple', ...
                    'PromptString',{'Select label channels to segment','(Ctrl+Click to select multiple)'});
            f_label_channels_ims = f_label_channels_ims(indx);

            TF_r0_im = contains(f_reg_channels, 'r0');
            TF_r1_im = contains(f_reg_channels, 'r1');
            r0Im_f = f_reg_channels{TF_r0_im};
            r1Im_f = f_reg_channels{TF_r1_im};
            num_fs = length(f_label_channels_ims);
            ims_f = cell(num_fs + 2, 1);

            ims_f{1} = r0Im_f;
            for i=1:num_fs
                ims_f{i+1} = f_label_channels_ims{i};
            end
            ims_f{end} = r1Im_f;

            for i=1:length(ims_f)
                ims_f{i} = fullfile(im_dir,ims_f{i});
            end

            % get the CCF volumes directory for this section
            ccf_vols_dir = fullfile(app.exp_dir,'post','CCF_vols',app.seg_sel);
            ccfOverlay_f = fullfile(ccf_vols_dir,strcat(app.seg_sel,'annotation.tif'));
            
            manual_masks(ims_f,ccfOverlay_f,app)

        end

        % Cell edit callback: PitchYawTable
        function PitchYawTableCellEdit(app, event)
            app.writeTablesExperiment()
        end

        % Button pushed function: LaunchBlockCutButton
        function LaunchBlockCutButtonPushed(app, event)
            block_cut(app)
        end

        % Button pushed function: CCFRegisterButton
        function CCFRegisterButtonPushed(app, event)
            sample_dir = fullfile(app.exp_dir,'post','core_output',app.ccf_reg_sel);
            pitch = app.pitch_yaw_table{app.ccf_reg_sel,1};
            yaw = app.pitch_yaw_table{app.ccf_reg_sel,2};
            CCFregister(app,sample_dir,pitch,yaw)
        end

        % Button pushed function: MaskQCButton
        function MaskQCButtonPushed(app, event)
            
            im_f_path = fullfile(app.exp_dir,'post','core_output',app.seg_sel);
            listing = fetch_dir(im_f_path, 'file');
            listing = filt_kw(listing, app.reg_round_token, 'keep');
            listing = filt_kw(listing, app.reg_channel_token, 'keep');
            im_f = listing{1};

            if ~isempty(im_f)
                im_f = fullfile(im_f_path,im_f);
            else
                message = ['Unable to find registration channel image for ' ...
                    'this id in registration round.'];
                title = 'No registration image';
                uialert(app.UIFigure,message,title)
                return
            end

            masks_im_path = fullfile(app.exp_dir,'post','masks',app.seg_sel);
            if isfile(fullfile(masks_im_path,[app.seg_sel '_masks_ccf.tif']))
                masks_im = fullfile(masks_im_path,[app.seg_sel '_masks_ccf.tif']);
            elseif isfile(fullfile(masks_im_path,[app.seg_sel '_cp_masks.tif']))
                masks_im = fullfile(masks_im_path,[app.seg_sel '_cp_masks.tif']);
            else
                message = ['Unable to find masks (CCF filtered or direct ' ...
                    'cellpose output) for this id.'];
                title = 'No masks';
                uialert(app.UIFigure,message,title)
                return
            end

            mask_assessor(im_f,masks_im,app)


        end

        % Button pushed function: FilterMasksCCFButton
        function FilterMasksCCFButtonPushed(app, event)
            
            masks_im = fullfile(app.exp_dir,'post','masks',app.seg_sel,[app.seg_sel '_cp_masks.tif']);
            masks_im_label_filt = fullfile(app.exp_dir,'post','masks',app.seg_sel,[app.seg_sel '_cp_masks_label_filt.tif']);
            ccf_im = fullfile(app.exp_dir,'post','CCF_vols',app.seg_sel,[app.seg_sel 'annotation.tif']);
            ccf_structs = app.CCFStructuresEditField.Value;
            selection = 'Original cellpose masks image';

            if ~isfile(masks_im)
                message = 'No cellpose masks found for this file.';
                title = 'No cp masks';
                uialert(app.UIFigure,message,title)
                return
            end

            if ~isfile(ccf_im)
                message = 'No CCF registration found for this file.';
                title = 'No ccf registration';
                uialert(app.UIFigure,message,title)
                return
            end
            
            if isfile(masks_im_label_filt)
                message = ['A labels image(s) filtered masks image has been found for this' ...
                    ' file. Would you like to CCF filter that image or the original cellpose output' ...
                    ' masks image?'];
                title = 'Which image to CCF filter?';
                Options = {'Labels image(s) filtered masks image', 'Original cellpose masks image'};
                selection = uiconfirm(app.UIFigure,message,title,Options=Options);
            end
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Filtering...',...
                    'Message','Filtering masks for this image...', ...
                    'Indeterminate','on');
            dlg.Value = 0;
            
            switch selection
                case 'Original cellpose masks image'
                    filter_masks_ccf(masks_im,ccf_im,ccf_structs)
                case 'Labels image(s) filtered masks image'
                    filter_masks_ccf(masks_im_label_filt,ccf_im,ccf_structs)
            end
            
            app.post_processing_table(app.seg_sel,'CCF Filtered') = {1};
            app.updateTablesExperiment()
            app.writeTablesExperiment()

            close(dlg)
            
        end

        % Button pushed function: DoMaskAddInButton
        function DoMaskAddInButtonPushed(app, event)
            
            im_f_path = fullfile(app.exp_dir,'post','core_output',app.seg_sel);
            list = fetch_dir(fullfile(app.exp_dir,'post','core_output',app.seg_sel),'file');
            reg_list = filt_kw(list,app.reg_channel_token, 'keep');
            embed_list = setdiff(list,reg_list);
            
            prompt = {'Select registration image. (Only 1)'};
            [indx,~] = listdlg('ListString',reg_list,'PromptString',prompt);
            reg_im = reg_list(indx);
            reg_im = reg_im{1};
            reg_im = fullfile(im_f_path,reg_im);

            prompt = {'Select images to embed with ' 
                'registration image (up to 2). '
                'These are the labeling targets '
                'you want to specifically include '
                'every cell for.'
                'Ctrl-click to select multiple.'};
            [indx,~] = listdlg('ListString',embed_list,'PromptString',prompt);
            embed_ims = embed_list(indx);
            for i=1:length(embed_ims)
                embed_ims{i} = fullfile(im_f_path,embed_ims{i});
            end

            ccf_im = fullfile(app.exp_dir,'post','CCF_vols',app.seg_sel,[app.seg_sel 'annotation.tif']);
            if ~isfile(ccf_im)
                message = 'No CCF registration found for this file.';
                title = 'No ccf registration';
                uialert(app.UIFigure,message,title)
                return
            end
            
            mask_add_in(ccf_im,reg_im,embed_ims)

        end

        % Button pushed function: FinalQCButton
        function FinalQCButtonPushed(app, event)
            im_dir = fullfile(app.exp_dir,'post','core_output',app.final_qc_sel);
            f_masks_im = fullfile(app.exp_dir,'post','masks',app.final_qc_sel,[app.final_qc_sel '_masks_qc_final.tif']);
            quality_control(im_dir,f_masks_im)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'docs', 'doc_assets', 'logo', 'HCRprocess logo.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.FilesTable = uitable(app.MainTab);
            app.FilesTable.ColumnName = {'Stitched'; 'Unmixed'; 'Registered'; 'Finished'};
            app.FilesTable.RowName = {};
            app.SettingsTable = uitable(app.MainTab);
            app.SettingsTable.ColumnName = {'Unmix Settings'; 'Fiducials'};
            app.SettingsTable.RowName = {};
            app.PostTable = uitable(app.MainTab);
            app.PostTable.ColumnName = {'CCF Registered'; 'Manual Masks'; 'Cellpose Masks'; 'Integration'; 'CCF Filtering'; 'Mask QC'; 'Final QC'};
            app.PostTable.RowName = {};
            app.PostTable.CellSelectionCallback = createCallbackFcn(app, @PostTableCellSelection, true);
            app.PostprocessingroundindependentLabel = uilabel(app.MainTab);
            app.PostprocessingroundindependentLabel.Text = 'Post-processing (round-independent)';
            app.ManualsettingsroundspecificLabel = uilabel(app.MainTab);
            app.ManualsettingsroundspecificLabel.Text = 'Manual settings (round-specific)';
            app.CoreprocessingpipelineroundspecificLabel = uilabel(app.MainTab);
            app.CoreprocessingpipelineroundspecificLabel.Text = 'Core processing pipeline (round-specific)';
            app.StitchingTab = uitab(app.TabGroup);
            app.StitchingTab.Title = 'Stitching';
            app.StitchTable = uitable(app.StitchingTab);
            app.StitchTable.ColumnName = {'Stitched'};
            app.StitchTable.RowName = {};
            app.StitchTable.CellSelectionCallback = createCallbackFcn(app, @StitchTableCellSelection, true);
            app.StitchingFunctionsPanel = uipanel(app.StitchingTab);
            app.StitchingFunctionsPanel.Title = 'Stitching Functions';
            app.StitchButton = uibutton(app.StitchingFunctionsPanel, 'push');
            app.StitchButton.ButtonPushedFcn = createCallbackFcn(app, @StitchButtonPushed, true);
            app.StitchButton.Text = 'Stitch this file';
            app.StitchAllButton = uibutton(app.StitchingFunctionsPanel, 'push');
            app.StitchAllButton.ButtonPushedFcn = createCallbackFcn(app, @StitchAllButtonPushed, true);
            app.StitchAllButton.Text = 'Stitch all files';
            app.ShadingCorrectionCheckBox = uicheckbox(app.StitchingFunctionsPanel);
            app.ShadingCorrectionCheckBox.Text = 'Shading correction?';
            app.ShadingCorrectionCheckBox.Value = true;
            app.FusionmethodDropDownLabel = uilabel(app.StitchingFunctionsPanel);
            app.FusionmethodDropDownLabel.Text = {'Fusion'; 'method'};
            app.FusionMethodDropDown = uidropdown(app.StitchingFunctionsPanel);
            app.FusionMethodDropDown.Items = {'Max. Intensity', 'Linear Blending'};
            app.FusionMethodDropDown.Value = 'Max. Intensity';
            app.StripecorrectionLabel = uilabel(app.StitchingFunctionsPanel);
            app.StripecorrectionLabel.Text = 'Stripe correction';
            app.StripeCorrectionEditField = uieditfield(app.StitchingFunctionsPanel, 'text');
            app.StripeCorrectionEditField.Value = '[1 0 0 0 0]';
            app.StripeCorrectionExplanationLabel = uilabel(app.StitchingFunctionsPanel);
            app.StripeCorrectionExplanationLabel.Text = {'(which channels to'; 'perform stripe correction on)'};
            app.SpectralUnmixingTab = uitab(app.TabGroup);
            app.SpectralUnmixingTab.Title = 'Spectral Unmixing';
            app.UnmixParamTable = uitable(app.SpectralUnmixingTab);
            app.UnmixParamTable.ColumnName = {'Unmix Parameters'};
            app.UnmixParamTable.RowName = {};
            app.UnmixParamTable.CellSelectionCallback = createCallbackFcn(app, @UnmixParamTableCellSelection, true);
            app.UnmixTable = uitable(app.SpectralUnmixingTab);
            app.UnmixTable.ColumnName = {'Unmixed'};
            app.UnmixTable.RowName = {};
            app.UnmixTable.CellSelectionCallback = createCallbackFcn(app, @UnmixTableCellSelection, true);
            app.UnmixParametersFunctionsPanel = uipanel(app.SpectralUnmixingTab);
            app.UnmixParametersFunctionsPanel.Title = 'Unmix Parameters Functions';
            app.UnmixParamButton = uibutton(app.UnmixParametersFunctionsPanel, 'push');
            app.UnmixParamButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixParamButtonPushed, true);
            app.UnmixParamButton.Text = {'Set/edit unmixing'; 'parameters for this file'};
            app.UnmixFunctionsPanel = uipanel(app.SpectralUnmixingTab);
            app.UnmixFunctionsPanel.Title = 'Unmix Functions';
            app.UnmixButton = uibutton(app.UnmixFunctionsPanel, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Text = 'Unmix this file';
            app.UnmixAllButton = uibutton(app.UnmixFunctionsPanel, 'push');
            app.UnmixAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixAllButtonPushed, true);
            app.UnmixAllButton.Text = 'Unmix all files';
            app.UnmixLinearCheckBox = uicheckbox(app.UnmixFunctionsPanel);
            app.UnmixLinearCheckBox.Text = 'Unmix linear blending?';
            app.RegTab = uitab(app.TabGroup);
            app.RegTab.Title = 'Registration';
            app.FiducialsTable = uitable(app.RegTab);
            app.FiducialsTable.ColumnName = {'Fiducials'};
            app.FiducialsTable.RowName = {};
            app.FiducialsTable.CellSelectionCallback = createCallbackFcn(app, @FiducialsTableCellSelection, true);
            app.RegTable = uitable(app.RegTab);
            app.RegTable.ColumnName = {'Registered'};
            app.RegTable.RowName = {};
            app.RegTable.CellSelectionCallback = createCallbackFcn(app, @RegTableCellSelection, true);
            app.FiducialsFunctionsPanel = uipanel(app.RegTab);
            app.FiducialsFunctionsPanel.Title = 'Fiducials Functions';
            app.FiducialsButton = uibutton(app.FiducialsFunctionsPanel, 'push');
            app.FiducialsButton.ButtonPushedFcn = createCallbackFcn(app, @FiducialsButtonPushed, true);
            app.FiducialsButton.Text = 'Find/edit fiducials for this file';
            app.RegistrationFunctionsPanel = uipanel(app.RegTab);
            app.RegistrationFunctionsPanel.Title = 'Registration Functions';
            app.RegButton = uibutton(app.RegistrationFunctionsPanel, 'push');
            app.RegButton.ButtonPushedFcn = createCallbackFcn(app, @RegButtonPushed, true);
            app.RegButton.Text = 'Register this file';
            app.RegAllButton = uibutton(app.RegistrationFunctionsPanel, 'push');
            app.RegAllButton.ButtonPushedFcn = createCallbackFcn(app, @RegAllButtonPushed, true);
            app.RegAllButton.Text = 'Register all files';
            app.FinalTab = uitab(app.TabGroup);
            app.FinalTab.Title = 'Finishing';
            app.FinishTable = uitable(app.FinalTab);
            app.FinishTable.ColumnName = {'Finished'};
            app.FinishTable.RowName = {};
            app.FinishTable.CellSelectionCallback = createCallbackFcn(app, @FinishTableCellSelection, true);
            app.FinishingFunctionsPanel = uipanel(app.FinalTab);
            app.FinishingFunctionsPanel.Title = 'Finishing Functions';
            app.RollingballradiusEditFieldLabel = uilabel(app.FinishingFunctionsPanel);
            app.RollingballradiusEditFieldLabel.Text = 'Rolling ball radius:';
            app.RadiusEditField = uieditfield(app.FinishingFunctionsPanel, 'text');
            app.RadiusEditField.Value = '100';
            app.ProcessButton = uibutton(app.FinishingFunctionsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Finish this file';
            app.ProcessAllButton = uibutton(app.FinishingFunctionsPanel, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Finish all files';
            app.SubtractbackgroundCheckBox = uicheckbox(app.FinishingFunctionsPanel);
            app.SubtractbackgroundCheckBox.Text = 'Subtract background?';
            app.Histology = uitab(app.TabGroup);
            app.Histology.Title = 'Histology';
            app.HistoTable = uitable(app.Histology);
            app.HistoTable.ColumnName = {'Processed'};
            app.HistoTable.RowName = {};
            app.HistoTable.CellSelectionCallback = createCallbackFcn(app, @HistoTableCellSelection, true);
            app.HistologyFunctionsPanel = uipanel(app.Histology);
            app.HistologyFunctionsPanel.Title = 'Histology Functions';
            app.HistoProcessButton = uibutton(app.HistologyFunctionsPanel, 'push');
            app.HistoProcessButton.ButtonPushedFcn = createCallbackFcn(app, @HistoProcessButtonPushed, true);
            app.HistoProcessButton.Text = 'Process this file';
            app.HistoProcessAllButton = uibutton(app.HistologyFunctionsPanel, 'push');
            app.HistoProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @HistoProcessAllButtonPushed, true);
            app.HistoProcessAllButton.Text = 'Process all files';
            app.CCFRegistrationTab = uitab(app.TabGroup);
            app.CCFRegistrationTab.Title = 'CCF Registration';
            app.PitchYawTable = uitable(app.CCFRegistrationTab);
            app.PitchYawTable.ColumnName = {'Pitch (°)'; 'Yaw (°)'; 'Ipsi/Contra'};
            app.PitchYawTable.RowName = {};
            app.PitchYawTable.ColumnEditable = true;
            app.PitchYawTable.CellEditCallback = createCallbackFcn(app, @PitchYawTableCellEdit, true);
            app.CCFRegistrationFunctionsPanel = uipanel(app.CCFRegistrationTab);
            app.CCFRegistrationFunctionsPanel.Title = 'CCF Registration Functions';
            app.LaunchBlockCutButton = uibutton(app.CCFRegistrationFunctionsPanel, 'push');
            app.LaunchBlockCutButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchBlockCutButtonPushed, true);
            app.LaunchBlockCutButton.Text = {'Launch block cut GUI'; '(used to determine pitch and yaw)'};
            app.CCFRegisterButton = uibutton(app.CCFRegistrationFunctionsPanel, 'push');
            app.CCFRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @CCFRegisterButtonPushed, true);
            app.CCFRegisterButton.Text = 'CCF register this file';
            app.CCFRegisteredTable = uitable(app.CCFRegistrationTab);
            app.CCFRegisteredTable.ColumnName = {'CCF Registration Settings'; 'CCF Registered'};
            app.CCFRegisteredTable.RowName = {};
            app.CCFRegisteredTable.SelectionType = 'row';
            app.CCFRegisteredTable.SelectionChangedFcn = createCallbackFcn(app, @CCFRegisteredTableSelectionChanged, true);
            app.IpsiContraLabel = uilabel(app.CCFRegistrationTab);
            app.IpsiContraLabel.Text = 'Mark ''i'' or ''c''';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.SegTable = uitable(app.SegmentationTab);
            app.SegTable.ColumnName = {'Manual Masks'; 'Cellpose Masks'; 'Integration'; 'CCF Filtering'; 'Mask QC'};
            app.SegTable.RowName = {};
            app.SegTable.CellSelectionCallback = createCallbackFcn(app, @SegTableCellSelection, true);
            app.SegmentationfunctionsPanel = uipanel(app.SegmentationTab);
            app.SegmentationfunctionsPanel.Title = 'Segmentation functions';
            app.SegButton = uibutton(app.SegmentationfunctionsPanel, 'push');
            app.SegButton.ButtonPushedFcn = createCallbackFcn(app, @SegButtonPushed, true);
            app.SegButton.Text = 'Cellpose segment';
            app.SegAllButton = uibutton(app.SegmentationfunctionsPanel, 'push');
            app.SegAllButton.ButtonPushedFcn = createCallbackFcn(app, @SegAllButtonPushed, true);
            app.SegAllButton.Text = {'Cellpose segment'; 'all images'};
            app.GPUSegCheckBox = uicheckbox(app.SegmentationfunctionsPanel);
            app.GPUSegCheckBox.Text = 'Use GPU?';
            app.GPUSegCheckBox.Value = true;
            app.Stitchthreshold0to1EditFieldLabel = uilabel(app.SegmentationfunctionsPanel);
            app.Stitchthreshold0to1EditFieldLabel.Text = {'Stitch threshold'; '(0 to 1)'};
            app.StitchThreshEditField = uieditfield(app.SegmentationfunctionsPanel, 'numeric');
            app.StitchThreshEditField.Limits = [0 1];
            app.StitchThreshEditField.Value = 0.2;
            app.MinsizeEditFieldLabel = uilabel(app.SegmentationfunctionsPanel);
            app.MinsizeEditFieldLabel.Text = 'Min size';
            app.MinSizeEditField = uieditfield(app.SegmentationfunctionsPanel, 'numeric');
            app.MinSizeEditField.Limits = [1 Inf];
            app.MinSizeEditField.RoundFractionalValues = 'on';
            app.MinSizeEditField.Value = 30;
            app.AnisotropyEditFieldLabel = uilabel(app.SegmentationfunctionsPanel);
            app.AnisotropyEditFieldLabel.Text = 'Anisotropy';
            app.AnisotropyEditField = uieditfield(app.SegmentationfunctionsPanel, 'numeric');
            app.AnisotropyEditField.Limits = [1 Inf];
            app.AnisotropyEditField.Value = 6;
            app.MaskQCButton = uibutton(app.SegmentationfunctionsPanel, 'push');
            app.MaskQCButton.ButtonPushedFcn = createCallbackFcn(app, @MaskQCButtonPushed, true);
            app.MaskQCButton.Text = 'Do mask quality control on this image';
            app.FilterMasksCCFButton = uibutton(app.SegmentationfunctionsPanel, 'push');
            app.FilterMasksCCFButton.ButtonPushedFcn = createCallbackFcn(app, @FilterMasksCCFButtonPushed, true);
            app.FilterMasksCCFButton.Text = 'Filter masks for this image using CCF';
            app.CCFStructuresEditFieldLabel = uilabel(app.SegmentationfunctionsPanel);
            app.CCFStructuresEditFieldLabel.Text = 'CCF Structures';
            app.CCFStructuresEditField = uieditfield(app.SegmentationfunctionsPanel, 'text');
            app.CCFStructuresEditField.Value = 'IRN,PARN';
            app.SeparatemultiplestructuresusingasinglecommawithnospaceLabel = uilabel(app.SegmentationfunctionsPanel);
            app.SeparatemultiplestructuresusingasinglecommawithnospaceLabel.Text = {'Separate multiple structures using a'; 'single comma with no space'};
            app.DoMaskAddInButton = uibutton(app.SegmentationfunctionsPanel, 'push');
            app.DoMaskAddInButton.ButtonPushedFcn = createCallbackFcn(app, @DoMaskAddInButtonPushed, true);
            app.DoMaskAddInButton.Text = 'Integrate manual and cellpose masks';
            app.ManualSegButton = uibutton(app.SegmentationfunctionsPanel, 'push');
            app.ManualSegButton.ButtonPushedFcn = createCallbackFcn(app, @ManualSegButtonPushed, true);
            app.ManualSegButton.Text = {'Manually'; 'segment'};
            app.FinalQCTab = uitab(app.TabGroup);
            app.FinalQCTab.Title = 'Final QC';
            app.FinalQCTable = uitable(app.FinalQCTab);
            app.FinalQCTable.ColumnName = {'Final QC'};
            app.FinalQCTable.RowName = {};
            app.FinalQCTable.CellSelectionCallback = createCallbackFcn(app, @FinalQCTableCellSelection, true);
            app.FinalQCButton = uibutton(app.FinalQCTab, 'push');
            app.FinalQCButton.ButtonPushedFcn = createCallbackFcn(app, @FinalQCButtonPushed, true);
            app.FinalQCButton.Text = {'Perform final QC'; 'on this image'};
            app.ExpDirButton = uibutton(app.UIFigure, 'push');
            app.ExpDirButton.ButtonPushedFcn = createCallbackFcn(app, @ExpDirButtonPushed, true);
            app.ExpDirButton.Text = 'Change Experiment Directory';
            app.DirTree = uitree(app.UIFigure);
            app.DirTree.SelectionChangedFcn = createCallbackFcn(app, @DirTreeSelectionChanged, true);
            app.ExperimentDirectoryNode = uitreenode(app.DirTree);
            app.ExperimentDirectoryNode.Text = 'Experiment Directory';
            app.CoreProcessingPipelinePanel = uipanel(app.UIFigure);
            app.CoreProcessingPipelinePanel.Title = 'Core Processing Pipeline';
            app.StitchLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.RegLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.UnmixLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.Image = uiimage(app.CoreProcessingPipelinePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.StitchingLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.StitchingLabel.Text = 'Stitching';
            app.RegistrationLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.RegistrationLabel.Text = 'Registration';
            app.StitchSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.StitchSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.StitchSwitch.Enable = 'off';
            app.StitchSwitch.Value = 'On';
            app.UnmixingLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.UnmixingLabel.Text = 'Unmixing';
            app.Image_2 = uiimage(app.CoreProcessingPipelinePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.RegSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.RegSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.RegSwitch.Value = 'On';
            app.UnmixSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.UnmixSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.UnmixSwitch.Value = 'On';
            app.Image_4 = uiimage(app.CoreProcessingPipelinePanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.FinishLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.FinishingLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.FinishingLabel.Text = 'Finishing';
            app.FinishSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.FinishSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.FinishSwitch.Value = 'On';
            app.PostprocessingPanel = uipanel(app.UIFigure);
            app.PostprocessingPanel.Title = 'Post-processing';
            app.CCFFilteringLamp = uilamp(app.PostprocessingPanel);
            app.CCFFilteringLabel = uilabel(app.PostprocessingPanel);
            app.CCFFilteringLabel.Text = 'CCF Filtering';
            app.CellposeMasksLabel = uilabel(app.PostprocessingPanel);
            app.CellposeMasksLabel.Text = 'Cellpose Masks';
            app.CellposeMasksLamp = uilamp(app.PostprocessingPanel);
            app.ManualMasksLamp = uilamp(app.PostprocessingPanel);
            app.ManualMasksLabel = uilabel(app.PostprocessingPanel);
            app.ManualMasksLabel.Text = 'Manual Masks';
            app.CellposeMasksSwitch = uiswitch(app.PostprocessingPanel, 'toggle');
            app.CellposeMasksSwitch.ValueChangedFcn = createCallbackFcn(app, @PostProcessingSwitchValueChanged, true);
            app.CellposeMasksSwitch.Value = 'On';
            app.CCFFilteringSwitch = uiswitch(app.PostprocessingPanel, 'toggle');
            app.CCFFilteringSwitch.ValueChangedFcn = createCallbackFcn(app, @PostProcessingSwitchValueChanged, true);
            app.CCFFilteringSwitch.Value = 'On';
            app.ManualMasksSwitch = uiswitch(app.PostprocessingPanel, 'toggle');
            app.ManualMasksSwitch.ValueChangedFcn = createCallbackFcn(app, @PostProcessingSwitchValueChanged, true);
            app.ManualMasksSwitch.Value = 'On';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.DeleteIntermediatesCheckBox = uicheckbox(app.SettingsPanel);
            app.DeleteIntermediatesCheckBox.Text = 'Delete Intermediates';
            app.FunctionsPanel = uipanel(app.UIFigure);
            app.FunctionsPanel.Title = 'Functions';
            app.RegistrationRoundLabel = uilabel(app.FunctionsPanel);
            app.RegistrationRoundLabel.Text = 'Registration Round';
            app.RegRoundDropDown = uidropdown(app.FunctionsPanel);
            app.RegRoundDropDown.Items = {};
            app.RegRoundDropDown.ValueChangedFcn = createCallbackFcn(app, @RegRoundDropDownValueChanged, true);
            app.RegRoundDropDown.Value = {};
            app.TableFileSwitch = uiswitch(app.FunctionsPanel, 'slider');
            app.TableFileSwitch.Items = {'Status Tables', 'Files'};
            app.TableFileSwitch.ValueChangedFcn = createCallbackFcn(app, @TableFileSwitchValueChanged, true);
            app.TableFileSwitch.Value = 'Status Tables';
            app.DoAllButton = uibutton(app.FunctionsPanel, 'push');
            app.DoAllButton.ButtonPushedFcn = createCallbackFcn(app, @DoAllButtonPushed, true);
            app.DoAllButton.Text = 'DO ALL CORE';
            app.RedoCheckBox = uicheckbox(app.FunctionsPanel);
            app.RedoCheckBox.Text = 'Redo all too?';
            app.memMonitorButton = uibutton(app.FunctionsPanel, 'push');
            app.memMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @memMonitorButtonPushed, true);
            app.memMonitorButton.Text = 'Launch Memory Monitor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCRprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end