classdef LipoGone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChannelsPanel              matlab.ui.container.Panel
        ThreshMethodButtonGroup    matlab.ui.container.ButtonGroup
        OtsuButton                 matlab.ui.control.RadioButton
        AdaptiveButton             matlab.ui.control.RadioButton
        AdaptiveThresholdingSensitivitySlider  matlab.ui.control.Slider
        AdaptiveThresholdingSensitivityLabel  matlab.ui.control.Label
        ZplaneLabel                matlab.ui.control.Label
        ZplaneSlider               matlab.ui.control.Slider
        ZplaneSliderLabel          matlab.ui.control.Label
        ImageSelectionButtonGroup  matlab.ui.container.ButtonGroup
        ThresholdedButton          matlab.ui.control.RadioButton
        MultichannelButton         matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputf
        stack       % raw image stack
        im          % current im to be plotted
        thresh_im   % current thresholded image to be plotted
        info        % image info
        imsc        % scaled color image
        n_zplanes   % number of zplanes
        n_chans     % number of channels
        weights     % weights per channel
        threshold
        chan_names
        defclrs
        chanLabel_edit
        color_button
        cmin_edit
        cmax_edit
        autolim
        enable_checkbox
    end
    
    methods (Access = private)
        
        function update_ZplaneLabel(app)

            str = sprintf('(%u/%u)',app.ZplaneSlider.Value,app.n_zplanes);
            app.ZplaneLabel.Text = str;
            
        end

        function average_image(app)
            multi_chan = app.stack(:,:,:,app.ZplaneSlider.Value);
            multi_chan = cast(multi_chan,"double");

            for i=1:app.n_chans
                multi_chan(:,:,i) = rescale(multi_chan(:,:,i));
            end

            avg_im = zeros(size(multi_chan,[1 2]),'double');

            for i=1:app.n_chans
                avg_im = avg_im + app.weights(i).*multi_chan(:,:,i);
            end

            app.im = avg_im;
        end

        function update_image(app, mode)
            switch mode
                case 'multi'
                    imagesc(app.UIAxes,app.im);
                    colormap(app.UIAxes,"gray")
                case 'thresh'
                    imagesc(app.UIAxes,app.thresh_im);
                    colormap(app.UIAxes,"gray")
            end
        end

        function make_thresh_image(app)
            app.thresh_im = app.im;
            switch app.ThreshMethodButtonGroup.SelectedObject.Text
                case 'Adaptive'
                    app.threshold = adaptthresh(app.thresh_im, app.AdaptiveThresholdingSensitivitySlider.Value);
                case 'Otsu'
                    app.threshold = graythresh(app.thresh_im);
            end
            app.thresh_im = app.thresh_im > app.threshold;
        end

        function initialize_chans(app)
            for i = 1:app.n_chans

                pcol = [0.9 0.9 0.9];

                cmax = 255;
                yoff = 10;
                xoff = 130*(i-1);
                
                app.chanLabel_edit(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'edit', 'String', app.chan_names{i},'Units','pixels' ...
                    ,'BackgroundColor', [1 1 1],'Position',[xoff+15 yoff+150 110 20],'HorizontalAlignment','Center', 'Fontsize', 7, 'Fontweight', 'Bold');
                
                app.color_button(i) = uicontrol('parent', app.ChannelsPanel,'Style', 'pushbutton',  'Units', 'Pixels', 'Position', ...
                [xoff+15, yoff+115, 110, 30], 'String', '', 'Callback', {@colorButtonFcn,gcf, i}, 'BackgroundColor', app.defclrs(i, :));
                
                uicontrol('parent', app.ChannelsPanel, 'Style', 'text', 'String', 'Min: ','Units','pixels' ...
                    ,'BackgroundColor',pcol,'Position',[xoff+14 yoff+66 40 24],'HorizontalAlignment','Right');
                uicontrol('parent', app.ChannelsPanel, 'Style', 'text', 'String', 'Max: ','Units','pixels' ...
                    ,'BackgroundColor',pcol,'Position',[xoff+14 yoff+37 40 24],'HorizontalAlignment','Right');
                
                app.cmin_edit(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'edit', 'String', '0','Units','pixels' ...
                    ,'BackgroundColor',[1 1 1],'Position',[xoff+61 yoff+72 56 24],'HorizontalAlignment','Right', ...
                    'Callback', {@updateImage,gcf}, 'Enable', 'Off');
                
                app.cmax_edit(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'edit', 'String', num2str(cmax),'Units','pixels' ...
                    ,'BackgroundColor',[1 1 1],'Position',[xoff+61 yoff+42 56 24],'HorizontalAlignment','Right', ...
                    'Callback', {@updateImage,gcf}, 'Enable', 'Off');
                
                app.autolim(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'radiobutton',  'Units', 'pixels', 'Position', ...
                    [xoff+78 yoff+7 70 24], 'String', 'Auto','FontWeight','Bold', 'Min', 0, 'Max', 1, ...
                    'BackgroundColor', pcol, 'Value', 1, 'Callback', {@enableLims,gcf,i});
                
                app.enable_checkbox(i) = uicontrol('parent', app.ChannelsPanel, 'Style', 'checkbox', 'Value', i<4,'Units','pixels' ...
                ,'BackgroundColor',pcol,'Position',[xoff+28 yoff+7 46 24],'HorizontalAlignment','Right', ...
                'Callback', {@updateImage,gcf}, 'BackgroundColor', pcol, 'String', 'On', 'FontSize', 10);
            
                uicontrol('parent', app.ChannelsPanel, 'Style', 'text', 'String', ' ','Units','pixels' ...
                    ,'BackgroundColor',pcol-0.1,'Position',[xoff+132 yoff+10 5 150],'HorizontalAlignment','Right');
            end
        end

        function get_chan_names(app)

            fn_split = split(app.inputf,'__');
            chans = fn_split{2};
            app.chan_names = split(chans,'_');
            
            % in case additional image info was not included after a second double
            % underscore
            last_chan = split(app.chan_names{end},'.');
            app.chan_names{end} = last_chan{1};

        end

        function make_colors(app)
            possibleclrs = [...
                1 0 0;...
                0 1 0;...
                0 0 1;...
                1 0 1;...
                0 1 1;...
                1 1 0;...
                1 1 1];
            app.defclrs = possibleclrs(1:app.n_chans, :);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputf)
            app.inputf = inputf;
            

            addpath(['./' 'utils'])

            [app.stack, app.info] = read_tiff(inputf);

            sz = size(app.stack);
            app.n_chans = sz(3);
            app.n_zplanes = sz(4);

            app.weights = repelem(1/app.n_chans,app.n_chans);
            
            app.ZplaneSlider.Limits(1) = 1;
            app.ZplaneSlider.Limits(2) = app.n_zplanes;
            app.update_ZplaneLabel();

            app.average_image()
            app.update_image('multi')
            
            app.threshold = app.AdaptiveThresholdingSensitivitySlider.Value;

            app.get_chan_names()
            app.make_colors()
            app.initialize_chans()
            
        end

        % Selection changed function: ImageSelectionButtonGroup
        function ImageSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageSelectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Thresholded Image')
                app.make_thresh_image()
                app.update_image('thresh')
            elseif strcmp(selectedButton.Text,'Multichannel Image')
                app.average_image()
                app.update_image('multi')
            end
        end

        % Value changed function: ZplaneSlider
        function ZplaneSliderValueChanged(app, event)
            value = app.ZplaneSlider.Value;
            app.ZplaneSlider.Value = round(value);
            app.update_ZplaneLabel()
            app.average_image()

            switch app.ImageSelectionButtonGroup.SelectedObject.Text
                case 'Multichannel Image'
                    app.update_image('multi')
                case 'Thresholded Image'
                    app.update_image('thresh')
            end
            
        end

        % Value changed function: AdaptiveThresholdingSensitivitySlider
        function AdaptiveThresholdingSensitivitySliderValueChanged(app, event)

            if strcmp(app.ImageSelectionButtonGroup.SelectedObject.Text,'Thresholded Image')
                app.make_thresh_image()
                app.update_image('thresh')
            end

        end

        % Selection changed function: ThreshMethodButtonGroup
        function ThreshMethodButtonGroupSelectionChanged(app, event)
            app.make_thresh_image()
            app.update_image('thresh')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageSelectionButtonGroupSelectionChanged, true);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.MultichannelButton = uiradiobutton(app.ImageSelectionButtonGroup);
            app.MultichannelButton.Text = 'Multichannel Image';
            app.MultichannelButton.Value = true;
            app.ThresholdedButton = uiradiobutton(app.ImageSelectionButtonGroup);
            app.ThresholdedButton.Text = 'Thresholded Image';
            app.ZplaneSliderLabel = uilabel(app.UIFigure);
            app.ZplaneSliderLabel.Text = 'Z-plane';
            app.ZplaneSlider = uislider(app.UIFigure);
            app.ZplaneSlider.MajorTicks = [];
            app.ZplaneSlider.ValueChangedFcn = createCallbackFcn(app, @ZplaneSliderValueChanged, true);
            app.ZplaneSlider.MinorTicks = [];
            app.ZplaneLabel = uilabel(app.UIFigure);
            app.ZplaneLabel.Text = 'Zplane';
            app.AdaptiveThresholdingSensitivityLabel = uilabel(app.UIFigure);
            app.AdaptiveThresholdingSensitivityLabel.Text = {'Adaptive'; 'Thresholding'; 'Sensitivity'};
            app.AdaptiveThresholdingSensitivitySlider = uislider(app.UIFigure);
            app.AdaptiveThresholdingSensitivitySlider.Limits = [0 1];
            app.AdaptiveThresholdingSensitivitySlider.MajorTicks = [];
            app.AdaptiveThresholdingSensitivitySlider.MajorTickLabels = {''};
            app.AdaptiveThresholdingSensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @AdaptiveThresholdingSensitivitySliderValueChanged, true);
            app.AdaptiveThresholdingSensitivitySlider.MinorTicks = [];
            app.AdaptiveThresholdingSensitivitySlider.Value = 0.5;
            app.ThreshMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ThreshMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThreshMethodButtonGroupSelectionChanged, true);
            app.ThreshMethodButtonGroup.Title = 'Thresholding Method';
            app.AdaptiveButton = uiradiobutton(app.ThreshMethodButtonGroup);
            app.AdaptiveButton.Text = 'Adaptive';
            app.AdaptiveButton.Value = true;
            app.OtsuButton = uiradiobutton(app.ThreshMethodButtonGroup);
            app.OtsuButton.Text = 'Otsu';
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LipoGone(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end