classdef block_cut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SavePitchYawSettingswillcloseGUIButton  matlab.ui.control.Button
        ManipulateOverlayPanel        matlab.ui.container.Panel
        RotationSpinner               matlab.ui.control.Spinner
        RotationSpinnerLabel          matlab.ui.control.Label
        YZoomSpinner                  matlab.ui.control.Spinner
        YZoomSpinnerLabel             matlab.ui.control.Label
        XZoomSpinner                  matlab.ui.control.Spinner
        XZoomLabel                    matlab.ui.control.Label
        CCFOverlayOpacitySlider       matlab.ui.control.Slider
        CCFOverlayOpacityLabel        matlab.ui.control.Label
        OverlayCCFCheckBox            matlab.ui.control.CheckBox
        SettingsPanel                 matlab.ui.container.Panel
        OverlayAnnoCheckBox           matlab.ui.control.CheckBox
        SampleImageParamsPanel        matlab.ui.container.Panel
        AutosetlimitsCheckBox         matlab.ui.control.CheckBox
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MinEditFieldLabel             matlab.ui.control.Label
        SampleImageChannelsListBox    matlab.ui.control.ListBox
        SampleImageChannelsListBoxLabel  matlab.ui.control.Label
        LoadSampleImageButton         matlab.ui.control.Button
        HistoPanel                    matlab.ui.container.Panel
        CCFOverlayAxes                matlab.ui.control.UIAxes
        HistoAxes                     matlab.ui.control.UIAxes
        YawImage                      matlab.ui.control.Image
        PitchImage                    matlab.ui.control.Image
        YawSpinner                    matlab.ui.control.Spinner
        BlockingcutyawLabel           matlab.ui.control.Label
        UpdateCCFVolumeButton         matlab.ui.control.Button
        PitchSpinner                  matlab.ui.control.Spinner
        BlockingcutpitchSpinnerLabel  matlab.ui.control.Label
        CCFtt                         matlab.ui.control.TextArea
        SelectCCFVolumeButtonGroup    matlab.ui.container.ButtonGroup
        AverageTemplateButton         matlab.ui.control.RadioButton
        AnnotationBoundaryButton      matlab.ui.control.RadioButton
        AnnotationButton              matlab.ui.control.RadioButton
        ColorCCFbyButtonGroup         matlab.ui.container.ButtonGroup
        SubstructureButton            matlab.ui.control.RadioButton
        StructureButton               matlab.ui.control.RadioButton
        DivisionButton                matlab.ui.control.RadioButton
        CategoryButton                matlab.ui.control.RadioButton
        OrganButton                   matlab.ui.control.RadioButton
        AllenCCFVolumePanel           matlab.ui.container.Panel
        incPButtonFast                matlab.ui.control.Button
        incAButtonFast                matlab.ui.control.Button
        incAButton                    matlab.ui.control.Button
        incPButton                    matlab.ui.control.Button
        zSlider                       matlab.ui.control.Slider
        CCFaxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        abcDir      % directory of abc atlas
        imgDir      % subdirectory of abc atlas for CCF stuff
        colorDir    % subdirectory of abc atlas for color stuff
        expDir      % experiment directory, app input argument

        parent_app  % parent app (HCRprocess)

        ccfNII %
        ccfColors %
        ccfTerms
        
        anno_orig
        anno_bound_orig
        avg_temp_orig

        anno % Allen CCF volume, axes correspond to the following anatomical directions:
             % Default: X (DV), Y (AP), Z (ML)
             % Post-rotations: X (ML), Y (DV), Z (AP)
        anno_bound
        avg_temp

        Tcolors % Table that stores hexadecimal color codes of every parcellation index
                % across all parcellation hierarchy levels
        Tterms

        colors
        
        numZ    % total number of z (AP) planes in CCF volume
        zCCF    % current z (AP) plane in CCF volume

        ttOrgan
        ttCategory
        ttDivision
        ttStructure
        ttSubstructure

        CCFmarker
        CCF_im
        CCF_im_overlay

        im % sample image
        im_info % sample image metadata
        im_num_chans % number of sample image channels
        im_chan % currently selected sample image channel
        im_num_planes % number of planes in sample image
        im_plane %currently selected z plane for multi-plane sample images

    end
    
    methods (Access = private)

        function loadCCFvol(app)

            function vol = rotateVol(vol)
                vol = imrotate3(vol,-90,[0 0 1]);
                vol = imrotate3(vol,-90,[0 1 0]);
            end
            
            app.anno = niftiread(fullfile(app.imgDir,'annotation_10.nii'));
            app.anno_bound = niftiread(fullfile(app.imgDir,'annotation_boundary_10.nii'));
            app.avg_temp = niftiread(fullfile(app.imgDir,'average_template_10.nii'));

            app.anno = rotateVol(app.anno);
            app.anno_bound = rotateVol(app.anno_bound);
            app.avg_temp = rotateVol(app.avg_temp);

            app.anno_orig = app.anno;
            app.anno_bound_orig = app.anno_bound;
            app.avg_temp_orig = app.avg_temp;
            
        end
        
        function setCCFcolormap(app)
            
            if strcmp(app.SelectCCFVolumeButtonGroup.SelectedObject.Text,'Annotation')

                switch app.ColorCCFbyButtonGroup.SelectedObject.Text
                    case 'Organ'
                        app.colors = hex2rgb(app.Tcolors{:,'organ_color'});
                    case 'Category'
                        app.colors = hex2rgb(app.Tcolors{:,'category_color'});
                    case 'Division'
                        app.colors = hex2rgb(app.Tcolors{:,'division_color'});
                    case 'Structure'
                        app.colors = hex2rgb(app.Tcolors{:,'structure_color'});
                    case 'Substructure'
                        app.colors = hex2rgb(app.Tcolors{:,'substructure_color'});
                end

            else

                app.colors = gray(517);

            end

        end

        function updateCCFvol(app)

            X = app.anno(:,:,app.zCCF);
            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    C = unique(X);
                    cmap = app.colors(ismember(app.Tcolors.parcellation_index,C),:);
                    d = dictionary(C',1:length(C));
                    X = d(X);
                    RGB = ind2rgb(X,cmap);

                    if app.OverlayAnnoCheckBox.Value == true
                        BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                        RGB = imoverlay(RGB,BW,"black");
                    end
                    
                    app.CCF_im = imshow(RGB,Parent=app.CCFaxes);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

                case 'Annotation Boundary'

                    app.CCF_im = imshow(app.anno_bound(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

                case 'Average Template'

                    app.CCF_im = imshow(app.avg_temp(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors,DisplayRange=[0,516]);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

            end

            app.CCF_im.AlphaData = AlphaData;

            if app.OverlayCCFCheckBox.Value == true

                app.updateCCFOverlay()

            end

        end

        function clickCCFCallback(app,~,hit)
            x = uint16(hit.IntersectionPoint(2));
            y = uint16(hit.IntersectionPoint(1));
            hold(app.CCFaxes,"on")
            delete(app.CCFmarker)
            app.CCFmarker = scatter(app.CCFaxes,y,x,40,"r+");
            hold(app.CCFaxes,"off")
            parc_ind = app.anno(x,y,app.zCCF);
            rows = (app.Tterms.parcellation_index == parc_ind);
            row = app.Tterms(rows,:);
            app.ttOrgan = row.organ{1};
            app.ttCategory = row.category{1};
            app.ttDivision = row.division{1};
            app.ttStructure = row.structure{1};
            app.ttSubstructure = row.substructure{1};
            app.updateCCFtt()
        end
        
        function updateCCFtt(app)
            formatSpec = "Organ: %s | Category: %s | Division: %s | Structure: %s | Substructure: %s";
            str = sprintf(formatSpec,app.ttOrgan,app.ttCategory, ...
                app.ttDivision,app.ttStructure,app.ttSubstructure);
            app.CCFtt.Value = str;
        end

        function calibrateZSlider(app, NameValueArgs)

            arguments
                app
                NameValueArgs.init
            end
            
            if NameValueArgs.init == false
                oldNumZ = app.numZ;
            end

            app.numZ = uint16(size(app.anno,3));
            app.zSlider.Limits = [double(1) double(app.numZ)];
            app.zSlider.MajorTicks = [1 app.numZ];
            app.zSlider.MajorTickLabels = {'A' 'P'};
            
            if NameValueArgs.init == true
                app.zCCF = uint16(median(1:app.numZ));
            else
                oldZNorm = double(app.zCCF) / double(oldNumZ);
                app.zCCF = uint16(oldZNorm * app.numZ);
            end

            app.zSlider.Value = double(app.zCCF);

        end

        function calibrateCCFOverlayOpacitySlider(app)

            app.CCFOverlayOpacitySlider.Limits = [0 1];
            app.CCFOverlayOpacitySlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.CCFOverlayOpacitySlider.MajorTickLabels = {};
            app.CCFOverlayOpacitySlider.MinorTicks = [];
            app.CCFOverlayOpacitySlider.Value = 1;

        end

        function updateSampleImage(app)

            imshow(app.im(:,:,app.im_chan,app.im_plane),[app.MinEditField.Value, app.MaxEditField.Value],Parent=app.HistoAxes,InitialMagnification="fit");

        end
    
        function createCCFOverlay(app)

            app.CCF_im_overlay = imshow(app.CCF_im.CData,Parent=app.CCFOverlayAxes);
            app.CCF_im_overlay.CDataMapping = 'direct';
            app.CCF_im_overlay.Clipping = 'off';

        end

        function updateCCFOverlay(app)

            old_xlim = app.CCFOverlayAxes.XLim;
            old_ylim = app.CCFOverlayAxes.YLim;
            
            app.CCF_im_overlay.CData = imrotate(app.CCF_im.CData,app.RotationSpinner.Value);

            % make colormap yellow for annotation boundary overlay and gray
            % otherwise
            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text
                case 'Annotation'
                    app.CCFOverlayAxes.Colormap = app.colors;
                case 'Annotation Boundary'
                    c = gray(256);
                    c(:,3) = 0;
                    app.CCFOverlayAxes.Colormap = c;
                case 'Average Template'
                    app.CCFOverlayAxes.Colormap = gray(256);
            end

            AlphaData = imrotate(app.CCF_im.AlphaData,app.RotationSpinner.Value);
            AlphaData(AlphaData ~= 0) = app.CCFOverlayOpacitySlider.Value;
            if strcmp(app.SelectCCFVolumeButtonGroup.SelectedObject.Text,'Annotation Boundary')
                AlphaData(app.CCF_im_overlay.CData == 0) = 0;
            end
            app.CCF_im_overlay.AlphaData = AlphaData;
            
            xPix = size(AlphaData,2);
            yPix = size(AlphaData,1);

            % xlim(app.CCFOverlayAxes,'manual')
            % ylim(app.CCFOverlayAxes,'manual')

            % app.CCFOverlayAxes.XLim = [0, xPix];
            % app.CCFOverlayAxes.YLim = [0, yPix];
            
            xAdj = (xPix * app.XZoomSpinner.Value) - xPix;
            xAdj = round(xAdj / 2);
            yAdj = (yPix * app.YZoomSpinner.Value) - yPix;
            yAdj = round(yAdj / 2);
            app.CCF_im_overlay.XData = [-xAdj, xPix + xAdj];
            app.CCF_im_overlay.YData = [-yAdj, yPix + yAdj];

            app.CCFOverlayAxes.XLim = old_xlim;
            app.CCFOverlayAxes.YLim = old_ylim;

            pan(app.CCFOverlayAxes,'on')

        end

        function autoSetLims(app, value)

            switch value

                case true

                    app.MaxEditField.Editable = false;
                    app.MinEditField.Editable = false;

                    P = prctile(app.im(:,:,app.im_chan),[0.1, 99.9],"all");
                    app.MaxEditField.Value = double(P(2));
                    app.MinEditField.Value = double(P(1));
                    app.HistoAxes.CLim = P;
                    
                case false

                    app.MaxEditField.Editable = true;
                    app.MinEditField.Editable = true;
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            
            app.parent_app = parent_app;
            app.expDir = parent_app.exp_dir;
            
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            movegui(app.UIFigure,'north')

            app.abcDir = params('abcDir');
            app.imgDir = fullfile(app.abcDir,'image_volumes', ...
                'Allen-CCF-2020','20230630');
            app.colorDir = fullfile(app.abcDir,'metadata', ...
                'Allen-CCF-2020','20230630','views');

            app.ccfColors = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_color.csv');
            app.ccfTerms = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_acronym.csv');

            app.Tcolors = readtable(app.ccfColors);
            app.Tterms = readtable(app.ccfTerms);

            app.setCCFcolormap()

            axis(app.CCFaxes,"off")
            axis(app.HistoAxes,"off")
            axis(app.CCFOverlayAxes,"off")
            axis(app.CCFaxes,"image")
            axis(app.HistoAxes,"image")
            axis(app.CCFOverlayAxes,"image")
            disableDefaultInteractivity(app.CCFaxes)
            disableDefaultInteractivity(app.CCFOverlayAxes)
            disableDefaultInteractivity(app.HistoAxes)

            app.loadCCFvol()
            app.calibrateZSlider(init=true)
            app.calibrateCCFOverlayOpacitySlider()

            app.CCFmarker = [];
            app.CCF_im_overlay = [];

            app.updateCCFvol()

        end

        % Selection changed function: ColorCCFbyButtonGroup
        function ColorCCFbyButtonGroupSelectionChanged(app, event)
           app.setCCFcolormap()     
           app.updateCCFvol()
        end

        % Selection changed function: SelectCCFVolumeButtonGroup
        function SelectCCFVolumeButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            app.zCCF = uint16(app.zSlider.Value);
            app.updateCCFvol()
        end

        % Button pushed function: incPButton
        function incPButtonPushed(app, event)
            if app.zCCF < app.numZ
                app.zCCF = app.zCCF + 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incAButton
        function incAButtonPushed(app, event)
            if app.zCCF > 1
                app.zCCF = app.zCCF - 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incPButtonFast
        function incPButtonFastPushed(app, event)
            if app.zCCF < (app.numZ - 10)
                app.zCCF = app.zCCF + 10;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            else
                app.zCCF = app.numZ;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incAButtonFast
        function incAButtonFastPushed(app, event)
            if app.zCCF >= 10
                app.zCCF = app.zCCF - 10;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            else
                app.zCCF = 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end    
        end

        % Button pushed function: UpdateCCFVolumeButton
        function UpdateCCFVolumeButtonPushed(app, event)
            
            app.anno = app.anno_orig;
            app.anno_bound = app.anno_bound_orig;
            app.avg_temp = app.avg_temp_orig;

            if app.PitchSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.PitchSpinner.Value,[1 0 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.PitchSpinner.Value,[1 0 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.PitchSpinner.Value,[1 0 0],"nearest");
            end
            
            if app.YawSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.YawSpinner.Value,[0 1 0],"nearest");
            end
            
            app.calibrateZSlider(init=false)
            app.updateCCFvol();
        end

        % Button pushed function: LoadSampleImageButton
        function LoadSampleImageButtonPushed(app, event)
            [file, location] = uigetfile('*.tiff;*.tif','Select tiff file',app.expDir);
            figure(app.UIFigure);
            [app.im, app.im_info] = read_tiff(fullfile(location,file));
            
            % evil, long if-else tree for taking in any kind of input image
            if ismatrix(app.im) % 2D image, 1 chan, 1 plane, easy

                app.im_num_chans = 1;

            elseif ~isempty(app.im_info)

                [chans, planes, ~, data_found_dict] = parse_im_info(fullfile(location,file));

                if data_found_dict('chans_found') == true
                    app.im_num_chans = chans; 
                else
                    app.im_num_chans = 1;
                end
                
                if app.im_num_chans == 1 % third dimension is planes, not channels
                    app.im = app.im(:,:,round(size(app.im,3)/2));
                end
                
                if data_found_dict('planes_found') == true
                    app.im_num_planes = planes;
                else
                    app.im_num_planes = 1;
                end
            end
            
            Items = {};
            for i = 1:app.im_num_chans
                Items{end+1} = append('Channel',' ',string(i)); 
            end
            Items = cellstr(Items);
            app.SampleImageChannelsListBox.Items = Items;
            app.SampleImageChannelsListBox.Value = 'Channel 1';
            app.im_chan = 1;
            if app.im_num_planes > 1
                app.im_plane = round(app.im_num_planes/2);
            else
                app.im_plane = 1;
            end

            app.updateSampleImage()
            app.autoSetLims(app.AutosetlimitsCheckBox.Value)
        end

        % Value changed function: MaxEditField, MinEditField, 
        % ...and 1 other component
        function SampleImageChannelsListBoxValueChanged(app, event)
           
            value = app.SampleImageChannelsListBox.Value;

            app.im_chan = str2num(value(end));
            app.updateSampleImage()

            if app.AutosetlimitsCheckBox.Value == true
                app.autoSetLims(true)
            end

        end

        % Value changed function: AutosetlimitsCheckBox
        function AutosetlimitsCheckBoxValueChanged(app, event)
            value = app.AutosetlimitsCheckBox.Value;
            app.autoSetLims(value)
        end

        % Value changed function: OverlayAnnoCheckBox
        function OverlayAnnoCheckBoxValueChanged(app, event)
            app.updateCCFvol()
        end

        % Value changed function: OverlayCCFCheckBox
        function OverlayCCFCheckBoxValueChanged(app, event)
            
            switch app.OverlayCCFCheckBox.Value
                case true
                    app.createCCFOverlay()
                    app.updateCCFOverlay()
                case false
                    delete(app.CCF_im_overlay)
            end
            
        end

        % Value changed function: CCFOverlayOpacitySlider
        function CCFOverlayOpacitySliderValueChanged(app, event)
            app.updateCCFOverlay()  
        end

        % Value changed function: XZoomSpinner
        function XZoomSpinnerValueChanged(app, event)
            app.updateCCFOverlay()
        end

        % Value changed function: YZoomSpinner
        function YZoomSpinnerValueChanged(app, event)
            app.updateCCFOverlay()
        end

        % Value changed function: RotationSpinner
        function RotationSpinnerValueChanged(app, event)
            app.updateCCFOverlay()
        end

        % Button pushed function: SavePitchYawSettingswillcloseGUIButton
        function SavePitchYawSettingswillcloseGUIButtonPushed(app, event)
            app.parent_app.finish_block_cut(app.PitchSpinner.Value,app.YawSpinner.Value)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AllenCCFVolumePanel = uipanel(app.UIFigure);
            app.AllenCCFVolumePanel.Title = 'Allen CCF Volume';
            app.CCFaxes = uiaxes(app.AllenCCFVolumePanel);
            app.CCFaxes.XTick = [];
            app.CCFaxes.YTick = [];
            app.CCFaxes.ZTick = [];
            app.zSlider = uislider(app.AllenCCFVolumePanel);
            app.zSlider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.zSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.incPButton = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incPButton.ButtonPushedFcn = createCallbackFcn(app, @incPButtonPushed, true);
            app.incPButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.incPButton.Text = '';
            app.incAButton = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incAButton.ButtonPushedFcn = createCallbackFcn(app, @incAButtonPushed, true);
            app.incAButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.incAButton.Text = '';
            app.incAButtonFast = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incAButtonFast.ButtonPushedFcn = createCallbackFcn(app, @incAButtonFastPushed, true);
            app.incAButtonFast.Icon = fullfile(pathToMLAPP, 'assets', 'double_left_arrow.png');
            app.incAButtonFast.Text = '';
            app.incPButtonFast = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incPButtonFast.ButtonPushedFcn = createCallbackFcn(app, @incPButtonFastPushed, true);
            app.incPButtonFast.Icon = fullfile(pathToMLAPP, 'assets', 'double_right_arrow.png');
            app.incPButtonFast.Text = '';
            app.ColorCCFbyButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorCCFbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorCCFbyButtonGroupSelectionChanged, true);
            app.ColorCCFbyButtonGroup.Title = 'Color CCF by:';
            app.OrganButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.OrganButton.Text = 'Organ';
            app.CategoryButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.CategoryButton.Text = 'Category';
            app.DivisionButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.StructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SubstructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.SubstructureButton.Text = 'Substructure';
            app.SelectCCFVolumeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectCCFVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectCCFVolumeButtonGroupSelectionChanged, true);
            app.SelectCCFVolumeButtonGroup.Title = 'Select CCF Volume:';
            app.AnnotationButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationButton.Text = 'Annotation';
            app.AnnotationButton.Value = true;
            app.AnnotationBoundaryButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationBoundaryButton.Text = 'Annotation Boundary';
            app.AverageTemplateButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AverageTemplateButton.Text = 'Average Template';
            app.CCFtt = uitextarea(app.UIFigure);
            app.CCFtt.Value = {'Click anywhere in the CCF to view metadata'};
            app.BlockingcutpitchSpinnerLabel = uilabel(app.UIFigure);
            app.BlockingcutpitchSpinnerLabel.Text = 'Blocking cut pitch (°):';
            app.PitchSpinner = uispinner(app.UIFigure);
            app.UpdateCCFVolumeButton = uibutton(app.UIFigure, 'push');
            app.UpdateCCFVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCCFVolumeButtonPushed, true);
            app.UpdateCCFVolumeButton.Text = 'Update CCF Volume';
            app.BlockingcutyawLabel = uilabel(app.UIFigure);
            app.BlockingcutyawLabel.Text = 'Blocking cut yaw (°):';
            app.YawSpinner = uispinner(app.UIFigure);
            app.PitchImage = uiimage(app.UIFigure);
            app.PitchImage.ImageSource = fullfile(pathToMLAPP, 'assets', 'pitch rotation graphic.png');
            app.YawImage = uiimage(app.UIFigure);
            app.YawImage.ImageSource = fullfile(pathToMLAPP, 'assets', 'yaw rotation graphic.png');
            app.HistoPanel = uipanel(app.UIFigure);
            app.HistoPanel.Title = 'Sample Image';
            app.HistoAxes = uiaxes(app.HistoPanel);
            app.HistoAxes.XTick = [];
            app.HistoAxes.YTick = [];
            app.HistoAxes.ZTick = [];
            app.CCFOverlayAxes = uiaxes(app.HistoPanel);
            app.CCFOverlayAxes.XTick = [];
            app.CCFOverlayAxes.YTick = [];
            app.CCFOverlayAxes.ZTick = [];
            app.LoadSampleImageButton = uibutton(app.UIFigure, 'push');
            app.LoadSampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleImageButtonPushed, true);
            app.LoadSampleImageButton.Text = 'Load Sample Image';
            app.SampleImageChannelsListBoxLabel = uilabel(app.UIFigure);
            app.SampleImageChannelsListBoxLabel.Text = {'Sample Image'; 'Channels'};
            app.SampleImageChannelsListBox = uilistbox(app.UIFigure);
            app.SampleImageChannelsListBox.Items = {};
            app.SampleImageChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @SampleImageChannelsListBoxValueChanged, true);
            app.SampleImageChannelsListBox.Value = {};
            app.SampleImageParamsPanel = uipanel(app.UIFigure);
            app.SampleImageParamsPanel.Title = 'Sample Image Display Parameters';
            app.MinEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @SampleImageChannelsListBoxValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @SampleImageChannelsListBoxValueChanged, true);
            app.AutosetlimitsCheckBox = uicheckbox(app.SampleImageParamsPanel);
            app.AutosetlimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutosetlimitsCheckBoxValueChanged, true);
            app.AutosetlimitsCheckBox.Text = 'Auto set limits';
            app.AutosetlimitsCheckBox.Value = true;
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.OverlayAnnoCheckBox = uicheckbox(app.SettingsPanel);
            app.OverlayAnnoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayAnnoCheckBoxValueChanged, true);
            app.OverlayAnnoCheckBox.Text = {'Overlay annotation boundaries'; 'on annotation volume?'};
            app.OverlayAnnoCheckBox.Value = true;
            app.ManipulateOverlayPanel = uipanel(app.UIFigure);
            app.ManipulateOverlayPanel.Title = 'Manipulate CCF Overlay';
            app.OverlayCCFCheckBox = uicheckbox(app.ManipulateOverlayPanel);
            app.OverlayCCFCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCCFCheckBoxValueChanged, true);
            app.OverlayCCFCheckBox.Text = 'Overlay CCF?';
            app.CCFOverlayOpacityLabel = uilabel(app.ManipulateOverlayPanel);
            app.CCFOverlayOpacityLabel.Text = {'CCF Overlay'; 'Opacity'};
            app.CCFOverlayOpacitySlider = uislider(app.ManipulateOverlayPanel);
            app.CCFOverlayOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @CCFOverlayOpacitySliderValueChanged, true);
            app.XZoomLabel = uilabel(app.ManipulateOverlayPanel);
            app.XZoomLabel.Text = 'X Zoom';
            app.XZoomSpinner = uispinner(app.ManipulateOverlayPanel);
            app.XZoomSpinner.Step = 0.01;
            app.XZoomSpinner.ValueChangedFcn = createCallbackFcn(app, @XZoomSpinnerValueChanged, true);
            app.XZoomSpinner.Value = 1;
            app.YZoomSpinnerLabel = uilabel(app.ManipulateOverlayPanel);
            app.YZoomSpinnerLabel.Text = 'Y Zoom';
            app.YZoomSpinner = uispinner(app.ManipulateOverlayPanel);
            app.YZoomSpinner.Step = 0.01;
            app.YZoomSpinner.ValueChangedFcn = createCallbackFcn(app, @YZoomSpinnerValueChanged, true);
            app.YZoomSpinner.Value = 1;
            app.RotationSpinnerLabel = uilabel(app.ManipulateOverlayPanel);
            app.RotationSpinnerLabel.Text = 'Rotation (°):';
            app.RotationSpinner = uispinner(app.ManipulateOverlayPanel);
            app.RotationSpinner.ValueChangedFcn = createCallbackFcn(app, @RotationSpinnerValueChanged, true);
            app.SavePitchYawSettingswillcloseGUIButton = uibutton(app.UIFigure, 'push');
            app.SavePitchYawSettingswillcloseGUIButton.ButtonPushedFcn = createCallbackFcn(app, @SavePitchYawSettingswillcloseGUIButtonPushed, true);
            app.SavePitchYawSettingswillcloseGUIButton.Text = {'Save Pitch/Yaw Settings'; '(will close GUI)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = block_cut(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end