classdef itxBspline2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        FinalGridspacingzSlider        matlab.ui.control.Slider
        FinalGridspacingzSliderLabel   matlab.ui.control.Label
        FinalGridspacingxySlider       matlab.ui.control.Slider
        FinalGridspacingxySliderLabel  matlab.ui.control.Label
        ResolutionspenaltiesSlider     matlab.ui.control.Slider
        ResolutionspenaltiesSliderLabel  matlab.ui.control.Label
        preprocessimagesCheckBox       matlab.ui.control.CheckBox
        InterpolatetransformCheckBox   matlab.ui.control.CheckBox
        For3d2dregistrationPanel       matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        zplanestoaddeachsideSlider     matlab.ui.control.Slider
        zplanestoaddeachsideSliderLabel  matlab.ui.control.Label
        ROIsmatEditField               matlab.ui.control.EditField
        ROIsmatEditFieldLabel          matlab.ui.control.Label
        ExpressionPredictionsIlastikpredictifpositivecsvEditField  matlab.ui.control.EditField
        ExpressionPredictionsIlastikpredictifpositivecsvEditFieldLabel  matlab.ui.control.Label
        cellidentitiesIlastikIDstifEditField  matlab.ui.control.EditField
        cellidentitiesIlastikIDstifEditFieldLabel  matlab.ui.control.Label
        SelectButton_5                 matlab.ui.control.Button
        SelectButton_4                 matlab.ui.control.Button
        SelectParentDirButton          matlab.ui.control.Button
        SelectButton_3                 matlab.ui.control.Button
        RegchannelfilenameEditField    matlab.ui.control.EditField
        RegchannelfilenameEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        InputDirectoryEditField        matlab.ui.control.EditField
        InputDirectoryEditFieldLabel   matlab.ui.control.Label
        SaveregchannelsCheckBox        matlab.ui.control.CheckBox
        SelectButton_2                 matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        IterationsSlider               matlab.ui.control.Slider
        IterationsSliderLabel          matlab.ui.control.Label
        ResolutionsnopenaltiesSlider   matlab.ui.control.Slider
        ResolutionsnopenaltiesSliderLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        parentFig;
        lastDir = "";
        d
        image_files
        fiximsz
        points_filepath
        dlg
        file_frac
        base
        increment
    end
    
    methods (Access = private)
        
        function selectHelper(app,field,isDir,filter)
            if ~exist("filter","var")
                filter = "*";
            end
            if isfolder(fileparts(field.Value))
                dir = fileparts(field.Value);
            elseif isfolder(app.lastDir)
                dir = app.lastDir;
            else
                dir = pwd;
            end
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            % Open directory selection dialog
            if isDir
                selected = uigetdir(dir);
            else
                [selected,path] = uigetfile(filter,dir); 
                selected=fullfile(path,selected);
            end
            delete(f); %delete the dummy figure
            % Check if a directory was selected
            if selected ~= 0
                if isDir
                    app.lastDir = selected;
                else
                    app.lastDir = fileparts(selected);
                end
                % Set the edit field to the full path
                field.Value = selected;
            end
        end

        function checkAndAutoFill(~, targDir, ext, field)
            % Get the list of files with the provided extension in the directory
            files = dir(fullfile(targDir, ext));
            
            % If there is exactly one file of the given type, auto-fill the corresponding input field
            if length(files) == 1
                field.Value = fullfile(targDir, files(1).name);
            elseif length(files) > 1
                warning('Multiple %s files found in directory. Please select manually.', ext);
            else
                warning('No %s file found in directory. Please select manually.', ext);
            end
        end
        
        
        function [newFixedImName,newMovImName] = calculateAndSaveZproject(app,h)
            
            newFixedImName = h.fn.fixed;
            newMovImName = h.fn.moving;
            
            if ndims(h.fixed)==3
                pts = h.pts.fix;
                img = h.fixed;
                fn = fullfile(app.InputDirectoryEditField.Value,'points','fixed.tif');
                newFixedImName = fn;
            else
                pts = h.pts.mov;
                img = h.moving;
                fn = fullfile(app.InputDirectoryEditField.Value,'points','moving.tif');
                fn2 = fullfile(app.InputDirectoryEditField.Value,'movingFull.tif');
                imwrite(uint16(squeeze(h.moving(:,:,1, :))), fn2, 'tif', 'writemode', 'overwrite', 'Compression', 'None');
                for k = 2:size(h.moving,3)
                    imwrite(uint16(squeeze(h.moving(:,:,k, :))), fn2, 'tif','writemode', 'append', 'Compression', 'None');
                end           
                
                newMovImName = fn;
            end
            minZ = min(pts(:,3));
            maxZ = max(pts(:,3));
            minZ = max(1,minZ-app.zplanestoaddeachsideSlider.Value);
            maxZ = min(size(img,3),maxZ+app.zplanestoaddeachsideSlider.Value);
            zProjected = max(img(:,:,minZ:maxZ),[],3);
            imwrite(uint16(squeeze(zProjected(:,:,1, :))), fn, 'tif', 'writemode', 'overwrite', 'Compression', 'None');
            for k = 2:size(zProjected,3)
                imwrite(uint16(squeeze(zProjected(:,:,k, :))), fn, 'tif','writemode', 'append', 'Compression', 'None');
            end             
        end
        
        function SelectParentDirButtonPushedHelper(app,parentDir)
            
    
            % Check for exactly one file of each type and auto fill corresponding input fields
            app.checkAndAutoFill(parentDir, '*.tiff;*.tif', app.cellidentitiesIlastikIDstifEditField);
            app.checkAndAutoFill(parentDir, '*.csv', app.ExpressionPredictionsIlastikpredictifpositivecsvEditField);
            app.checkAndAutoFill(parentDir, '*.mat', app.ROIsmatEditField);
        end
        
        % get the fiducials mat file corresponding to this image
        function pts_struct = find_pts_struct(~, moving_fn)

            [filepath,name,~] = fileparts(moving_fn);
            [filepath,~,~] = fileparts(filepath);
            filepath = fullfile(filepath,'internal','fiducials');

            moving_fn_split = split(name,"__"); 
            uniq = moving_fn_split{1};

            listing = dir(filepath);
            listing = {listing.name};
            for i=1:length(listing)
                if contains(listing{i},uniq)
                    mat_name = listing{i};
                end
            end

            pts_struct = fullfile(filepath, mat_name);

        end

        function make_temp_output(app, moving_fn)
            
            [filepath,~,~] = fileparts(moving_fn);
            [filepath,~,~] = fileparts(filepath);
            filepath = fullfile(filepath,'reg','temp');
            mkdir(filepath)
            app.OutputDirectoryEditField.Value = filepath;

            app.points_filepath = fullfile(filepath,'points');
            mkdir(app.points_filepath)
            
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputf, fixedf, dlg, file_frac)
            app.InputDirectoryEditField.Value = inputf;
            app.make_temp_output(inputf)

            addpath(genpath(fullfile(pwd, 'reg_utils')));
            addpath(['./' 'utils'])

            pts_struct = app.find_pts_struct(inputf);
            S = load(pts_struct);
            app.d = S.d;
            app.image_files = S.image_files;

            % sometimes things get moved around
            app.image_files.moving = inputf;
            app.image_files.fixed = fixedf;
            app.fiximsz = S.fiximsz;

            app.dlg = dlg;
            app.file_frac = file_frac;
            A = sscanf(file_frac, '(%u/%u files)');
            app.base = A(1)/A(2);
            app.increment = 1/A(2);

            app.RunButtonPushed()
        end

        % Value changed function: ResolutionsnopenaltiesSlider
        function ResolutionsnopenaltiesSliderValueChanged(app, event)
            app.ResolutionsnopenaltiesSlider.Value=round(app.ResolutionsnopenaltiesSlider.Value);
        end

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            app.IterationsSlider.Value=round(app.IterationsSlider.Value);
        end

        % Value changed function: SaveregchannelsCheckBox
        function SaveregchannelsCheckBoxValueChanged(app, event)
            value = app.SaveregchannelsCheckBox.Value;
            if value
                app.RegchannelfilenameEditField.Enable='on';
            else
                app.RegchannelfilenameEditField.Enable='off';
            end
        end

        % Value changed function: RegchannelfilenameEditField
        function RegchannelfilenameEditFieldValueChanged(app, event)
            value = app.RegchannelfilenameEditField.Value;
            if ~endsWith(value, '.tif')
                value = strcat(value, '.tif');
                app.RegchannelfilenameEditField.Value = value;
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.selectHelper(app.InputDirectoryEditField,1);
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            app.selectHelper(app.OutputDirectoryEditField,1);
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            app.selectHelper(app.cellidentitiesIlastikIDstifEditField,0,"*.tif;*.tiff")
        end

        % Button pushed function: SelectButton_4
        function SelectButton_4Pushed(app, event)
            app.selectHelper(app.ExpressionPredictionsIlastikpredictifpositivecsvEditField,0,"*.csv")
        end

        % Button pushed function: SelectButton_5
        function SelectButton_5Pushed(app, event)
            app.selectHelper(app.ROIsmatEditField,0,"*.mat")
        end

        % Button pushed function: SelectParentDirButton
        function SelectParentDirButtonPushed(app, event)
            
            if isfolder(app.lastDir)
                targDir = app.lastDir;
            else
                targDir = pwd;
            end

            % Select parent directory
            parentDir = uigetdir(targDir);

            % If no directory selected, exit the function
            if parentDir == 0
                return
            end
            app.lastDir=targDir;

            app.SelectParentDirButtonPushedHelper(parentDir)

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%             function intensity_bswarp_fcn(~,~,main_fig,itx_fig,matlab_python_working)
                       
            
            initialTransformFileName = "initial_transform_vals.txt";
            fixedImFilename = app.image_files.fixed;
            movingImFilename = app.image_files.moving;
            numResNoPenalties = app.ResolutionsnopenaltiesSlider.Value;
            numResPenalties = app.ResolutionspenaltiesSlider.Value;
            finalGridSpacingxy = app.FinalGridspacingxySlider.Value;
            finalGridSpacingz = app.FinalGridspacingzSlider.Value;
            numIter = app.IterationsSlider.Value;
            outFolder = app.OutputDirectoryEditField.Value;

            outFile = app.RegchannelfilenameEditField.Value;
            numChannelsInToTransform = getNumChannelsTif(movingImFilename); %this really should be separate for each file....
            
            numChannelsInFixed = getNumChannelsTif(fixedImFilename);
            numChannelsInMoving = getNumChannelsTif(movingImFilename);
            regChannelFixed = app.image_files.fixedChan;
            regChannelMoving = app.image_files.movingChan;
            
            im = read_tiff(app.image_files.moving, app.image_files.movingChan);
            affine = doAfWarpfast(app.d, im, app.fiximsz);
            affine = inv(affine);
            affine = affine([1:3 5:7 9:11 4 8 12]);
            
            fixedFilepts = fullfile(app.points_filepath,"ptsFixed.txt");
            writePointsFile(fixedFilepts,app.d.fix)
            movFilepts = fullfile(app.points_filepath,"ptsMov.txt");
            writePointsFile(movFilepts,app.d.mov);

            % if ndims(h.fixed)~=ndims(h.moving)
            %     [fixedImFilename,movingImFilename] = app.calculateAndSaveZproject(h);
            % end

            args.initialTransformFilePath = initialTransformFileName;
            args.fixedImFilename = fixedImFilename;
            args.movingImFilename = movingImFilename;
            args.affineTransform = regexprep(num2str(affine),' +',',');
            args.outputFullFilePath = fullfile(outFolder,outFile);
            args.resolutionsNoPenalties = numResNoPenalties;
            args.resolutionsPenalties = numResPenalties;
            args.iterations = numIter;
            args.fixedChannels = numChannelsInFixed;
            args.movingChannels = numChannelsInMoving;
            args.toTransformChannels = numChannelsInToTransform;
            args.registrationChannelFixed = regChannelFixed;
            args.registrationChannelMoving = regChannelMoving;
            args.finalGridSpacingxy = finalGridSpacingxy;
            args.finalGridSpacingz = finalGridSpacingz;
            args.interpolateTransform = app.InterpolatetransformCheckBox.Value;
            args.preprocessImages = app.preprocessimagesCheckBox.Value;
            jsonStr = jsonencode(args);
            % Specify the file name
            filename = fullfile(pwd,'internal','data.json');
            
            % Write the JSON string to the file
            fid = fopen(filename, 'w');
            if fid == -1
                error('Cannot open file %s for writing.', filename);
            end
            fwrite(fid, jsonStr, 'char');
            fclose(fid);
            
%             toCall = string('"python" "callElastixBspline.py" ') + strjoin(argsIn, ' '); %#ok<*STRQUOT> 
            % toCall = string("activate ") + params('HCRprocessEnv');
            % a = system(toCall);
            % if a>0
            %     warndlg("couldn't call python or python threw an error-- pausing execution");
            %     keyboard
            % end
            % 
            % toCall = string('"python" "elastixBspline.py" ') + filename; %#ok<*STRQUOT> 
            % disp("success(end+1) = system(string('" + toCall + "'));");
            % system(toCall); %call python registration code

            toCall = strcat(string("activate "),params('HCRprocessEnv'), ' && ',string('"python" "elastixBspline.py" '),filename);%#ok<*STRQUOT>
            a = system(toCall);
            if a>0
                warndlg("couldn't call python or python threw an error-- pausing execution");
                keyboard
            end
            disp("success(end+1) = system(string('" + toCall + "'));");
            
            if exist(fixedFilepts, 'file')
                delete(fixedFilepts);
            end
            
            if exist(movFilepts, 'file')
                delete(movFilepts);
            end

            %app.dlg.Message = strcat(app.file_frac, ' Merging output...');
            %app.dlg.Value = app.base + (1/3)*app.increment;
            
            outf = mergeRegChannels(movingImFilename,outFolder);
            
            rmdir(outFolder,'s')

            %app.dlg.Message = strcat(app.file_frac, ' Fixing saturated pixels...');
            %app.dlg.Value = app.base + (2/3)*app.increment;

            fixSatPixels(outf)

            
            % if temp && exist(pointsDir, 'dir')
            %     rmdir(pointsDir);
            % end
        end

        % Value changed function: zplanestoaddeachsideSlider
        function zplanestoaddeachsideSliderValueChanged(app, event)
            app.zplanestoaddeachsideSlider.Value = round(app.zplanestoaddeachsideSlider.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResolutionsnopenaltiesSliderLabel = uilabel(app.GridLayout);
            app.ResolutionsnopenaltiesSliderLabel.Text = 'Resolutions no penalties';
            app.ResolutionsnopenaltiesSlider = uislider(app.GridLayout);
            app.ResolutionsnopenaltiesSlider.Limits = [0 10];
            app.ResolutionsnopenaltiesSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.ResolutionsnopenaltiesSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionsnopenaltiesSliderValueChanged, true);
            app.ResolutionsnopenaltiesSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.ResolutionsnopenaltiesSlider.Value = 5;
            app.IterationsSliderLabel = uilabel(app.GridLayout);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.GridLayout);
            app.IterationsSlider.Limits = [0 10000];
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.IterationsSlider.MinorTicks = [0 125 250 375 500 625 750 875 1000 1125 1250 1375 1500 1625 1750 1875 2000 2125 2250 2375 2500 2625 2750 2875 3000 3125 3250 3375 3500 3625 3750 3875 4000 4125 4250 4375 4500 4625 4750 4875 5000 5125 5250 5375 5500 5625 5750 5875 6000 6125 6250 6375 6500 6625 6750 6875 7000 7125 7250 7375 7500 7625 7750 7875 8000 8125 8250 8375 8500 8625 8750 8875 9000 9125 9250 9375 9500 9625 9750 9875 10000];
            app.IterationsSlider.Value = 4000;
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.GridLayout, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SaveregchannelsCheckBox = uicheckbox(app.GridLayout);
            app.SaveregchannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveregchannelsCheckBoxValueChanged, true);
            app.SaveregchannelsCheckBox.Text = 'Save reg channels';
            app.SaveregchannelsCheckBox.Value = true;
            app.InputDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.InputDirectoryEditFieldLabel.Text = 'Input Directory';
            app.InputDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.InputDirectoryEditField.Editable = 'off';
            app.OutputDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.OutputDirectoryEditField.Editable = 'off';
            app.RegchannelfilenameEditFieldLabel = uilabel(app.GridLayout);
            app.RegchannelfilenameEditFieldLabel.Text = 'Reg channel filename';
            app.RegchannelfilenameEditField = uieditfield(app.GridLayout, 'text');
            app.RegchannelfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @RegchannelfilenameEditFieldValueChanged, true);
            app.RegchannelfilenameEditField.Editable = 'off';
            app.RegchannelfilenameEditField.Value = 'registration_channels.tif';
            app.For3d2dregistrationPanel = uipanel(app.GridLayout);
            app.For3d2dregistrationPanel.Title = 'For 3d -> 2d registration';
            app.GridLayout2 = uigridlayout(app.For3d2dregistrationPanel);
            app.SelectButton_3 = uibutton(app.GridLayout2, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select';
            app.SelectParentDirButton = uibutton(app.GridLayout2, 'push');
            app.SelectParentDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParentDirButtonPushed, true);
            app.SelectParentDirButton.Text = 'Select Parent Dir';
            app.SelectButton_4 = uibutton(app.GridLayout2, 'push');
            app.SelectButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_4Pushed, true);
            app.SelectButton_4.Text = 'Select';
            app.SelectButton_5 = uibutton(app.GridLayout2, 'push');
            app.SelectButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_5Pushed, true);
            app.SelectButton_5.Text = 'Select';
            app.cellidentitiesIlastikIDstifEditFieldLabel = uilabel(app.GridLayout2);
            app.cellidentitiesIlastikIDstifEditFieldLabel.Text = 'cell identities (Ilastik IDs tif)';
            app.cellidentitiesIlastikIDstifEditField = uieditfield(app.GridLayout2, 'text');
            app.cellidentitiesIlastikIDstifEditField.Editable = 'off';
            app.ExpressionPredictionsIlastikpredictifpositivecsvEditFieldLabel = uilabel(app.GridLayout2);
            app.ExpressionPredictionsIlastikpredictifpositivecsvEditFieldLabel.Text = 'Expression Predictions (Ilastik predict if positive csv)';
            app.ExpressionPredictionsIlastikpredictifpositivecsvEditField = uieditfield(app.GridLayout2, 'text');
            app.ExpressionPredictionsIlastikpredictifpositivecsvEditField.Editable = 'off';
            app.ROIsmatEditFieldLabel = uilabel(app.GridLayout2);
            app.ROIsmatEditFieldLabel.Text = 'ROIs (mat)';
            app.ROIsmatEditField = uieditfield(app.GridLayout2, 'text');
            app.ROIsmatEditField.Editable = 'off';
            app.zplanestoaddeachsideSliderLabel = uilabel(app.GridLayout2);
            app.zplanestoaddeachsideSliderLabel.Text = 'zplanes to add each side';
            app.zplanestoaddeachsideSlider = uislider(app.GridLayout2);
            app.zplanestoaddeachsideSlider.Limits = [0 8];
            app.zplanestoaddeachsideSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.zplanestoaddeachsideSlider.ValueChangedFcn = createCallbackFcn(app, @zplanestoaddeachsideSliderValueChanged, true);
            app.zplanestoaddeachsideSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8];
            app.InterpolatetransformCheckBox = uicheckbox(app.GridLayout);
            app.InterpolatetransformCheckBox.Text = 'Interpolate transform';
            app.InterpolatetransformCheckBox.Value = true;
            app.preprocessimagesCheckBox = uicheckbox(app.GridLayout);
            app.preprocessimagesCheckBox.Text = 'preprocess images';
            app.preprocessimagesCheckBox.Value = true;
            app.ResolutionspenaltiesSliderLabel = uilabel(app.GridLayout);
            app.ResolutionspenaltiesSliderLabel.Text = 'Resolutions penalties';
            app.ResolutionspenaltiesSlider = uislider(app.GridLayout);
            app.ResolutionspenaltiesSlider.Limits = [0 10];
            app.ResolutionspenaltiesSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.ResolutionspenaltiesSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.ResolutionspenaltiesSlider.Value = 1;
            app.FinalGridspacingxySliderLabel = uilabel(app.GridLayout);
            app.FinalGridspacingxySliderLabel.Text = 'Final Grid spacing xy';
            app.FinalGridspacingxySlider = uislider(app.GridLayout);
            app.FinalGridspacingxySlider.Limits = [20 500];
            app.FinalGridspacingxySlider.Value = 100;
            app.FinalGridspacingzSliderLabel = uilabel(app.GridLayout);
            app.FinalGridspacingzSliderLabel.Text = 'Final Grid spacing z';
            app.FinalGridspacingzSlider = uislider(app.GridLayout);
            app.FinalGridspacingzSlider.Limits = [1 10];
            app.FinalGridspacingzSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FinalGridspacingzSlider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.FinalGridspacingzSlider.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = itxBspline2D(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end