classdef manual_masks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayoutWrapper              matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        MarkLabeledCellsTab            matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        SaveResultsButton              matlab.ui.control.Button
        ImPanel                        matlab.ui.container.Panel
        LabelImsAxes                   matlab.ui.control.UIAxes
        ChansPanel                     matlab.ui.container.Panel
        zSliderLabel                   matlab.ui.control.Label
        zPlaneLabel                    matlab.ui.control.Label
        zSlider                        matlab.ui.control.Slider
        PreloadRGBZstackButton         matlab.ui.control.Button
        CirclesTable                   matlab.ui.control.Table
        OverlaysPanel                  matlab.ui.container.Panel
        RestoreGridButton              matlab.ui.control.Button
        GridOpacitySlider              matlab.ui.control.Slider
        GridOpacityLabel               matlab.ui.control.Label
        CCFOpacitySlider               matlab.ui.control.Slider
        CCFOpacitySliderLabel          matlab.ui.control.Label
        GridRectanglesSpinner          matlab.ui.control.Spinner
        GridRectanglesSpinnerLabel     matlab.ui.control.Label
        GridLineThicknessSpinner       matlab.ui.control.Spinner
        GridLineThicknessSpinnerLabel  matlab.ui.control.Label
        GridButton                     matlab.ui.control.StateButton
        GridLamp                       matlab.ui.control.Lamp
        CCFButton                      matlab.ui.control.StateButton
        CCFLamp                        matlab.ui.control.Lamp
        CirclesButton                  matlab.ui.control.StateButton
        CirclesLamp                    matlab.ui.control.Lamp
        ControlsPanel                  matlab.ui.container.Panel
        ActiveLabelChannelDropDown     matlab.ui.control.DropDown
        ActiveLabelChannelDropDownLabel  matlab.ui.control.Label
        CircleZspanSpinner             matlab.ui.control.Spinner
        CircleZspanSpinnerLabel        matlab.ui.control.Label
        CircleRadiusSpinner            matlab.ui.control.Spinner
        CircleRadiusSpinnerLabel       matlab.ui.control.Label
        DecrementZButton               matlab.ui.control.Button
        IncrementZButton               matlab.ui.control.Button
        DrawMasksTab                   matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        CrossCorrelationSearchPanel    matlab.ui.container.Panel
        EnableCrossCorSearchCheckBox   matlab.ui.control.CheckBox
        ROIRadiusSpinner               matlab.ui.control.Spinner
        ROIRadiusSpinnerLabel          matlab.ui.control.Label
        ZSearchDistanceSpinner         matlab.ui.control.Spinner
        ZSearchDistanceSpinnerLabel    matlab.ui.control.Label
        XYSearchDistanceSpinner        matlab.ui.control.Spinner
        XYSearchDistanceSpinnerLabel   matlab.ui.control.Label
        OverlaysPanel_2                matlab.ui.container.Panel
        RebalanceR0ImButton            matlab.ui.control.Button
        RebalanceR1ImButton            matlab.ui.control.Button
        NextmaskxButton                matlab.ui.control.Button
        PassspaceButton                matlab.ui.control.Button
        MasksOpacitySlider             matlab.ui.control.Slider
        MasksOpacitySliderLabel        matlab.ui.control.Label
        MasksButton                    matlab.ui.control.StateButton
        MasksLamp                      matlab.ui.control.Lamp
        CircleButton                   matlab.ui.control.StateButton
        CircleLamp                     matlab.ui.control.Lamp
        zSliderR1                      matlab.ui.control.Slider
        zSliderLabelR1                 matlab.ui.control.Label
        zPlaneLabelR1                  matlab.ui.control.Label
        ControlsPanel2                 matlab.ui.container.Panel
        ContractionBiasEditField       matlab.ui.control.NumericEditField
        ContractionBiasEditFieldLabel  matlab.ui.control.Label
        SmoothFactorEditField          matlab.ui.control.NumericEditField
        SmoothFactorEditFieldLabel     matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        SnapMasksCheckBox              matlab.ui.control.CheckBox
        DecrementZButton2              matlab.ui.control.Button
        IncrementZButton2              matlab.ui.control.Button
        SegmentationIterationsEditField  matlab.ui.control.NumericEditField
        SegmentationiterationsEditFieldLabel  matlab.ui.control.Label
        MasksTable                     matlab.ui.control.Table
        Round0ImagePanel               matlab.ui.container.Panel
        xCorrLabel                     matlab.ui.control.Label
        r0ImAxes                       matlab.ui.control.UIAxes
        Round1ImagePanel               matlab.ui.container.Panel
        r1ImAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        parent_app

        ims_f
        r0Im_f
        labelIms_f
        r1Im_f
        ccfOverlay_f

        r0Im
        labelIms = {}
        r1Im
        ccfOverlayIm
        circlesVol
        r0ROI
        r1SearchROI
        masksVol

        gridIm
        gridLabelMatrix
        keepGridLabels
        keepMask
        
        canvasRGB
        r0ImRGB
        labelImsRGB = {}
        r1ImRGB
        ccfOverlayImRGB
        circlesVolRGB
        r0ROIRGB
        masksVolRGB

        labelImsObj
        r0ImObj
        r1ImObj
        
        numChans
        numLabelIms
        chanStr = {}

        chansGrid
        chansLabelEdit = {}
        chansColorButton = {}
        chansCMinEdit = {}
        chansCMaxEdit = {}
        chansEnableCheckbox = {}
        chansAutoLim = {}
        
        defaultCMin = 0
        defaultCMax = 99.995
        componentsColor = [0.9 0.9 0.9]
        defaultColors
        uint8White = uint8([255 255 255])
        uint8Red = uint8([255 0 0])
        uint8Blue = uint8([0 0 255])
        zPlaneLabelIms = 1
        zPlaneR1Im = 1
        zPlaneR0Im = 1
        xyPad = 50
        xySearchDist = 100
        zSearchDist = 5
        circleLW = 1
        zRadius

        num_rows
        num_cols
        num_planes
        num_pix % per plane
        num_vox % over all planes / full volume

        PanAnchor
        IsPanning = false
        PanAxes
        ROIMode = false

        masks
        xMasks % intrinsic coordinates (x is column, y is row)
        yMasks % "
        zMasks % "
        masksCMap

    end
    
    methods (Access = private)
        
        function [im_local, im_num_chans_local] = loadIm(~, filepath)

            [im_local, im_info_local] = read_tiff(filepath);
            
            % evil, long if-else tree for taking in any kind of input image
            if ismatrix(im_local) % 2D image, 1 chan, 1 plane, easy

                im_num_chans_local = 1;

            elseif ~isempty(im_info_local)

                [chans, ~, ~, data_found_dict] = parse_im_info(filepath);

                if data_found_dict('chans_found') == true
                    im_num_chans_local = chans; 
                else
                    im_num_chans_local = 1;
                end

                assert(im_num_chans_local == 1, ...
                    'Number of channels in each image must be 1, got %g.', ...
                    im_num_chans_local)

            end
    
        end

        function loadIms(app)

            Message = 'Loading r0 registration image...';
            d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','on');

            [app.r0Im, ~] =  app.loadIm(app.r0Im_f);

            Message = 'Loading label images...';
            d.Message = Message;

            for i=1:length(app.labelIms_f)
                app.labelIms{i} = app.loadIm(app.labelIms_f{i});
            end

            Message = 'Loading r1 registration image...';
            d.Message = Message;

            [app.r1Im, ~] =  app.loadIm(app.r1Im_f);

            [app.num_rows, app.num_cols, app.num_planes] = size(app.r0Im);
            app.num_pix = app.num_rows * app.num_cols;
            app.num_vox = app.num_rows * app.num_cols * app.num_planes;
            app.zPlaneLabelIms = round(app.num_planes / 2);
            app.zPlaneR1Im = round(app.num_planes / 2);
            app.buildZSliders()

            app.canvasRGB = zeros(app.num_rows, app.num_cols, 1, 3, "uint8");
            app.r0ImRGB = app.makeRGBImage(app.r0Im,1);
            for i=1:app.numLabelIms
                app.labelImsRGB{i} = app.makeRGBImage(app.labelIms{i},i+1);
            end
            app.r1ImRGB = app.makeRGBImageGeneral(app.r1Im, app.uint8White, app.defaultCMin, app.defaultCMax);

            Message = 'Loading CCF overlay...';
            d.Message = Message;
            app.ccfOverlayIm = app.loadIm(app.ccfOverlay_f);

            Message = 'Making CCF overlay...';
            d.Message = Message;
            app.makeCCFOverlay()
            app.makeRGBImageCCF()

            app.circlesVol = zeros(app.num_rows, app.num_cols, app.num_planes, "uint16");
            app.circlesVolRGB = zeros(app.num_rows, app.num_cols, app.num_planes, 3, "uint8");
            app.masksVol = zeros(app.num_rows, app.num_cols, app.num_planes, "uint16");
            app.masksVolRGB = zeros(app.num_rows, app.num_cols, app.num_planes, 3, "uint8");

            app.makeGridIm()
            app.makeKeepMask()

            app.updateImageLabels()
            app.updateImageR1()
            app.updateImageR0()

            delete(d)

        end

        function extractROI(app, targetMask)

            maskMetaIdx = find(app.masks == targetMask, 1);
            x = app.xMasks(maskMetaIdx); % col index in data
            y = app.yMasks(maskMetaIdx); % row index in data
            z = app.zMasks(maskMetaIdx);

            % flipped to move from intrinsic coordinates to pixel
            % coordinates
            min1 = y - app.xyPad;
            min2 = x - app.xyPad;
            max1 = y + app.xyPad;
            max2 = x + app.xyPad;
            
            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.num_rows, max2 > app.num_cols])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    ind1 = min1:max1;
                    ind2 = min2:max2;

                    app.r0ROI = app.r0Im(ind1, ind2, z);

                    XLim = double([min2, max2]);
                    YLim = double([min1, max1]);

                    set(app.r0ImAxes, 'XLim', XLim, ...
                        'YLim', YLim);

                    % P = qprctile(app.r0ROI(:), [app.defaultCMin app.defaultCMax]);
                    % cmin = P(1);
                    % cmax = P(2);
                    % 
                    % im_scaled = (single(app.r0ROI) - cmin) ./ (cmax-cmin);
                    % im_scaled = clip(im_scaled, 0, 1);
                    % 
                    % clr_uint8 = reshape(app.uint8White, 1, 1, 3);
                    % app.r0ROIRGB = uint8(im_scaled .* single(clr_uint8));
                    % 
                    % if app.CircleButton.Value
                    %     app.r0ROIRGB = app.r0ROIRGB + squeeze(app.circlesVolRGB(ind1, ind2, z, :));
                    % end
                
                % this is not fully functional yet, it's assumed that ROIs
                % will always be in-bounds, if not, this needs to be
                % finished
                case true  

                    prepad1 = max(1-min1,0);
                    postpad1 = max(max1-app.num_rows,0);
                    prepad2 = max(1-min2,0);
                    postpad2 = max(max2-app.num_cols,0);

                    min1 = min1 + prepad1;
                    max1 = max1 - postpad1;
                    min2 = min2 + prepad2;
                    max2 = max2 - postpad2;

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    
                    app.r0ROI = app.r0Im(ind1, ind2, z);
                    app.r0ROI = padarray(app.r0ROI,[prepad1 prepad2],"pre");
                    app.r0ROI = padarray(app.r0ROI,[postpad1 postpad2],"post");
                        
            end

        end

        function moveROIInR1(app, targetMask)

            maskMetaIdx = find(app.masks == targetMask, 1);
            x = app.xMasks(maskMetaIdx); % col index in data
            y = app.yMasks(maskMetaIdx); % row index in data
            z = app.zMasks(maskMetaIdx);

            if z ~= app.zPlaneR1Im
                app.zPlaneR1Im = z;
                app.updateImageR1()
            end

            % flipped to move from intrinsic coordinates to pixel
            % coordinates
            min1 = y - app.xyPad;
            min2 = x - app.xyPad;
            max1 = y + app.xyPad;
            max2 = x + app.xyPad;

            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.num_rows, max2 > app.num_cols])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    XLim = double([min2, max2]);
                    YLim = double([min1, max1]);

                    set(app.r1ImAxes, 'XLim', XLim, ...
                        'YLim', YLim);

                case true  

                    test = 1;

            end
        end

        function findROIInR1(app, targetMask)
            
            maskMetaIdx = find(app.masks == targetMask, 1);
            x = app.xMasks(maskMetaIdx); % col index in data
            y = app.yMasks(maskMetaIdx); % row index in data
            z = app.zMasks(maskMetaIdx);

            % flipped to move from intrinsic coordinates to pixel
            % coordinates
            min1 = y - app.xySearchDist;
            min2 = x - app.xySearchDist;
            min3 = max(z - app.zSearchDist, 1);
            max1 = y + app.xySearchDist;
            max2 = x + app.xySearchDist;
            max3 = min(z + app.zSearchDist, app.num_planes);
            
            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.num_rows, max2 > app.num_cols])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    app.r1SearchROI = app.r1Im(ind1, ind2, ind3);

                case true  

                    % prepad1 = max(1-min1,0);
                    % postpad1 = max(max1-app.num_rows,0);
                    % prepad2 = max(1-min2,0);
                    % postpad2 = max(max2-app.num_cols,0);
                    % 
                    % min1 = min1 + prepad1;
                    % max1 = max1 - postpad1;
                    % min2 = min2 + prepad2;
                    % max2 = max2 - postpad2;
                    % 
                    % ind1 = min1:max1;
                    % ind2 = min2:max2;
                    % 
                    % app.r0ROI = app.r0Im(ind1, ind2, z);
                    % app.r0ROI = padarray(app.r0ROI,[prepad1 prepad2],"pre");
                    % app.r0ROI = padarray(app.r0ROI,[postpad1 postpad2],"post");
                        
            end

            numSearchPlanes = size(app.r1SearchROI, 3);
            outputSize = size(app.r0ROI,1) + size(app.r1SearchROI,1) - 1;
            C = zeros(outputSize, outputSize, numSearchPlanes, 'double');

            for i=1:numSearchPlanes
                C(:,:,i) = normxcorr2(app.r0ROI,app.r1SearchROI(:,:,i));
            end

            [M,I] = max(C,[],"all");
            [row_peak,col_peak,z_peak] = ind2sub(size(C),I);

            formatSpec = 'Maximum cross-correlation: %.4g';
            app.xCorrLabel.Text = sprintf(formatSpec,M);
            
            % finds coordinates in r1ROI space where overlaying the center 
            % of the r0ROI template produces maximum cross-correlation;
            % these coordinates can be negative or greater than r1ROI 
            % dimensions (outside r1ROI space)

            templateSize = app.xySearchDist + 1;
            row_center = row_peak - round(templateSize/2);
            col_center = col_peak - round(templateSize/2);
            z_center   = z_peak;

            % coordinates in overall r1 image space
            r1Im_row = row_center + min1 - 1;
            r1Im_col = col_center + min2 - 1;
            r1Im_plane = z_center + min3 - 1;

             if z_center ~= app.zPlaneR1Im
                app.zSliderR1.Value = double(r1Im_plane);
                app.zSliderValueChanged()
                app.updateImageR1()
             elseif app.CircleButton.Value
                app.updateImageR1()
            end

            XLim = double([r1Im_col - app.xyPad, r1Im_col + app.xyPad]);
            YLim = double([r1Im_row - app.xyPad, r1Im_row + app.xyPad]);

            set(app.r1ImAxes, 'XLim', XLim, ...
                'YLim', YLim);

        end

        function drawCircle(app, x, y, zSpan, color)
            
            r = app.CircleRadiusSpinner.Value;
            x0 = max(1, floor(x - r - app.circleLW - 1));
            x1 = min(app.num_cols, ceil(x + r + app.circleLW + 1));
            y0 = max(1, floor(y - r - app.circleLW - 1));
            y1 = min(app.num_rows, ceil(y + r + app.circleLW + 1));

            r = app.CircleRadiusSpinner.Value;
            [xx, yy] = meshgrid(x0:x1, y0:y1);
            dist = sqrt((single(xx) - single(x)).^2 + (single(yy) - single(y)).^2);
            edgeMask = (abs(dist - r) <= app.circleLW);

            fullMask = repmat(edgeMask, [1, 1, numel(zSpan), 3]);

            nz = numel(zSpan);
            patch = app.circlesVolRGB(y0:y1, x0:x1, zSpan, :);
            patch(fullMask) = repelem(color(:), nnz(edgeMask) * nz);
            app.circlesVolRGB(y0:y1, x0:x1, zSpan, :) = patch;

        end

        function addNewCircle(app, x, y, z)

            newCircle = uint16(max([max(app.masks) + 1, 1]));
            app.circlesVol(y,x,z) = newCircle; % intrinsic to pixel indices

            app.CirclesTable.Data(end+1,:) = [{newCircle}, num2cell(zeros(1, width(app.CirclesTable.Data)-1))];
            app.CirclesTable.Data(end,app.ActiveLabelChannelDropDown.Value) = {1};

            zSpan = (z - app.zRadius) : (z + app.zRadius);
            zSpan = clip(zSpan, 1, app.num_planes);

            app.drawCircle(x, y, zSpan, [255 255 0])

            app.masks(end+1) = newCircle;
            app.xMasks(end+1) = x;
            app.yMasks(end+1) = y;
            app.zMasks(end+1) = z;
            
            % add mask too
            app.MasksTable.Data(end+1,:) = [{newCircle}, {0}, {0}];

        end

        function deleteCircle(app, circle)

            % update tables
            app.CirclesTable.Data(app.CirclesTable.Data.('Mask ID') == circle,:) = [];
            app.MasksTable.Data(app.MasksTable.Data.('Mask ID') == circle,:) = [];

            % update masks meta data
            maskMetaIdx = find(app.masks == circle, 1);
            x = app.xMasks(maskMetaIdx);
            y = app.yMasks(maskMetaIdx);
            z = app.zMasks(maskMetaIdx);
            app.masks(maskMetaIdx) = [];
            app.xMasks(maskMetaIdx) = [];
            app.yMasks(maskMetaIdx) = [];
            app.zMasks(maskMetaIdx) = [];

            % update circles volume and circles rgb volume
            app.circlesVol(y, x, z) = 0;
            
            zSpan = (z - app.zRadius) : (z + app.zRadius);
            zSpan = clip(zSpan, 1, app.num_planes);
            
            app.drawCircle(x, y, zSpan, [0 0 0])


        end

        function regenCirclesRGB(app)
            
            app.circlesVolRGB = zeros(app.num_rows, app.num_cols, app.num_planes, 3, "uint8");

            for i=1:length(app.masks)

                zSpan = (app.zMasks(i) - app.zRadius) : (app.zMasks(i) + app.zRadius);
                zSpan = clip(zSpan, 1, app.num_planes);

                for j=1:length(zSpan)
                    app.circlesVolRGB(:,:,zSpan(j),:) = insertShape(squeeze(app.circlesVolRGB(:,:,zSpan(j),:)), ...
                        "circle", ...
                        [app.xMasks(i) app.yMasks(i) app.CircleRadiusSpinner.Value]);
                end

            end

        end

        function createChannelControls(app)
        
            % Outer grid: one column per channel, all equal width, spans full panel
            app.chansGrid = uigridlayout(app.ChansPanel, [1, app.numChans]);
            app.chansGrid.ColumnWidth = repmat({'1x'}, 1, app.numChans);
            app.chansGrid.RowHeight = {'1x'};
            app.chansGrid.Padding = [5 5 5 5];
            app.chansGrid.ColumnSpacing = 2;
        
            for i = 1:app.numChans
                % Nested grid for one channel's stacked controls
                % Rows: label | color button | min row | max row | auto/enable row
                chGrid = uigridlayout(app.chansGrid, [5, 1]);
                chGrid.RowHeight = {'fit', '2x', '1x', '1x', '1x'};
                chGrid.RowSpacing = 3;
                chGrid.Padding = [2 2 2 2];
                chGrid.Layout.Row = 1;
                chGrid.Layout.Column = i;
        
                % Channel label
                app.chansLabelEdit{i} = uieditfield(chGrid, 'text', ...
                    'Value', app.chanStr{i}, ...
                    'BackgroundColor', [1 1 1], ...
                    'HorizontalAlignment', 'center', ...
                    'FontSize', 18, ...
                    'FontWeight', 'bold');
                app.chansLabelEdit{i}.Layout.Row = 1;
        
                % Color swatch button
                app.chansColorButton{i} = uibutton(chGrid, 'push', ...
                    'Text', '', ...
                    'BackgroundColor', app.defaultColors(i,:), ...
                    'UserData', 'colorButton', ...
                    'ButtonPushedFcn', @(src,evt) app.colorButtonFcn(src, evt, i));
                app.chansColorButton{i}.Layout.Row = 2;

                app.chansCMinEdit{i} = uieditfield(chGrid, 'numeric', ...
                    'Value', 0, ...
                    'Limits', [0 100], ...
                    'ValueDisplayFormat', '%g', ...
                    'BackgroundColor', [1 1 1], ...
                    'HorizontalAlignment', 'center', ...
                    'FontSize', 18, ...
                    'Editable', 'off', ...
                    'ValueChangedFcn', @(src,evt) app.changeLims(src, evt, i));
                app.chansCMinEdit{i}.Layout.Row = 3;

                app.chansCMaxEdit{i} = uieditfield(chGrid, 'numeric', ...
                    'Value', 99.995, ...
                    'Limits', [0 100], ...
                    'ValueDisplayFormat', '%g', ...
                    'BackgroundColor', [1 1 1], ...
                    'HorizontalAlignment', 'center', ...
                    'FontSize', 18, ...
                    'Editable', 'off', ...
                    'ValueChangedFcn', @(src,evt) app.changeLims(src, evt, i));
                app.chansCMaxEdit{i}.Layout.Row = 4;
        
                % Bottom row: On checkbox + Auto toggle side by side
                bottomGrid = uigridlayout(chGrid, [1, 2]);
                bottomGrid.ColumnWidth = {'1x', '1x'};
                bottomGrid.Padding = [0 0 0 0];
                bottomGrid.ColumnSpacing = 2;
                bottomGrid.Layout.Row = 5;
        
                app.chansEnableCheckbox{i} = uicheckbox(bottomGrid, ...
                    'Text', 'On', ...
                    'FontSize', 18, ...
                    'Value', true, ...
                    'UserData', 'enableCheckbox', ...
                    'ValueChangedFcn', @(src,evt) app.updateImageLabels(src, evt));

                autoChk = uicheckbox(bottomGrid, 'Text', 'Auto', 'FontSize', 18, ...
                    'Value', true, ...
                    'ValueChangedFcn', @(src,evt) app.toggleLims(src, evt, i));
                autoChk.Layout.Column = 2;
                app.chansAutoLim{i} = autoChk;
        
            end

        end

        function changeLims(app, ~, ~, chan) 

            app.UIFigure.Pointer = 'watch';
            drawnow

            if chan == 1
                app.r0ImRGB = app.makeRGBImage(app.r0Im,chan);
            else
                app.labelImsRGB{chan-1} = app.makeRGBImage(app.labelIms{chan-1},chan);
            end

            app.updateImageLabels()

            app.UIFigure.Pointer = 'arrow';

        end

        function toggleLims(app, ~, ~, chan)

            switch app.chansAutoLim{chan}.Value

                case true

                    if app.chansCMinEdit{chan}.Value ~= app.defaultCMin || app.chansCMaxEdit{chan}.Value ~= app.defaultCMax
                        app.changeLims([], [], chan)
                    end

                    app.chansCMinEdit{chan}.Value = app.defaultCMin;
                    app.chansCMaxEdit{chan}.Value = app.defaultCMax;
                    app.chansCMinEdit{chan}.Editable = 'off';
                    app.chansCMaxEdit{chan}.Editable = 'off';

                case false

                    app.chansCMinEdit{chan}.Editable = 'on';
                    app.chansCMaxEdit{chan}.Editable = 'on';

            end

        end

        function colorButtonFcn(app, ~, ~, chan)

            newclr = uisetcolor(app.chansColorButton{chan}.BackgroundColor);
            
            % uisetcolor returns 0 if the user cancels the dialog
            if isequal(newclr, 0)
                return
            end
            
            app.chansColorButton{chan}.BackgroundColor = newclr;
            
            if chan == 1
                app.r0ImRGB = app.makeRGBImage(app.r0Im,1);
            else
                app.labelImsRGB{chan-1} = app.makeRGBImage(app.labelIms{chan-1},chan);
            end

            app.updateImageLabels()

        end

        function RGBIm = makeRGBImage(app, im, chan)

            clr = app.chansColorButton{chan}.BackgroundColor;
            clr_uint8 = uint8(round(clr * 255));
            clr_uint8 = reshape(clr_uint8, 1, 1, 1, 3);

            switch app.chansAutoLim{chan}.Value
                case true
                    P = qprctile(im(:), [app.defaultCMin app.defaultCMax]);
                case false
                    P = qprctile(im(:), [app.chansCMinEdit{chan}.Value app.chansCMaxEdit{chan}.Value]);
            end

            cmin = P(1);
            cmax = P(2);

            im_scaled = (single(im) - cmin) ./ (cmax-cmin);
            im_scaled = clip(im_scaled, 0, 1);
            RGBIm = uint8(im_scaled .* single(clr_uint8));

        end

        function RGBIm = makeRGBImageGeneral(~, im, rgb, pmin, pmax)
            
            if ~isa(rgb, 'uint8')
                rgb = uint8(round(rgb * 255));
            end
            rgb = reshape(rgb, 1, 1, 1, 3);

            P = qprctile(im(:), [pmin pmax]);
            cmin = P(1);
            cmax = P(2);

            im_scaled = (single(im) - cmin) ./ (cmax-cmin);
            im_scaled = clip(im_scaled, 0, 1);
            RGBIm = uint8(im_scaled .* single(rgb));

        end

        function makeRGBImageCCF(app)
            white = reshape(app.uint8White, 1, 1, 1, 3);
            white = single(white) * app.CCFOpacitySlider.Value * 0.01;
            app.ccfOverlayImRGB = uint8(single(app.ccfOverlayIm) .* white);
        end

        function makeKeepMask(app)
            app.keepMask = ones(app.num_rows, app.num_cols, 'uint8');
            app.keepMask(~ismember(app.gridLabelMatrix,app.keepGridLabels)) = 0;
        end

        function updateImageLabels(app, varargin)
            
            im = zeros(app.num_rows, app.num_cols, 1, 3, 'uint8');

            if app.chansEnableCheckbox{1}.Value
                im = imadd(im, app.r0ImRGB(:,:,app.zPlaneLabelIms,:));
            end
            
            for i=1:app.numLabelIms
                if app.chansEnableCheckbox{i+1}.Value
                    im = imadd(im, app.labelImsRGB{i}(:,:,app.zPlaneLabelIms,:));
                end
            end

            if app.CirclesButton.Value
                im = imadd(im, app.circlesVolRGB(:,:,app.zPlaneLabelIms,:)); 
            end

            if app.CCFButton.Value
                im = imadd(im, app.ccfOverlayImRGB(:,:,app.zPlaneLabelIms,:));
            end

            if app.GridButton.Value
                im = (im .* app.keepMask) + app.gridIm;
            end

            app.labelImsObj.CData = squeeze(im);

        end

        function updateImageR1(app, varargin)
            
            im = app.canvasRGB;
            im = imadd(im, app.r1ImRGB(:,:,app.zPlaneR1Im,:));

            if app.MasksButton.Value
                im = imadd(im, app.masksVolRGB(:,:,app.zPlaneR1Im,:));
            end

            if app.CCFButton.Value
                im = imadd(im, app.ccfOverlayImRGB(:,:,app.zPlaneR1Im,:));
            end

            im = squeeze(im);
            
            sel = app.MasksTable.Selection;
            if ~isempty(sel)
                inRange = (abs(double(app.zPlaneR1Im) - double(app.zMasks(sel))) <= app.zRadius);
                if app.CircleButton.Value && inRange
                    im = insertShape(im, 'circle', [app.xMasks(sel), app.yMasks(sel), app.CircleRadiusSpinner.Value], ...
                        'ShapeColor', 'yellow');
                end
            end

            app.r1ImObj.CData = im;

        end

        function updateImageR0(app)

            im = app.canvasRGB;
            im = imadd(im, app.r0ImRGB(:,:,app.zPlaneR0Im,:));
            im = squeeze(im);

            sel = app.MasksTable.Selection;
            if ~isempty(sel)
                inRange = (abs(double(app.zPlaneR0Im) - double(app.zMasks(sel))) <= app.zRadius);
                if app.CircleButton.Value && inRange
                    im = insertShape(im, 'circle', [app.xMasks(sel), app.yMasks(sel), app.CircleRadiusSpinner.Value], ...
                        'ShapeColor', 'yellow');
                end
            end

            app.r0ImObj.CData = im;

        end

        function inAxes = isMouseOverAxes(app, ax)

            mousePos = app.UIFigure.CurrentPoint;     % [x y] in pixels, from bottom-left
        
            axPos = getpixelposition(ax, true);       % true = isrecursive (relative to figure) [left bottom width height]
            inAxes = mousePos(1) >= axPos(1) && ...
                     mousePos(1) <= axPos(1) + axPos(3) && ...  % inside x
                     mousePos(2) >= axPos(2) && ...
                     mousePos(2) <= axPos(2) + axPos(4);  % inside y
        
        end

        function makeMasksColormap(app, targetMask)

            numMasks = height(app.CirclesTable.Data);
            highlightColor = [1 0 0];   % red
            otherColor    = [0 0 1];    % blue
            bgColor       = [0 0 0];    % black for label 0 / background

            app.masksCMap = repmat(otherColor, numMasks+1, 1);
            app.masksCMap(1,:) = bgColor; 
            app.masksCMap(targetMask+1,:) = highlightColor; 

        end

        function makeGridIm(app)

            app.gridIm = makeGrid(app.circlesVol(:,:,1), ...
                app.GridRectanglesSpinner.Value, ...
                app.GridLineThicknessSpinner.Value);

            CC = bwconncomp(~app.gridIm, 4);
            app.gridLabelMatrix = labelmatrix(CC);
            app.keepGridLabels = unique(app.gridLabelMatrix);
            
            app.keepMask = ones(app.num_rows, app.num_cols, 'uint8');
            app.gridIm = uint8(app.gridIm * 255 * app.GridOpacitySlider.Value * 0.01);
            
        end

        function makeCCFOverlay(app)

            parc_inds = unique(app.ccfOverlayIm);
            parc_inds(parc_inds == 0) = [];
            ccfOverlayBound = zeros(size(app.ccfOverlayIm),'logical');

            for i=1:app.num_planes

                BW = zeros(app.num_rows,app.num_cols,'logical');

                for j=1:length(parc_inds)
                    parc_ind = parc_inds(j);
                    parc_ind_perim = bwperim(app.ccfOverlayIm(:,:,i) == parc_ind);
                    BW = BW | parc_ind_perim;
                end

                BW = bwmorph(BW,"thicken",2);
                BW = bwmorph(BW,"bridge");
                BW = bwmorph(BW,"majority",2);
                ccfOverlayBound(:,:,i) = BW;

            end

            app.ccfOverlayIm = ccfOverlayBound;

        end

        function updateLamp(~, button, lamp)
            switch button.Value
                case true
                    lamp.Color = [0 1 0];
                case false
                    lamp.Color = [1 1 1];
            end
        end

        function updateZRadius(app)
            app.zRadius = uint8((app.CircleZspanSpinner.Value - 1) / 2);
        end

        function buildTablesBackEnds(app)

            % build circles table back end
            for i=2:app.numChans
                app.CirclesTable.ColumnName{end+1} = app.chanStr{i};
            end
            T = cell2table(cell(0, numel(app.CirclesTable.ColumnName)), ...
                'VariableNames', app.CirclesTable.ColumnName);
            app.CirclesTable.Data = T;
            
            % build masks table back end
            T = table('Size', [0 3], ...
                'VariableTypes', {'uint16', 'logical', 'logical'}, ...
                'VariableNames', app.MasksTable.ColumnName);
            app.MasksTable.Data = T;

        end

        function rightClick(app, x, y)

            switch app.TabGroup.SelectedTab.Title

                case 'Mark Labeled Cells'

                    % intrinsic coordinates
                    x = uint16(x);
                    y = uint16(y);

                    if ~isempty(app.masks) % not the first mask

                        dz = abs(app.zMasks - double(app.zPlaneLabelIms));
                        inZRange = (dz <= app.zRadius);
                        
                        if sum(inZRange) ~= 0 % at least one mask in z range
                            dxy = [double(app.xMasks(inZRange)'), double(app.yMasks(inZRange)')] - double([x, y]);
                            distXY = sqrt(sum(dxy .^ 2, 2));
                            normDistXY = distXY / app.CircleRadiusSpinner.Value;
                            [minDist, k] = min(normDistXY);
                        else % no masks in z range
                            minDist = Inf;
                        end
                        
                        if minDist <= 1 % mask already exists
                            
                            masksInZRange = app.masks(inZRange);
                            nearestValue = masksInZRange(k);

                            if isempty(app.UIFigure.CurrentModifier) % regular right click

                                app.CirclesTable.Data(app.CirclesTable.Data.('Mask ID') == nearestValue, ...
                                    app.ActiveLabelChannelDropDown.Value) = {1};
                                return

                            elseif strcmp(app.UIFigure.CurrentModifier,'control') % control + right click

                                app.deleteCircle(nearestValue)

                            end
                            
                        else % new mask (but not the first)

                            app.addNewCircle(x,y,app.zPlaneLabelIms)

                        end

                    else % first mask

                        app.addNewCircle(x,y,app.zPlaneLabelIms)

                    end

                    app.updateImageLabels()

                case 'Draw Masks'

                    if isempty(app.MasksTable.Selection)
                        msg = 'Please select a mask ID before drawing';
                        errordlg(msg)
                        return
                    end
                    
                    xl = xlim(app.r1ImAxes);
                    yl = ylim(app.r1ImAxes);

                    % xl = round(xl);
                    % yl = round(yl);

                    % xlim(app.r1ImAxes, xl)
                    % ylim(app.r1ImAxes, yl)

                    app.ROIMode = true;

                    h = drawfreehand(app.r1ImAxes);
                    P_reduced = reducepoly(h.Position);

                    col1 = uint16(xl(1));
                    col2 = uint16(xl(2));
                    row1 = uint16(yl(1));
                    row2 = uint16(yl(2));

                    r1ImView = squeeze(app.r1Im(row1:row2,col1:col2,app.zPlaneR1Im,:));
                    m = size(r1ImView, 1);
                    n = size(r1ImView, 2);

                    pts_crop = P_reduced - [(double(col1) - 1), (double(row1) - 1)];
                    BW = poly2mask(pts_crop(:,1), pts_crop(:,2), m, n);

                    if app.SnapMasksCheckBox.Value
                        BW = activecontour(r1ImView, ...
                            BW, ...
                            app.SegmentationIterationsEditField.Value, ...
                            app.MethodDropDown.Value, ...
                            'SmoothFactor', app.SmoothFactorEditField.Value, ...
                            'ContractionBias', app.ContractionBiasEditField.Value);
                    end

                    % debug code
                    % for i=1:100:2000
                    %     f = figure(i);
                    %     ax = axes(f);
                    %     BW = poly2mask(pts_crop(:,1), pts_crop(:,2), m, n);
                    %     BW = activecontour(r1ImView, ...
                    %         BW, ...
                    %         i, ...
                    %         'Chan-Vese', ...
                    %         'SmoothFactor', 10, ...
                    %         'ContractionBias',-0.3);
                    %     imshow(BW)
                    % end
                    
                    newMask = app.MasksTable.Data{app.MasksTable.Selection, 'Mask ID'};

                    if app.MasksTable.Data{app.MasksTable.Selection, 'Mask Created'}
                        app.deleteMask(newMask)
                    end
                    
                    fullBW = false(app.num_rows, app.num_cols);
                    fullBW(row1:row2,col1:col2) = BW;

                    slice = app.masksVol(:,:,app.zPlaneR1Im);
                    slice(fullBW) = newMask;
                    app.masksVol(:,:,app.zPlaneR1Im) = slice;
                    
                    blue = uint8(double(app.uint8Blue) * app.MasksOpacitySlider.Value * 0.01);
                    slice = squeeze(app.masksVolRGB(:,:,app.zPlaneR1Im,:));

                    k = find(fullBW);
                    slice(k) = blue(1);
                    slice(k + app.num_pix) = blue(2);
                    slice(k + 2*app.num_pix) = blue(3);

                    app.masksVolRGB(:,:,app.zPlaneR1Im,:) = slice;

                    delete(h)
                    app.updateImageR1()
                    app.ROIMode = false;

                    app.MasksTable.Data{app.MasksTable.Selection, 'Mask Created'} = true;
                    app.MasksTable.Data{app.MasksTable.Selection, 'Assessed'} = true;
    
            end

        end

        function buildAxes(app)
            
            axis(app.LabelImsAxes,"off")
            axis(app.LabelImsAxes,"image")
            disableDefaultInteractivity(app.LabelImsAxes)
            app.LabelImsAxes.Toolbar = [];
            app.labelImsObj = image(app.LabelImsAxes,[]);
            app.LabelImsAxes.YDir = 'reverse';

            axis(app.r0ImAxes,"off")
            axis(app.r0ImAxes,"image")
            disableDefaultInteractivity(app.r0ImAxes)
            app.r0ImAxes.Toolbar = [];
            app.r0ImObj = image(app.r0ImAxes,[]);
            app.r0ImAxes.YDir = 'reverse';

            axis(app.r1ImAxes,"off")
            axis(app.r1ImAxes,"image")
            disableDefaultInteractivity(app.r1ImAxes)
            app.r1ImAxes.Toolbar = [];
            app.r1ImObj = image(app.r1ImAxes,[]);
            app.r1ImAxes.YDir = 'reverse';

        end

        function buildZSliders(app)

            app.zSlider.Limits = [1, app.num_planes];
            app.zSlider.MinorTicks = 1:app.num_planes;
            app.zSlider.Value = app.zPlaneLabelIms;
            app.zPlaneLabel.Text = num2str(app.zPlaneLabelIms);

            app.zSliderR1.Limits = [1, app.num_planes];
            app.zSliderR1.MinorTicks = 1:app.num_planes;
            app.zSliderR1.Value = app.zPlaneR1Im;
            app.zPlaneLabelR1.Text = num2str(app.zPlaneR1Im);

        end

        function deleteMask(app, maskID)

            voxelCount = numel(app.masksVol);
            idx = find(app.masksVol == maskID);

            app.masksVol(idx) = 0;

            % RGB volume is X-by-Y-by-Z-by-3, so offsetting by channel*voxelCount
            % reaches the same voxel in each channel
            for channel = 0:2
                app.masksVolRGB(idx + channel*voxelCount) = 0;
            end

        end

        function passMask(app)

            if ~strcmp(app.TabGroup.SelectedTab.Title, 'Draw Masks')
                return
            end

            app.UIFigure.Pointer = 'watch';
            drawnow

            selectedRow = app.MasksTable.Selection;
            selectedMask = app.MasksTable.Data{selectedRow, 'Mask ID'};
            
            if app.MasksTable.Data{selectedRow, 'Mask Created'}
                app.deleteMask(selectedMask)
            end
            
            app.MasksTable.Data{selectedRow, 'Mask Created'} = false;
            app.MasksTable.Data{selectedRow, 'Assessed'} = true;

            app.updateImageR1()

            app.UIFigure.Pointer = 'arrow';

        end

        function cycleNextMask(app)

            k = find(~app.MasksTable.Data.('Assessed'), 1);

            if isempty(k)
                return
            end

            app.MasksTable.Selection = k;
            app.MasksTableSelectionChanged();

        end

        function loadState(app)
            
            [filepath,~,~] = fileparts(app.r0Im_f);
            [filepath,name,~] = fileparts(filepath);
            [filepath,~,~] = fileparts(filepath);
            masksDir = fullfile(filepath,'masks',name);
            manual_masks_f = fullfile(masksDir, 'manual_masks.txt');

            if isfile(manual_masks_f)

                Message = 'Loading previous state...';
                d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','on');

                T = readtable(manual_masks_f);
                app.masks = uint16(T.('mask_id')');
                app.xMasks = uint16(T.('x')');
                app.yMasks = uint16(T.('y')');
                app.zMasks = uint8(T.('z')');

                T2 = T(:, [1, 7:end]);
                T2.Properties.VariableNames{1} = 'Mask ID';
                T2 = convertvars(T2, 2:width(T2), 'logical');
                T2 = convertvars(T2, 1, 'uint16');
                app.CirclesTable.Data = T2;
                
                T3 = T(:, {'mask_id', 'mask_created', 'assessed'});
                T3.Properties.VariableNames = {'Mask ID', 'Mask Created', 'Assessed'};
                T3 = convertvars(T3, {'Mask Created', 'Assessed'}, 'logical');
                T3 = convertvars(T3, 1, 'uint16');
                app.MasksTable.Data = T3;

                app.regenCirclesRGB()
                app.updateImageLabels()

                [app.masksVol, ~] = app.loadIm(fullfile(masksDir, 'manual_masks.tiff'));
                blue = uint8(double(app.uint8Blue) * app.MasksOpacitySlider.Value * 0.01);
                masksVolBin = (app.masksVol > 0);

                for c = 1:3
                    slice = app.masksVolRGB(:,:,:,c);
                    slice(masksVolBin) = blue(c);
                    app.masksVolRGB(:,:,:,c) = slice;
                end

                app.updateImageR1()

                delete(d)

            end

        end
        
        % which axes is the mouse currently over?
        % returns empty if not over axes
        function ax = whichAx(app)

            switch app.TabGroup.SelectedTab.Title

                case 'Mark Labeled Cells'

                    ax = app.LabelImsAxes;
                    if ~app.isMouseOverAxes(ax)
                        ax = [];
                    end

                case 'Draw Masks'

                    ax1 = app.r1ImAxes;
                    ax2 = app.r0ImAxes;
                    if app.isMouseOverAxes(ax1)
                        ax = ax1;
                    elseif app.isMouseOverAxes(ax2)
                        ax = ax2;
                    else
                        ax = [];
                    end

            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ims_f, ccfOverlay_f, parent_app)
            
            app.UIFigure.WindowState = 'maximized';
            drawnow

            app.parent_app = parent_app;

            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
       
            app.buildAxes()

            % expected order is r0 image, label images, r1 image
            app.ims_f = ims_f;
            app.ccfOverlay_f = ccfOverlay_f;
            app.r0Im_f = app.ims_f{1};
            app.labelIms_f = app.ims_f(2:end-1);
            app.r1Im_f = app.ims_f{end};
            
            app.numLabelIms = length(app.labelIms_f);
            app.numChans = 1 + app.numLabelIms;
            for i=1:app.numChans
                [~,name,~] = fileparts(app.ims_f{i});
                app.chanStr{i} = name(4:end);
            end
            
            app.ActiveLabelChannelDropDown.Items = app.chanStr(2:end);
            app.ActiveLabelChannelDropDown.Value =  app.chanStr{2};
            app.defaultColors = hsv(app.numChans);
            app.updateZRadius()
            
            app.buildTablesBackEnds()
            app.createChannelControls()
            app.loadIms()
            app.loadState()
            

        end

        % Value changed function: zSlider, zSliderR1
        function zSliderValueChanged(app, event)
            
            switch app.TabGroup.SelectedTab.Title

                case 'Mark Labeled Cells'

                    app.zPlaneLabelIms = uint8(app.zSlider.Value);
                    app.zPlaneLabel.Text = num2str(app.zPlaneLabelIms);
                    app.updateImageLabels()

                case 'Draw Masks'

                    app.zPlaneR1Im = uint8(app.zSliderR1.Value);
                    app.zPlaneLabelR1.Text = num2str(app.zPlaneR1Im);
                    app.updateImageR1()
                    
            end

        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)

            ax = app.whichAx();
            if isempty(ax)
                return
            end

            % --- Zoom logic ---
            xlim   = double(get(ax, 'XLim'));
            ylim   = double(get(ax, 'YLim'));
            cp     = ax.CurrentPoint;
            mouseX = cp(1,1);
            mouseY = cp(1,2);

            zoomFactor = 1.15;
            if event.VerticalScrollCount < 0
                scale = 1 / zoomFactor;   % zoom in
            else
                scale = zoomFactor;        % zoom out
            end
            
            newXLim = mouseX + (xlim - mouseX) * scale;
            newYLim = mouseY + (ylim - mouseY) * scale;
            newXLim = max(0.5, min(double(app.num_cols) + 0.5, double(newXLim)));
            newYLim = max(0.5, min(double(app.num_rows) + 0.5, double(newYLim)));
            
            set(ax, 'XLim', newXLim, ...
                'YLim', newYLim);

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            if app.ROIMode
                return
            end

            ax = app.whichAx();
            if isempty(ax)
                return
            end

            cp = ax.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);

            switch app.UIFigure.SelectionType

                case 'normal'

                    app.PanAnchor  = [x, y];
                    app.IsPanning  = true;

                case 'alt'

                    app.rightClick(x, y)

                case 'extend'

                    x = uint16(x);
                    y = uint16(y);

                    gridLabel = app.gridLabelMatrix(y,x);

                    if gridLabel ~= 0

                        if ismember(gridLabel, app.keepGridLabels)
                            app.keepGridLabels(app.keepGridLabels == gridLabel) = [];
                        else
                            app.keepGridLabels(end+1) = gridLabel;
                        end

                        app.makeKeepMask()
                        app.updateImageLabels()

                    end

            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.IsPanning = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            if ~app.IsPanning
                return; 
            end

            ax = app.whichAx();

            cp = ax.CurrentPoint;
            dx = app.PanAnchor(1) - cp(1,1);
            dy = app.PanAnchor(2) - cp(1,2);

            ax.XLim = ax.XLim + dx;
            ax.YLim = ax.YLim + dy;

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if ismember('control', event.Modifier)
                switch event.Key
                    case 'z'
                        app.RebalanceR1ImButtonPushed()
                    case 'c'
                        app.RebalanceR0ImButtonPushed()
                end
            else
                switch event.Key
                    case 'z'
                        app.DecrementZButtonPushed([])
                    case 'c'
                        app.IncrementZButtonPushed([])
                    case 'space'
                        app.passMask()
                    case 'x'
                        app.cycleNextMask()
                end
            end
        end

        % Button pushed function: DecrementZButton, DecrementZButton2
        function DecrementZButtonPushed(app, event)
            
            switch app.TabGroup.SelectedTab.Title

                case 'Mark Labeled Cells'

                    if app.zPlaneLabelIms > 1
                        app.zSlider.Value = double(app.zPlaneLabelIms) - 1;
                        app.zSliderValueChanged([])
                    else
                        return
                    end
                
                case 'Draw Masks'

                    ax = app.whichAx();

                    switch ax.Tag
                        case 'r1ImAxes'
                            if app.zPlaneR1Im > 1
                                app.zSliderR1.Value = double(app.zPlaneR1Im) - 1;
                                app.zSliderValueChanged([])
                            else
                                return
                            end
                        case 'r0ImAxes'
                            if app.zPlaneR0Im > 1
                                app.zPlaneR0Im = app.zPlaneR0Im - 1;
                                app.updateImageR0()
                            else
                                return
                            end
                    end

            end
            
        end

        % Button pushed function: IncrementZButton, IncrementZButton2
        function IncrementZButtonPushed(app, event)
           
            switch app.TabGroup.SelectedTab.Title

                case 'Mark Labeled Cells'

                    if app.zPlaneLabelIms < app.num_planes
                        app.zSlider.Value = double(app.zPlaneLabelIms) + 1;
                        app.zSliderValueChanged([])
                    else
                        return
                    end

                case 'Draw Masks'

                    ax = app.whichAx();

                    switch ax.Tag
                        case 'r1ImAxes'
                            if app.zPlaneR1Im < app.num_planes
                                app.zSliderR1.Value = double(app.zPlaneR1Im) + 1;
                                app.zSliderValueChanged([])
                            else
                                return
                            end
                        case 'r0ImAxes'
                            if app.zPlaneR0Im < app.num_planes
                                app.zPlaneR0Im = app.zPlaneR0Im + 1;
                                app.updateImageR0()
                            else
                                return
                            end
                    end

            end
            
        end

        % Clicked callback: CirclesTable
        function CirclesTableClicked(app, event)
            
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            if displayColumn == 1

                app.UIFigure.Pointer = 'watch';
                drawnow
                
                targetMask = app.CirclesTable.Data{app.CirclesTable.Selection, 'Mask ID'};
                app.makeMasksColormap(targetMask)
                app.LabelImsAxes.Colormap = app.masksCMap;
    
                app.UIFigure.Pointer = 'arrow';

            else

                app.CirclesTable.Data{displayRow,displayColumn} = ~app.CirclesTable.Data{displayRow,displayColumn};
                
            end

        end

        % Value changed function: GridLineThicknessSpinner
        function GridLineThicknessSpinnerValueChanged(app, event)
            app.makeGridIm()
        end

        % Value changed function: CirclesButton
        function CirclesButtonValueChanged(app, event)
            app.updateLamp(app.CirclesButton, app.CirclesLamp)
            app.updateImageLabels()
        end

        % Value changed function: CCFButton
        function CCFButtonValueChanged(app, event)
            app.updateLamp(app.CCFButton, app.CCFLamp)
            app.updateImageLabels()
        end

        % Value changed function: GridButton
        function GridButtonValueChanged(app, event)
            app.updateLamp(app.GridButton, app.GridLamp)
            app.updateImageLabels()
        end

        % Value changed function: CCFOpacitySlider
        function CCFOpacitySliderValueChanged(app, event)
            app.makeRGBImageCCF()
            app.updateImageLabels()
        end

        % Value changed function: GridOpacitySlider
        function GridOpacitySliderValueChanged(app, event)
            app.makeGridIm()
            app.updateImageLabels()
        end

        % Value changed function: CircleZspanSpinner
        function CircleZspanSpinnerValueChanged(app, event)
            app.updateZRadius()
            app.regenCirclesRGB()
            app.updateImageLabels()
        end

        % Button pushed function: RestoreGridButton
        function RestoreGridButtonPushed(app, event)
            app.makeGridIm()
            app.updateImageLabels()
        end

        % Selection changed function: MasksTable
        function MasksTableSelectionChanged(app, event)
            
            targetMask = app.MasksTable.Data{app.MasksTable.Selection, 'Mask ID'};
            app.zPlaneR0Im = app.zMasks(app.MasksTable.Selection);
            app.updateImageR0()
            app.extractROI(targetMask)
            % app.r0ImObj.CData = app.r0ROIRGB;

            if app.EnableCrossCorSearchCheckBox.Value
                app.findROIInR1(targetMask)
            else
                app.moveROIInR1(targetMask)
            end
            
            
        end

        % Value changed function: XYSearchDistanceSpinner
        function XYSearchDistanceSpinnerValueChanged(app, event)
            app.xySearchDist = app.XYSearchDistanceSpinner.Value;
        end

        % Value changed function: ZSearchDistanceSpinner
        function ZSearchDistanceSpinnerValueChanged(app, event)
            app.zSearchDist = app.ZSearchDistanceSpinner.Value;
        end

        % Value changed function: ROIRadiusSpinner
        function ROIRadiusSpinnerValueChanged(app, event)
            app.xyPad = app.ROIRadiusSpinner.Value;
            app.MasksTableSelectionChanged()
        end

        % Value changed function: CircleButton
        function CircleButtonValueChanged(app, event)
            app.updateLamp(app.CircleButton, app.CircleLamp)
            app.MasksTableSelectionChanged()
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
            Message = 'Saving results...';
            d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','on');

            [filepath,~,~] = fileparts(app.r0Im_f);
            [filepath,name,~] = fileparts(filepath);
            [filepath,~,~] = fileparts(filepath);
            masksDir = fullfile(filepath,'masks',name);

            masks_table = table(app.masks', ...
                app.xMasks', app.yMasks', app.zMasks', ...
                app.MasksTable.Data.('Mask Created'), ...
                app.MasksTable.Data.('Assessed'), ...
                'VariableNames', {'mask_id', 'x', 'y', 'z', 'mask_created', 'assessed'});
            labels = app.CirclesTable.Data(:, 2:end);
            masks_table = [masks_table, labels];

            writetable(masks_table, fullfile(masksDir, 'manual_masks.txt'))

            filename = fullfile(masksDir, 'manual_masks.tiff');
            t = Tiff(filename, 'w8');
            dims = size(app.masksVol);

            tagstruct.ImageLength = dims(1);
            tagstruct.ImageWidth = dims(2);
            tagstruct.SampleFormat = Tiff.SampleFormat.UInt;
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.BitsPerSample = 16;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.ExtraSamples = Tiff.ExtraSamples.Unspecified;

            for i=1:dims(3)
                setTag(t,tagstruct);
                warning('off','MATLAB:imagesci:Tiff:missingExtraSamples')
                write(t,uint16(app.masksVol(:,:,i)));
                writeDirectory(t);
            end

            close(t)
            delete(d)
            
            [~,name,~] = fileparts(masksDir);
            app.parent_app.finish_manual_masks(name)

            delete(app)

        end

        % Button pushed function: RebalanceR1ImButton
        function RebalanceR1ImButtonPushed(app, event)
                
            % --- visible window in pixel indices ---
            xl = app.r1ImAxes.XLim;
            yl = app.r1ImAxes.YLim;
            cols = max(1, floor(xl(1) + 0.5)) : min(app.num_cols, ceil(xl(2) - 0.5));
            rows = max(1, floor(yl(1) + 0.5)) : min(app.num_rows, ceil(yl(2) - 0.5));

            ROI = app.r1Im(rows, cols, app.zPlaneR1Im);
            cmin = app.chansCMinEdit{1}.Value;
            cmax = app.chansCMaxEdit{1}.Value;

            cmin = single(prctile(ROI, cmin, "all"));
            cmax = single(prctile(ROI, cmax, "all"));

            rgb = reshape(app.uint8White, 1, 1, 1, 3);

            im = app.r1Im(:,:,app.zPlaneR1Im);
            im_scaled = (single(im) - cmin) ./ (cmax-cmin);
            im_scaled = clip(im_scaled, 0, 1);
            RGBIm = uint8(im_scaled .* single(rgb));
            app.r1ImRGB(:,:,app.zPlaneR1Im,:) = RGBIm;

            app.updateImageR1()
            
        end

        % Button pushed function: RebalanceR0ImButton
        function RebalanceR0ImButtonPushed(app, event)
            % --- visible window in pixel indices ---
            xl = app.r0ImAxes.XLim;
            yl = app.r0ImAxes.YLim;
            cols = max(1, floor(xl(1) + 0.5)) : min(app.num_cols, ceil(xl(2) - 0.5));
            rows = max(1, floor(yl(1) + 0.5)) : min(app.num_rows, ceil(yl(2) - 0.5));

            ROI = app.r0Im(rows, cols, app.zPlaneR0Im);
            cmin = app.chansCMinEdit{1}.Value;
            cmax = app.chansCMaxEdit{1}.Value;

            cmin = single(prctile(ROI, cmin, "all"));
            cmax = single(prctile(ROI, cmax, "all"));

            rgb = app.chansColorButton{1}.BackgroundColor;
            rgb = uint8(round(rgb * 255));
            rgb = reshape(rgb, 1, 1, 1, 3);

            im = app.r0Im(:,:,app.zPlaneR0Im);
            im_scaled = (single(im) - cmin) ./ (cmax-cmin);
            im_scaled = clip(im_scaled, 0, 1);
            RGBIm = uint8(im_scaled .* single(rgb));
            app.r0ImRGB(:,:,app.zPlaneR0Im,:) = RGBIm;

            app.updateImageR0()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayoutWrapper = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutWrapper);
            app.MarkLabeledCellsTab = uitab(app.TabGroup);
            app.MarkLabeledCellsTab.Title = 'Mark Labeled Cells';
            app.GridLayout = uigridlayout(app.MarkLabeledCellsTab);
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.IncrementZButton = uibutton(app.ControlsPanel, 'push');
            app.IncrementZButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementZButtonPushed, true);
            app.IncrementZButton.Text = 'Increment z [c]';
            app.DecrementZButton = uibutton(app.ControlsPanel, 'push');
            app.DecrementZButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementZButtonPushed, true);
            app.DecrementZButton.Text = 'Decrement z [z]';
            app.CircleRadiusSpinnerLabel = uilabel(app.ControlsPanel);
            app.CircleRadiusSpinnerLabel.Text = 'Circle Radius';
            app.CircleRadiusSpinner = uispinner(app.ControlsPanel);
            app.CircleRadiusSpinner.Limits = [1 Inf];
            app.CircleRadiusSpinner.Value = 20;
            app.CircleZspanSpinnerLabel = uilabel(app.ControlsPanel);
            app.CircleZspanSpinnerLabel.Text = 'Circle Z-span';
            app.CircleZspanSpinner = uispinner(app.ControlsPanel);
            app.CircleZspanSpinner.Step = 2;
            app.CircleZspanSpinner.Limits = [1 Inf];
            app.CircleZspanSpinner.ValueChangedFcn = createCallbackFcn(app, @CircleZspanSpinnerValueChanged, true);
            app.CircleZspanSpinner.Value = 5;
            app.ActiveLabelChannelDropDownLabel = uilabel(app.ControlsPanel);
            app.ActiveLabelChannelDropDownLabel.Text = 'Active Label Channel';
            app.ActiveLabelChannelDropDown = uidropdown(app.ControlsPanel);
            app.OverlaysPanel = uipanel(app.GridLayout);
            app.OverlaysPanel.Title = 'Overlays';
            app.CirclesLamp = uilamp(app.OverlaysPanel);
            app.CirclesButton = uibutton(app.OverlaysPanel, 'state');
            app.CirclesButton.ValueChangedFcn = createCallbackFcn(app, @CirclesButtonValueChanged, true);
            app.CirclesButton.Text = 'Circles';
            app.CirclesButton.Value = true;
            app.CCFLamp = uilamp(app.OverlaysPanel);
            app.CCFButton = uibutton(app.OverlaysPanel, 'state');
            app.CCFButton.ValueChangedFcn = createCallbackFcn(app, @CCFButtonValueChanged, true);
            app.CCFButton.Text = 'CCF';
            app.CCFButton.Value = true;
            app.GridLamp = uilamp(app.OverlaysPanel);
            app.GridButton = uibutton(app.OverlaysPanel, 'state');
            app.GridButton.ValueChangedFcn = createCallbackFcn(app, @GridButtonValueChanged, true);
            app.GridButton.Text = 'Grid';
            app.GridButton.Value = true;
            app.GridLineThicknessSpinnerLabel = uilabel(app.OverlaysPanel);
            app.GridLineThicknessSpinnerLabel.Text = 'Grid Line Thickness';
            app.GridLineThicknessSpinner = uispinner(app.OverlaysPanel);
            app.GridLineThicknessSpinner.Limits = [1 Inf];
            app.GridLineThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @GridLineThicknessSpinnerValueChanged, true);
            app.GridLineThicknessSpinner.Value = 5;
            app.GridRectanglesSpinnerLabel = uilabel(app.OverlaysPanel);
            app.GridRectanglesSpinnerLabel.Text = 'Grid Rectangles';
            app.GridRectanglesSpinner = uispinner(app.OverlaysPanel);
            app.GridRectanglesSpinner.Value = 10;
            app.CCFOpacitySliderLabel = uilabel(app.OverlaysPanel);
            app.CCFOpacitySliderLabel.Text = 'CCF Opacity';
            app.CCFOpacitySlider = uislider(app.OverlaysPanel);
            app.CCFOpacitySlider.MajorTicks = [0 100];
            app.CCFOpacitySlider.MajorTickLabels = {''};
            app.CCFOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @CCFOpacitySliderValueChanged, true);
            app.CCFOpacitySlider.Value = 50;
            app.GridOpacityLabel = uilabel(app.OverlaysPanel);
            app.GridOpacityLabel.Text = 'Grid Opacity';
            app.GridOpacitySlider = uislider(app.OverlaysPanel);
            app.GridOpacitySlider.MajorTicks = [0 100];
            app.GridOpacitySlider.MajorTickLabels = {''};
            app.GridOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @GridOpacitySliderValueChanged, true);
            app.GridOpacitySlider.Value = 50;
            app.RestoreGridButton = uibutton(app.OverlaysPanel, 'push');
            app.RestoreGridButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreGridButtonPushed, true);
            app.RestoreGridButton.Text = 'Restore Grid';
            app.CirclesTable = uitable(app.GridLayout);
            app.CirclesTable.ColumnName = {'Mask ID'};
            app.CirclesTable.RowName = {};
            app.CirclesTable.SelectionType = 'row';
            app.CirclesTable.ClickedFcn = createCallbackFcn(app, @CirclesTableClicked, true);
            app.PreloadRGBZstackButton = uibutton(app.GridLayout, 'push');
            app.PreloadRGBZstackButton.Text = 'Preload RGB z-stack';
            app.zSlider = uislider(app.GridLayout);
            app.zSlider.Limits = [1 100];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.Value = 75.2322384673338;
            app.zPlaneLabel = uilabel(app.GridLayout);
            app.zPlaneLabel.Text = '1';
            app.zSliderLabel = uilabel(app.GridLayout);
            app.zSliderLabel.Text = 'Z-plane:';
            app.ChansPanel = uipanel(app.GridLayout);
            app.ChansPanel.Title = 'Channels';
            app.ImPanel = uipanel(app.GridLayout);
            app.ImPanel.Title = 'Image';
            app.LabelImsAxes = uiaxes(app.ImPanel);
            app.LabelImsAxes.XTick = [];
            app.LabelImsAxes.YTick = [];
            app.SaveResultsButton = uibutton(app.GridLayout, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.DrawMasksTab = uitab(app.TabGroup);
            app.DrawMasksTab.Title = 'Draw Masks';
            app.GridLayout2 = uigridlayout(app.DrawMasksTab);
            app.Round1ImagePanel = uipanel(app.GridLayout2);
            app.Round1ImagePanel.Title = 'Round 1 Image';
            app.r1ImAxes = uiaxes(app.Round1ImagePanel);
            app.r1ImAxes.XTick = [];
            app.r1ImAxes.YTick = [];
            app.r1ImAxes.Tag = 'r1ImAxes';
            app.Round0ImagePanel = uipanel(app.GridLayout2);
            app.Round0ImagePanel.Title = 'Round 0 Image';
            app.r0ImAxes = uiaxes(app.Round0ImagePanel);
            app.r0ImAxes.XTick = [];
            app.r0ImAxes.YTick = [];
            app.r0ImAxes.Tag = 'r0ImAxes';
            app.xCorrLabel = uilabel(app.Round0ImagePanel);
            app.xCorrLabel.Text = 'Maximum cross-correlation: ';
            app.MasksTable = uitable(app.GridLayout2);
            app.MasksTable.ColumnName = {'Mask ID'; 'Mask Created'; 'Assessed'};
            app.MasksTable.RowName = {};
            app.MasksTable.SelectionType = 'row';
            app.MasksTable.SelectionChangedFcn = createCallbackFcn(app, @MasksTableSelectionChanged, true);
            app.ControlsPanel2 = uipanel(app.GridLayout2);
            app.ControlsPanel2.Title = 'Controls';
            app.SegmentationiterationsEditFieldLabel = uilabel(app.ControlsPanel2);
            app.SegmentationiterationsEditFieldLabel.Text = 'Segmentation iterations';
            app.SegmentationIterationsEditField = uieditfield(app.ControlsPanel2, 'numeric');
            app.SegmentationIterationsEditField.Limits = [1 Inf];
            app.SegmentationIterationsEditField.RoundFractionalValues = 'on';
            app.SegmentationIterationsEditField.Value = 500;
            app.IncrementZButton2 = uibutton(app.ControlsPanel2, 'push');
            app.IncrementZButton2.ButtonPushedFcn = createCallbackFcn(app, @IncrementZButtonPushed, true);
            app.IncrementZButton2.Text = 'Increment z [c]';
            app.DecrementZButton2 = uibutton(app.ControlsPanel2, 'push');
            app.DecrementZButton2.ButtonPushedFcn = createCallbackFcn(app, @DecrementZButtonPushed, true);
            app.DecrementZButton2.Text = 'Decrement z [z]';
            app.SnapMasksCheckBox = uicheckbox(app.ControlsPanel2);
            app.SnapMasksCheckBox.Text = 'Snap masks with snakes?';
            app.SnapMasksCheckBox.Value = true;
            app.MethodDropDownLabel = uilabel(app.ControlsPanel2);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.ControlsPanel2);
            app.MethodDropDown.Items = {'Chan-Vese', 'edge'};
            app.MethodDropDown.Value = 'Chan-Vese';
            app.SmoothFactorEditFieldLabel = uilabel(app.ControlsPanel2);
            app.SmoothFactorEditFieldLabel.Text = 'SmoothFactor';
            app.SmoothFactorEditField = uieditfield(app.ControlsPanel2, 'numeric');
            app.SmoothFactorEditField.Limits = [0 Inf];
            app.SmoothFactorEditField.Value = 8;
            app.ContractionBiasEditFieldLabel = uilabel(app.ControlsPanel2);
            app.ContractionBiasEditFieldLabel.Text = 'ContractionBias';
            app.ContractionBiasEditField = uieditfield(app.ControlsPanel2, 'numeric');
            app.ContractionBiasEditField.Limits = [-1 1];
            app.ContractionBiasEditField.Value = -0.5;
            app.zPlaneLabelR1 = uilabel(app.GridLayout2);
            app.zPlaneLabelR1.Text = '1';
            app.zSliderLabelR1 = uilabel(app.GridLayout2);
            app.zSliderLabelR1.Text = 'Z-plane:';
            app.zSliderR1 = uislider(app.GridLayout2);
            app.zSliderR1.Limits = [1 100];
            app.zSliderR1.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSliderR1.Value = 75.2322384673338;
            app.OverlaysPanel_2 = uipanel(app.GridLayout2);
            app.OverlaysPanel_2.Title = 'Overlays';
            app.CircleLamp = uilamp(app.OverlaysPanel_2);
            app.CircleButton = uibutton(app.OverlaysPanel_2, 'state');
            app.CircleButton.ValueChangedFcn = createCallbackFcn(app, @CircleButtonValueChanged, true);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.MasksLamp = uilamp(app.OverlaysPanel_2);
            app.MasksButton = uibutton(app.OverlaysPanel_2, 'state');
            app.MasksButton.Text = 'Masks';
            app.MasksButton.Value = true;
            app.MasksOpacitySliderLabel = uilabel(app.OverlaysPanel_2);
            app.MasksOpacitySliderLabel.Text = 'Masks Opacity';
            app.MasksOpacitySlider = uislider(app.OverlaysPanel_2);
            app.MasksOpacitySlider.MajorTicks = [0 100];
            app.MasksOpacitySlider.Value = 50;
            app.PassspaceButton = uibutton(app.OverlaysPanel_2, 'push');
            app.PassspaceButton.Text = 'Pass [space]';
            app.NextmaskxButton = uibutton(app.OverlaysPanel_2, 'push');
            app.NextmaskxButton.Text = 'Next mask [x]';
            app.RebalanceR1ImButton = uibutton(app.OverlaysPanel_2, 'push');
            app.RebalanceR1ImButton.ButtonPushedFcn = createCallbackFcn(app, @RebalanceR1ImButtonPushed, true);
            app.RebalanceR1ImButton.Text = 'Rebalance r1 Im [ctrl + z]';
            app.RebalanceR0ImButton = uibutton(app.OverlaysPanel_2, 'push');
            app.RebalanceR0ImButton.ButtonPushedFcn = createCallbackFcn(app, @RebalanceR0ImButtonPushed, true);
            app.RebalanceR0ImButton.Text = 'Rebalance r0 Im [ctrl + c]';
            app.CrossCorrelationSearchPanel = uipanel(app.GridLayout2);
            app.CrossCorrelationSearchPanel.Title = 'Cross-Correlation Search';
            app.XYSearchDistanceSpinnerLabel = uilabel(app.CrossCorrelationSearchPanel);
            app.XYSearchDistanceSpinnerLabel.Text = 'XY Search Distance';
            app.XYSearchDistanceSpinner = uispinner(app.CrossCorrelationSearchPanel);
            app.XYSearchDistanceSpinner.Limits = [1 Inf];
            app.XYSearchDistanceSpinner.RoundFractionalValues = 'on';
            app.XYSearchDistanceSpinner.ValueChangedFcn = createCallbackFcn(app, @XYSearchDistanceSpinnerValueChanged, true);
            app.XYSearchDistanceSpinner.Value = 200;
            app.ZSearchDistanceSpinnerLabel = uilabel(app.CrossCorrelationSearchPanel);
            app.ZSearchDistanceSpinnerLabel.Text = 'Z Search Distance';
            app.ZSearchDistanceSpinner = uispinner(app.CrossCorrelationSearchPanel);
            app.ZSearchDistanceSpinner.Limits = [1 Inf];
            app.ZSearchDistanceSpinner.RoundFractionalValues = 'on';
            app.ZSearchDistanceSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSearchDistanceSpinnerValueChanged, true);
            app.ZSearchDistanceSpinner.Value = 5;
            app.ROIRadiusSpinnerLabel = uilabel(app.CrossCorrelationSearchPanel);
            app.ROIRadiusSpinnerLabel.Text = 'ROI Radius';
            app.ROIRadiusSpinner = uispinner(app.CrossCorrelationSearchPanel);
            app.ROIRadiusSpinner.Step = 10;
            app.ROIRadiusSpinner.Limits = [10 Inf];
            app.ROIRadiusSpinner.RoundFractionalValues = 'on';
            app.ROIRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIRadiusSpinnerValueChanged, true);
            app.ROIRadiusSpinner.Value = 50;
            app.EnableCrossCorSearchCheckBox = uicheckbox(app.CrossCorrelationSearchPanel);
            app.EnableCrossCorSearchCheckBox.Text = 'Enable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_masks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end