classdef mask_assessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        EXPORTFILTEREDMASKSButton  matlab.ui.control.Button
        UNDOVButton                matlab.ui.control.Button
        SortbyZplanedescButton     matlab.ui.control.Button
        SortbyZspandescButton      matlab.ui.control.Button
        SortbyZspanascButton       matlab.ui.control.Button
        SortbyZplaneascButton      matlab.ui.control.Button
        MasksAssessedGauge         matlab.ui.control.LinearGauge
        MasksAssessedLabel         matlab.ui.control.Label
        REJECTMASKZButton          matlab.ui.control.Button
        LoadResultsButton          matlab.ui.control.Button
        SaveResultsButton          matlab.ui.control.Button
        ACCEPTMASKCButton          matlab.ui.control.Button
        MasksTable                 matlab.ui.control.Table
        SettingsPanel              matlab.ui.container.Panel
        ImagesperrowSpinner        matlab.ui.control.Spinner
        ImagesperrowSpinnerLabel   matlab.ui.control.Label
        UDLabel                    matlab.ui.control.Label
        LRLabel                    matlab.ui.control.Label
        MuteButton                 matlab.ui.control.Button
        AudioVolumeSlider          matlab.ui.control.Slider
        AudiovolumeLabel           matlab.ui.control.Label
        ZPadPlanesSpinner          matlab.ui.control.Spinner
        ZPadPlanesLabel            matlab.ui.control.Label
        XYPadPixelsSpinner         matlab.ui.control.Spinner
        XYPadPixelsLabel           matlab.ui.control.Label
        MaskOpacitySlider          matlab.ui.control.Slider
        MasksOpacityLabel          matlab.ui.control.Label
        MASKSONXButton             matlab.ui.control.Button
        ImageParamsPanel           matlab.ui.container.Panel
        AutoSetLimitsCheckBox      matlab.ui.control.CheckBox
        MaxEditField               matlab.ui.control.NumericEditField
        MaxEditFieldLabel          matlab.ui.control.Label
        MinEditField               matlab.ui.control.NumericEditField
        MinEditFieldLabel          matlab.ui.control.Label
        ROIPanel                   matlab.ui.container.Panel
        ROIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im_f
        im
        masks_im
        masks_im_orig
        masks_path
        masks_im_info
        uniq_id
        masks
        num_masks
        masks_stats
        im_size

        parent_app
        
        ding_y
        ding_y_scaled
        ding_Fs
        buzz_y
        buzz_y_scaled
        buzz_Fs
        rewind_y
        rewind_y_scaled
        rewind_Fs
        
        num_per_row = 4
        current_mask = 1
        previous_masks = [1]
        show_masks = true
        mask_opacity = 0.5
        xypad = 20
        zpad = 2

        ROI
        ROI_im

        ROI_masks
        ROI_masks_alpha
        ROI_masks_im
    end
    
    methods (Access = private)
        
        function extract_ROI(app)
            
            bbox = num2cell(app.masks_stats{string(app.current_mask),"BoundingBox"});
            [ulf_x, ulf_y, ulf_z, width_x, width_y, width_z] = bbox{:};

            min1 = ceil(ulf_y) - app.xypad;
            min2 = ceil(ulf_x) - app.xypad;
            min3 = ceil(ulf_z) - app.zpad;

            max1 = floor(ulf_y + width_y) + app.xypad;
            max2 = floor(ulf_x + width_x) + app.xypad;
            max3 = floor(ulf_z + width_z) + app.zpad;
            
            % fill every z slot you can
            num_z = max3 - min3 + 1;
            remainder_z = mod(num_z,app.num_per_row);
            if num_z < app.num_per_row
                max3 = max3 + app.num_per_row - num_z;
            elseif remainder_z ~= 0
                max3 = max3 + app.num_per_row - remainder_z;
            end
            
            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1, min3 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.im_size(1), max2 > app.im_size(2), max3 > app.im_size(3)])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    vol = app.im(ind1, ind2, ind3);
                    vol_masks = app.masks_im(ind1, ind2, ind3);

                case true

                    prepad1 = max(1-min1,0);
                    postpad1 = max(max1-app.im_size(1),0);
                    prepad2 = max(1-min2,0);
                    postpad2 = max(max2-app.im_size(2),0);
                    prepad3 = max(1-min3,0);
                    postpad3 = max(max3-app.im_size(3),0);

                    min1 = min1 + prepad1;
                    max1 = max1 - postpad1;
                    min2 = min2 + prepad2;
                    max2 = max2 - postpad2;
                    min3 = min3 + prepad3;
                    max3 = max3 - postpad3;

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    
                    vol = app.im(ind1, ind2, ind3);
                    vol = padarray(vol,[prepad1 prepad2 prepad3],"pre");
                    vol = padarray(vol,[postpad1 postpad2 postpad3],"post");

                    if (prepad3 ~= 0) || (postpad3 ~= 0)

                        out_of_bounds_im = zeros(size(vol,1),size(vol,2));
                        out_of_bounds_im = insertShape(out_of_bounds_im,"line", ...
                            [0 0 size(vol,2) size(vol,1); size(vol,2) 0 0 size(vol,1)], ...
                            ShapeColor='red');
                        out_of_bounds_im = out_of_bounds_im(:,:,1);
                        out_of_bounds_im = rescale(out_of_bounds_im,0,max(vol,[],"all"));
                        out_of_bounds_im = uint16(out_of_bounds_im);

                        if prepad3 ~= 0
                            vol(:,:,1:prepad3) = repmat(out_of_bounds_im,1,1,prepad3);
                        end

                        if postpad3 ~= 0
                            vol(:,:,end-postpad3+1:end) = repmat(out_of_bounds_im,1,1,postpad3);
                        end
                        
                    end

                    vol_masks = app.masks_im(ind1, ind2, ind3);
                    vol_masks = padarray(vol_masks,[prepad1 prepad2 prepad3],"pre");
                    vol_masks = padarray(vol_masks,[postpad1 postpad2 postpad3],"post");

            end
            
            app.ROI = imtile(vol,BorderSize=[1 1],GridSize=[NaN app.num_per_row]);

            local_masks = unique(vol_masks);
            max_mask = max(local_masks);
            map = repmat([0 0 1],max_mask+1,1);
            map = vertcat(map,[1 0.5 0]);
            map(app.current_mask+1,:) = [1 0 0];
            map(1,:) = [0 0 0];
            pp_mask = zeros(size(vol_masks),'logical');
            pp_mask(:,:,app.zpad + app.MasksTable.Data.("Principal plane")(app.MasksTable.Selection)) = 1;
            pp_mask = pp_mask & (vol_masks == app.current_mask);
            vol_masks(pp_mask) = max_mask + 1;

            app.ROI_masks = imtile(vol_masks,map,BackgroundColor="black",BorderSize=[1 1],GridSize=[NaN app.num_per_row]);
            grayTile = rgb2gray(app.ROI_masks);
            app.ROI_masks_alpha = (grayTile > 0);

        end

        function [vol,vol_masks] = extract_ROI_pp(app)
            
            bbox = num2cell(app.masks_stats{string(app.current_mask),"BoundingBox"});
            [ulf_x, ulf_y, ulf_z, width_x, width_y, width_z] = bbox{:};

            min1 = ceil(ulf_y);
            min2 = ceil(ulf_x);
            min3 = ceil(ulf_z);

            max1 = floor(ulf_y + width_y);
            max2 = floor(ulf_x + width_x); 
            max3 = floor(ulf_z + width_z);
            
            ind1 = min1:max1;
            ind2 = min2:max2;
            ind3 = min3:max3;
            vol = app.im(ind1, ind2, ind3);
            vol_masks = app.masks_im(ind1, ind2, ind3);

        end

        function initialize_ROI(app)

            app.ROI_im = imshow(app.ROI,Parent=app.ROIAxes);
            app.autoSetLims(true)
            app.ROI_masks_im = imshow(app.ROI_masks,Parent=app.ROIAxes);
            app.ROI_masks_im.AlphaData = app.ROI_masks_alpha * app.mask_opacity;

        end

        function update_ROI(app)

            app.ROI_im.CData = app.ROI;
            app.autoSetLims(true)
            app.ROI_masks_im.CData = app.ROI_masks;
            app.ROI_masks_im.AlphaData = app.ROI_masks_alpha * app.mask_opacity;

        end

        function autoSetLims(app, value)

            switch value

                case true

                    app.MaxEditField.Editable = false;
                    app.MinEditField.Editable = false;

                    P = prctile(app.ROI,[0.1, 99.9],"all");
                    app.MaxEditField.Value = double(P(2));
                    app.MinEditField.Value = double(P(1));
                    app.ROIAxes.CLim = P;
                    
                case false

                    app.MaxEditField.Editable = true;
                    app.MinEditField.Editable = true;

            end

        end

        % set current mask to next mask in the table that has not been
        % assessed
        function set_next_mask(app)
            
            nextRow = find(~app.MasksTable.Data.("Assessed"),1,"first");

            if ~isempty(nextRow)
                app.MasksTable.Selection = nextRow;
                app.current_mask = app.MasksTable.Data.("Mask ID")(app.MasksTable.Selection);
                app.extract_ROI()
                app.update_ROI()
            end
            
        end

        function update_progress(app)
            
            num_assessed = sum(app.MasksTable.Data.('Assessed'));
            frac = num_assessed / app.num_masks;
            perc = round(frac,2) * 100;
            perc = uint8(perc);

            app.MasksAssessedGauge.Value = perc;
            if perc == 100
                app.MasksAssessedGauge.ScaleColors = [0 1 0];
                app.MasksAssessedGauge.ScaleColorLimits(1,2) = 100;
            else
                app.MasksAssessedGauge.ScaleColors = [0 1 0; 1 0 0];
                app.MasksAssessedGauge.ScaleColorLimits(1,2) = max(perc,1);
                app.MasksAssessedGauge.ScaleColorLimits(2,1) = max(perc,1);
            end
            app.MasksAssessedLabel.Text = sprintf('Masks assessed (%u/%u) %u%%',num_assessed,app.num_masks,perc);

        end

        function sort_by_zspan(app, direction)
            masks_table = app.MasksTable.Data;
            masks_table = sortrows(masks_table,'Z-span',direction);
            app.MasksTable.Data = masks_table;
            app.set_next_mask()
        end

        function sort_by_zplane(app, direction)
            masks_table = app.MasksTable.Data;
            masks_table = sortrows(masks_table,'Z-plane',direction);
            app.MasksTable.Data = masks_table;
            app.set_next_mask()
        end

        function undo_mask(app)

            % undo the masks table
            oldRow = find(app.MasksTable.Data.("Mask ID") == app.previous_masks(end));
            app.MasksTable.Data.("Assessed")(oldRow) = false;
            app.MasksTable.Selection = oldRow;

            % restore the mask in the masks image
            app.masks_im(app.masks_im_orig == app.previous_masks(end)) = app.previous_masks(end);

            app.current_mask = app.previous_masks(end);
            app.extract_ROI()
            app.update_ROI()

            app.previous_masks(end) = [];
            if isempty(app.previous_masks)
                app.previous_masks = [app.current_mask];
            end

        end

        function principal_plane = find_principal_plane(app)

            [vol,vol_mask] = app.extract_ROI_pp();

            num_planes = size(vol,3);

            if num_planes == 1
                principal_plane = 1;
                return
            end

            binary_mask = (vol_mask == app.current_mask);
            plane_means = zeros(num_planes,1);
            plane_sizes = zeros(num_planes,1);

            for i=1:num_planes
                ROI_plane = vol(:,:,i);
                binary_mask_plane = binary_mask(:,:,i);
                plane_means(i) = mean(ROI_plane(binary_mask_plane));
                plane_sizes(i) = sum(binary_mask_plane,"all");
            end

            plane_means = normalize(plane_means,"zscore");
            plane_sizes = normalize(plane_sizes,"zscore");
            scores = mean(horzcat(plane_means,plane_sizes),2);
            [~,I] = max(scores);

            principal_plane = I;

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im_f, masks_im, parent_app)
            
            d = uiprogressdlg(app.UIFigure, ...
                'Message','Loading...', ...
                'Title','Loading...', ...
                'Indeterminate','on');

            app.parent_app = parent_app;

            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            addpath(['./' 'assets'])

            [app.ding_y,app.ding_Fs] = audioread('ding-101492.mp3');
            app.ding_y = app.ding_y(2000:115000);
            app.ding_y_scaled = app.ding_y * (app.AudioVolumeSlider.Value/100);
            [app.buzz_y,app.buzz_Fs] = audioread('wrong-47985.mp3');
            app.buzz_y = app.buzz_y(700:9000) * 0.6;
            app.buzz_y_scaled = app.buzz_y * (app.AudioVolumeSlider.Value/100);
            [app.rewind_y,app.rewind_Fs] = audioread('old-school-hip-hop-rewind-effect-440587.mp3');
            app.rewind_y = app.rewind_y(500:21000) * 0.5;
            app.rewind_y_scaled = app.rewind_y * (app.AudioVolumeSlider.Value/100);

            movegui(app.UIFigure,'north')
            app.UIFigure.WindowState = 'maximized';
            
            axis(app.ROIAxes,"off")
            axis(app.ROIAxes,"image")
            hold(app.ROIAxes,"on")
            
            app.im_f = im_f;
            app.im = read_tiff(app.im_f);
            app.im_size = size(app.im);

            [app.masks_path,~,~] = fileparts(masks_im);
            [app.masks_im, app.masks_im_info] = read_tiff(masks_im);
            app.masks_im_orig = app.masks_im;
            app.masks = unique(app.masks_im);
            app.masks(1) = [];
            app.num_masks = length(app.masks);

            app.masks_stats = regionprops3(app.masks_im,{"BoundingBox","Centroid"});
            app.masks_stats(any(ismissing(app.masks_stats),2),:) = []; % remove NaN rows
            app.masks_stats.Properties.RowNames = string(app.masks);
            
            [~,name,~] = fileparts(app.masks_path);
            app.uniq_id = name;

            if isfile(fullfile(app.masks_path,[app.uniq_id '_masks_QC.mat']))

                app.LoadResultsButtonPushed()

            else

                % find principal planes of each ROI
                principal_planes = zeros(app.num_masks,1,'uint8');
                for i=1:app.num_masks
                    app.current_mask = app.masks(i);
                    principal_planes(i) = app.find_principal_plane();
                end

                masks_table = table;
                assessed = zeros(app.num_masks,1,'logical');
                results = repelem({'null'},app.num_masks,1);
                masks_table = addvars(masks_table,app.masks,assessed,results,'NewVariableNames',{'Mask ID','Assessed','Result'});
                masks_table = addvars(masks_table,ceil(app.masks_stats.BoundingBox(:,3)),'Before','Assessed','NewVariableNames',{'Z-plane'});
                masks_table = addvars(masks_table,app.masks_stats.BoundingBox(:,6),'Before','Assessed','NewVariableNames',{'Z-span'});
                masks_table = addvars(masks_table,principal_planes,'Before','Assessed','NewVariableNames',{'Principal plane'});
                app.MasksTable.Data = masks_table;
                app.SaveResultsButtonPushed()

            end

            app.current_mask = app.masks(1);
            app.MasksTable.Selection = 1;
            app.extract_ROI()
            app.initialize_ROI()
            app.update_progress()
            app.sort_by_zspan('ascend')

            close(d)
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
           
            key = event.Key;
            
            switch key
                case 'z'
                    app.REJECTMASKZButtonPushed()
                case 'x'
                    app.MASKSONXButtonPushed()
                case 'c'
                    app.ACCEPTMASKCButtonPushed()
                case 'v'
                    app.UNDOVButtonPushed()
                case 'uparrow'
                    app.ZPadPlanesSpinner.Value = app.ZPadPlanesSpinner.Value + 1;
                    app.ZPadPlanesSpinnerValueChanged()
                case 'downarrow'
                    if app.ZPadPlanesSpinner.Value > 0
                        app.ZPadPlanesSpinner.Value = app.ZPadPlanesSpinner.Value - 1;
                        app.ZPadPlanesSpinnerValueChanged()
                    end
                case 'leftarrow'
                    app.XYPadPixelsSpinner.Value = app.XYPadPixelsSpinner.Value + 1;
                    app.XYPadPixelsSpinnerValueChanged()
                case 'rightarrow'
                    if app.XYPadPixelsSpinner.Value > 0
                        app.XYPadPixelsSpinner.Value = app.XYPadPixelsSpinner.Value - 1;
                        app.XYPadPixelsSpinnerValueChanged()
                    end
            end
            
        end

        % Button pushed function: REJECTMASKZButton
        function REJECTMASKZButtonPushed(app, event)
            
            sound(app.buzz_y_scaled,app.buzz_Fs)
            app.MasksTable.Data.("Assessed")(app.MasksTable.Selection) = true;
            app.MasksTable.Data.("Result"){app.MasksTable.Selection} = 'bad';
            app.previous_masks = [app.previous_masks app.current_mask];

            mask_to_delete = app.current_mask;
            app.masks_im(app.masks_im == mask_to_delete) = 0;

            app.set_next_mask()
            scroll(app.MasksTable,"row",app.MasksTable.Selection)
            app.update_progress()

        end

        % Button pushed function: MASKSONXButton
        function MASKSONXButtonPushed(app, event)
            app.show_masks = ~app.show_masks;
            app.ROI_masks_im.Visible = app.show_masks;
        end

        % Button pushed function: ACCEPTMASKCButton
        function ACCEPTMASKCButtonPushed(app, event)
            sound(app.ding_y_scaled,app.ding_Fs)
            app.MasksTable.Data.("Assessed")(app.MasksTable.Selection) = true;
            app.MasksTable.Data.("Result"){app.MasksTable.Selection} = 'good';
            app.previous_masks = [app.previous_masks app.current_mask];
            app.set_next_mask()
            scroll(app.MasksTable,"row",app.MasksTable.Selection)
            app.update_progress()
        end

        % Value changed function: AutoSetLimitsCheckBox
        function AutoSetLimitsCheckBoxValueChanged(app, event)
            value = app.AutoSetLimitsCheckBox.Value;
            app.autoSetLims(value)
        end

        % Value changed function: MaskOpacitySlider
        function MaskOpacitySliderValueChanged(app, event)
            app.mask_opacity = app.MaskOpacitySlider.Value;
            app.update_ROI()
        end

        % Value changed function: XYPadPixelsSpinner
        function XYPadPixelsSpinnerValueChanged(app, event)
            app.xypad = app.XYPadPixelsSpinner.Value;
            app.extract_ROI()
            app.update_ROI()
        end

        % Value changed function: ZPadPlanesSpinner
        function ZPadPlanesSpinnerValueChanged(app, event)
            app.zpad = app.ZPadPlanesSpinner.Value;
            app.extract_ROI()
            app.update_ROI()
        end

        % Selection changed function: MasksTable
        function MasksTableSelectionChanged(app, event)
            selection = app.MasksTable.Selection;
            app.current_mask = app.MasksTable.Data.("Mask ID")(selection);
            app.extract_ROI()
            app.update_ROI()
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            f_name = fullfile(app.masks_path,[app.uniq_id '_masks_QC.mat']);
            masks_table = app.MasksTable.Data;
            save(f_name,"masks_table")
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            
            f_name = fullfile(app.masks_path,[app.uniq_id '_masks_QC.mat']);
            load(f_name,"masks_table")
            app.MasksTable.Data = masks_table;

            bad_masks = strcmp(app.MasksTable.Data.("Result"),"bad");
            masks_to_delete = app.MasksTable.Data.("Mask ID")(bad_masks);
            Lia = ismember(app.masks_im,masks_to_delete);
            app.masks_im(Lia) = 0;

        end

        % Value changed function: AudioVolumeSlider
        function AudioVolumeSliderValueChanged(app, event)
            app.ding_y_scaled = app.ding_y * (app.AudioVolumeSlider.Value/100);
            app.buzz_y_scaled = app.buzz_y * (app.AudioVolumeSlider.Value/100);
            app.rewind_y_scaled = app.rewind_y * (app.AudioVolumeSlider.Value/100);
        end

        % Button pushed function: MuteButton
        function MuteButtonPushed(app, event)
            app.AudioVolumeSlider.Value = 0;
            app.AudioVolumeSliderValueChanged()
        end

        % Button pushed function: SortbyZspanascButton
        function SortbyZspanascButtonPushed(app, event)
            app.sort_by_zspan('ascend')
        end

        % Button pushed function: SortbyZspandescButton
        function SortbyZspandescButtonPushed(app, event)
            app.sort_by_zspan('descend')
        end

        % Button pushed function: SortbyZplaneascButton
        function SortbyZplaneascButtonPushed(app, event)
            app.sort_by_zplane('ascend')
        end

        % Button pushed function: SortbyZplanedescButton
        function SortbyZplanedescButtonPushed(app, event)
            app.sort_by_zplane('descend')
        end

        % Value changed function: ImagesperrowSpinner
        function ImagesperrowSpinnerValueChanged(app, event)
            value = app.ImagesperrowSpinner.Value;
            app.num_per_row = value;
            app.extract_ROI()
            app.update_ROI()
        end

        % Button pushed function: UNDOVButton
        function UNDOVButtonPushed(app, event)
            sound(app.rewind_y_scaled,app.rewind_Fs)
            app.undo_mask()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            fig = app.UIFigure;
            txt_prompt = "Would you like to save results before closing?";
            choice = uiconfirm(fig,txt_prompt, ...
                'Closing app','Icon','question', ...
                "Options",["Yes","No"], ...
                "DefaultOption",1, ...
                "CancelOption",2);

            switch choice
                case 'Yes'
                    app.SaveResultsButtonPushed()
                case 'No'
                    % do nothing
            end
            
            delete(app)
            
        end

        % Button pushed function: EXPORTFILTEREDMASKSButton
        function EXPORTFILTEREDMASKSButtonPushed(app, event)
            
            good_masks_bool = strcmp(app.MasksTable.Data.Result,'good');
            good_masks = app.MasksTable.Data.("Mask ID")(good_masks_bool);
            keep_pixels = ismember(app.masks_im,good_masks);
            new_masks_im = app.masks_im;
            new_masks_im(~keep_pixels) = 0;
            
            path = fullfile(app.masks_path,[app.uniq_id '_masks_qc.tif']);
            t = Tiff(path, 'w8');
        
            sz = size(new_masks_im);
        
            tagstruct.ImageLength = sz(1);
            tagstruct.ImageWidth = sz(2);
            tagstruct.SampleFormat = Tiff.SampleFormat.UInt; % uint
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.BitsPerSample = 16;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            if isfield(app.masks_im_info,'ImageDescription')
                tagstruct.ImageDescription = app.masks_im_info.ImageDescription;
            end
            tagstruct.ExtraSamples = Tiff.ExtraSamples.Unspecified;
        
            for ii=1:sz(3)
                plane = new_masks_im(:,:,ii);
                setTag(t,tagstruct);
                write(t,uint16(plane));
                writeDirectory(t);
            end
            close(t)

            app.parent_app.finish_mask_qc(app.uniq_id)
            app.UIFigureCloseRequest()

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ROIPanel = uipanel(app.GridLayout);
            app.ROIPanel.Title = 'ROI';
            app.ROIAxes = uiaxes(app.ROIPanel);
            app.ROIAxes.XTick = [];
            app.ROIAxes.YTick = [];
            app.ImageParamsPanel = uipanel(app.GridLayout);
            app.ImageParamsPanel.Title = 'Sample Image Display Parameters';
            app.MinEditFieldLabel = uilabel(app.ImageParamsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ImageParamsPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.ImageParamsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ImageParamsPanel, 'numeric');
            app.AutoSetLimitsCheckBox = uicheckbox(app.ImageParamsPanel);
            app.AutoSetLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSetLimitsCheckBoxValueChanged, true);
            app.AutoSetLimitsCheckBox.Text = 'Auto set limits';
            app.AutoSetLimitsCheckBox.Value = true;
            app.MASKSONXButton = uibutton(app.GridLayout, 'push');
            app.MASKSONXButton.ButtonPushedFcn = createCallbackFcn(app, @MASKSONXButtonPushed, true);
            app.MASKSONXButton.Text = 'MASKS ON [X]';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.MasksOpacityLabel = uilabel(app.SettingsPanel);
            app.MasksOpacityLabel.Text = {'Masks'; 'Opacity'};
            app.MaskOpacitySlider = uislider(app.SettingsPanel);
            app.MaskOpacitySlider.Limits = [0 1];
            app.MaskOpacitySlider.MajorTicks = [0 1];
            app.MaskOpacitySlider.MajorTickLabels = {'0', '1'};
            app.MaskOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @MaskOpacitySliderValueChanged, true);
            app.MaskOpacitySlider.MinorTicks = [0 0.25 0.5 0.75 1];
            app.MaskOpacitySlider.Value = 0.5;
            app.XYPadPixelsLabel = uilabel(app.SettingsPanel);
            app.XYPadPixelsLabel.Text = 'XY Pad Pixels';
            app.XYPadPixelsSpinner = uispinner(app.SettingsPanel);
            app.XYPadPixelsSpinner.Limits = [0 Inf];
            app.XYPadPixelsSpinner.ValueChangedFcn = createCallbackFcn(app, @XYPadPixelsSpinnerValueChanged, true);
            app.XYPadPixelsSpinner.Value = 20;
            app.ZPadPlanesLabel = uilabel(app.SettingsPanel);
            app.ZPadPlanesLabel.Text = 'Z Pad Planes';
            app.ZPadPlanesSpinner = uispinner(app.SettingsPanel);
            app.ZPadPlanesSpinner.Limits = [0 Inf];
            app.ZPadPlanesSpinner.ValueChangedFcn = createCallbackFcn(app, @ZPadPlanesSpinnerValueChanged, true);
            app.ZPadPlanesSpinner.Value = 2;
            app.AudiovolumeLabel = uilabel(app.SettingsPanel);
            app.AudiovolumeLabel.Text = {'Audio'; 'volume'};
            app.AudioVolumeSlider = uislider(app.SettingsPanel);
            app.AudioVolumeSlider.MajorTicks = [0 100];
            app.AudioVolumeSlider.ValueChangedFcn = createCallbackFcn(app, @AudioVolumeSliderValueChanged, true);
            app.AudioVolumeSlider.MinorTicks = [0 25 50 75 100];
            app.AudioVolumeSlider.Value = 100;
            app.MuteButton = uibutton(app.SettingsPanel, 'push');
            app.MuteButton.ButtonPushedFcn = createCallbackFcn(app, @MuteButtonPushed, true);
            app.MuteButton.Icon = fullfile(pathToMLAPP, 'assets', '54309.png');
            app.MuteButton.Text = '';
            app.LRLabel = uilabel(app.SettingsPanel);
            app.LRLabel.Text = '[⬅️ ➡️]';
            app.UDLabel = uilabel(app.SettingsPanel);
            app.UDLabel.Text = '[⬆️ ⬇️]';
            app.ImagesperrowSpinnerLabel = uilabel(app.SettingsPanel);
            app.ImagesperrowSpinnerLabel.Text = 'Images per row';
            app.ImagesperrowSpinner = uispinner(app.SettingsPanel);
            app.ImagesperrowSpinner.Limits = [1 Inf];
            app.ImagesperrowSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesperrowSpinnerValueChanged, true);
            app.ImagesperrowSpinner.Value = 4;
            app.MasksTable = uitable(app.GridLayout);
            app.MasksTable.ColumnName = {'Mask ID'; 'Z-plane'; 'Z-span'; 'Principal plane'; 'Assessed'; 'Result'};
            app.MasksTable.RowName = {};
            app.MasksTable.SelectionType = 'row';
            app.MasksTable.SelectionChangedFcn = createCallbackFcn(app, @MasksTableSelectionChanged, true);
            app.MasksTable.Multiselect = 'off';
            app.ACCEPTMASKCButton = uibutton(app.GridLayout, 'push');
            app.ACCEPTMASKCButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTMASKCButtonPushed, true);
            app.ACCEPTMASKCButton.Text = 'ACCEPT MASK [C]';
            app.SaveResultsButton = uibutton(app.GridLayout, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save results';
            app.LoadResultsButton = uibutton(app.GridLayout, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = 'Load results';
            app.REJECTMASKZButton = uibutton(app.GridLayout, 'push');
            app.REJECTMASKZButton.ButtonPushedFcn = createCallbackFcn(app, @REJECTMASKZButtonPushed, true);
            app.REJECTMASKZButton.Text = 'REJECT MASK [Z]';
            app.MasksAssessedLabel = uilabel(app.GridLayout);
            app.MasksAssessedLabel.Text = 'Masks assessed';
            app.MasksAssessedGauge = uigauge(app.GridLayout, 'linear');
            app.SortbyZplaneascButton = uibutton(app.GridLayout, 'push');
            app.SortbyZplaneascButton.ButtonPushedFcn = createCallbackFcn(app, @SortbyZplaneascButtonPushed, true);
            app.SortbyZplaneascButton.Text = 'Sort by Z-plane (asc)';
            app.SortbyZspanascButton = uibutton(app.GridLayout, 'push');
            app.SortbyZspanascButton.ButtonPushedFcn = createCallbackFcn(app, @SortbyZspanascButtonPushed, true);
            app.SortbyZspanascButton.Text = 'Sort by Z-span (asc)';
            app.SortbyZspandescButton = uibutton(app.GridLayout, 'push');
            app.SortbyZspandescButton.ButtonPushedFcn = createCallbackFcn(app, @SortbyZspandescButtonPushed, true);
            app.SortbyZspandescButton.Text = 'Sort by Z-span (desc)';
            app.SortbyZplanedescButton = uibutton(app.GridLayout, 'push');
            app.SortbyZplanedescButton.ButtonPushedFcn = createCallbackFcn(app, @SortbyZplanedescButtonPushed, true);
            app.SortbyZplanedescButton.Text = 'Sort by Z-plane (desc)';
            app.UNDOVButton = uibutton(app.GridLayout, 'push');
            app.UNDOVButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOVButtonPushed, true);
            app.UNDOVButton.Text = 'UNDO [V]';
            app.EXPORTFILTEREDMASKSButton = uibutton(app.GridLayout, 'push');
            app.EXPORTFILTEREDMASKSButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTFILTEREDMASKSButtonPushed, true);
            app.EXPORTFILTEREDMASKSButton.Text = {'EXPORT'; 'FILTERED MASKS'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mask_assessor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end