classdef memMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        gcButton          matlab.ui.control.Button
        MATLABmem         matlab.ui.control.Label
        memMonitorSwitch  matlab.ui.control.Switch
        JVMmem            matlab.ui.control.Label
    end

    
    properties (Access = public)
        memTimer % timer that calls memory monitor updates
    end
    
    methods (Access = public)
        
        function update_mem(app,~,~)

            [userview,~] = memory;
            str = sprintf('Memory reserved for MATLAB:\n%.1f GB',userview.MemUsedMATLAB/1e9);
            app.MATLABmem.Text = str;

            r = java.lang.Runtime.getRuntime;
            usedMemory = r.totalMemory - r.freeMemory;
            usedMemory = usedMemory/1e9;
            str = sprintf('Memory used by JVM:\n%.1f/%.1f GB',usedMemory,r.maxMemory/1e9);
            app.JVMmem.Text = str;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            app.UIFigure.Name = 'Memory Monitor';
            
            app.update_mem()

            app.memTimer = timer('Period',1, ...
                'ExecutionMode','fixedSpacing', ...
                'TimerFcn',@app.update_mem);

            start(app.memTimer)
        end

        % Value changed function: memMonitorSwitch
        function memMonitorSwitchValueChanged(app, event)
            value = app.memMonitorSwitch.Value;
            switch value
                case 'On'
                    start(app.memTimer)
                case 'Off'
                    stop(app.memTimer)
            end
        end

        % Button pushed function: gcButton
        function gcButtonPushed(app, event)
            java.lang.Runtime.getRuntime.gc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JVMmem = uilabel(app.UIFigure);
            app.JVMmem.Text = 'Memory used by JVM:';
            app.memMonitorSwitch = uiswitch(app.UIFigure, 'slider');
            app.memMonitorSwitch.ValueChangedFcn = createCallbackFcn(app, @memMonitorSwitchValueChanged, true);
            app.memMonitorSwitch.Value = 'On';
            app.MATLABmem = uilabel(app.UIFigure);
            app.MATLABmem.Text = 'Memory reserved for MATLAB:';
            app.gcButton = uibutton(app.UIFigure, 'push');
            app.gcButton.ButtonPushedFcn = createCallbackFcn(app, @gcButtonPushed, true);
            app.gcButton.Text = 'Run JVM garbage collection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memMonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end