classdef quality_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ControlsPanel                  matlab.ui.container.Panel
        SaveUncuratedButton            matlab.ui.control.Button
        BinChannelImPerRowSpinner      matlab.ui.control.Spinner
        rowsBinaryLabel                matlab.ui.control.Label
        RegChannelImPerRowSpinner      matlab.ui.control.Spinner
        rowsRegistrationChannelsLabel  matlab.ui.control.Label
        LoadModelsButton               matlab.ui.control.Button
        SaveModelsButton               matlab.ui.control.Button
        TrainAllCheckBox               matlab.ui.control.CheckBox
        BinModelsTable                 matlab.ui.control.Table
        LoadResultsButton              matlab.ui.control.Button
        SaveResultsButton              matlab.ui.control.Button
        MessageBoxTextArea             matlab.ui.control.TextArea
        MessageBoxTextAreaLabel        matlab.ui.control.Label
        TrainbinarizationmodelsButton  matlab.ui.control.Button
        ACCEPTCELLCButton              matlab.ui.control.Button
        MASKSONXButton                 matlab.ui.control.Button
        REJECTCELLZButton              matlab.ui.control.Button
        BinaryGenesPanel               matlab.ui.container.Panel
        MANUALMASKLabel                matlab.ui.control.Label
        BinGeneTable                   matlab.ui.control.Table
        BinGeneAxes                    matlab.ui.control.UIAxes
        MasksTable                     matlab.ui.control.Table
        RegistrationChannelsPanel      matlab.ui.container.Panel
        RegChannelTable                matlab.ui.control.Table
        RegChannelAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        masks_tags = {'masks'};
        reg_channels_tags = {'neur','nt'}
        primary_reg_channel_tag = 'r1';
        
        im_dir 
        f_masks_im
        f_reg_channels_ims
        f_primary_reg_channel_im
        f_secondary_reg_channel_ims
        f_bin_channels_ims

        masks_im
        primary_reg_channel_im
        secondary_reg_channel_ims
        bin_channel_ims

        masks
        num_masks
        masks_stats
        current_mask
        % for when you have to cycle through masks behind the scenes, remember which one to return to
        old_mask
        manualMasksTable

        % matlab image objects
        reg_channels_im
        reg_channels_mask_im
        bin_channels_im
        bin_channels_mask_im

        im_size
        num_reg_channel_ims
        num_bin_channel_ims
        r_box
        g_box
        y_box
        previous_masks
        
        xypad = 10
        mask_opacity = 0.5
        show_masks = true
        bin_image_xy = 100

        inds
        pad

        binTiles
        binMaskTiles
        binMaskTilesAlpha
        regTiles
        regMaskTiles
        regMaskTilesAlpha

        num_reg_rows = 2
        num_reg_cols
        num_bin_rows = 2
        num_bin_cols
        
        bin_results
        bin_genes
        bin_models
        bin_layers
        bin_text
        bin_readout_text

        reg_chans
        reg_text
        
    end
    
    methods (Access = private)

        % calculate indices and padding (if needed) to extract the current
        % mask's ROI from an image
        function calc_ROI_ind(app)

            bbox = num2cell(app.masks_stats{string(app.current_mask),"BoundingBox"});
            [ulf_x, ulf_y, ulf_z, width_x, width_y, width_z] = bbox{:};

            min1 = ceil(ulf_y) - app.xypad;
            min2 = ceil(ulf_x) - app.xypad;
            min3 = ceil(ulf_z);

            max1 = floor(ulf_y + width_y) + app.xypad;
            max2 = floor(ulf_x + width_x) + app.xypad;
            max3 = floor(ulf_z + width_z);
            
            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1, min3 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.im_size(1), max2 > app.im_size(2), max3 > app.im_size(3)])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;

                    app.inds = {ind1, ind2, ind3};
                    app.pad = [];

                case true

                    prepad1 = max(1-min1,0);
                    postpad1 = max(max1-app.im_size(1),0);
                    prepad2 = max(1-min2,0);
                    postpad2 = max(max2-app.im_size(2),0);
                    prepad3 = max(1-min3,0);
                    postpad3 = max(max3-app.im_size(3),0);

                    min1 = min1 + prepad1;
                    max1 = max1 - postpad1;
                    min2 = min2 + prepad2;
                    max2 = max2 - postpad2;
                    min3 = min3 + prepad3;
                    max3 = max3 - postpad3;

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;

                    app.inds = {ind1, ind2, ind3};
                    app.pad = [prepad1, prepad2, prepad3, postpad1, postpad2, postpad3];

            end

        end
        
        % extact the current mask's ROI from an image, optionally restrict
        % to a single plane of that ROI, must have run app.calc_ROI_ind()
        % beforehand to obtain the indices and padding needed
        function ROI = extract_ROI(app,im,plane)

            arguments
                app
                im
                plane = []
            end

            [ind1, ind2, ind3] = app.inds{:};
            
            if isempty(app.pad)
                out_of_bounds = false;
            else
                out_of_bounds = true;
                [prepad1, prepad2, prepad3, postpad1, postpad2, postpad3] = deal(app.pad(1),app.pad(2),app.pad(3),app.pad(4),app.pad(5),app.pad(6));
            end

            switch out_of_bounds

                case false

                    ROI = im(ind1, ind2, ind3);

                case true
                    
                    ROI = im(ind1, ind2, ind3);
                    ROI = padarray(ROI,[prepad1 prepad2 prepad3],"pre");
                    ROI = padarray(ROI,[postpad1 postpad2 postpad3],"post");

            end

            if ~isempty(plane)
                ROI = ROI(:,:,plane);
            end
         
        end

        function principal_plane = find_principal_plane(app,ROI,ROI_mask)

            num_planes = size(ROI,3);

            if num_planes == 1
                principal_plane = 1;
                return
            end

            binary_mask = (ROI_mask == app.current_mask);
            plane_means = zeros(num_planes,1);
            plane_sizes = zeros(num_planes,1);

            for i=1:num_planes
                ROI_plane = ROI(:,:,i);
                binary_mask_plane = binary_mask(:,:,i);
                plane_means(i) = mean(ROI_plane(binary_mask_plane));
                plane_sizes(i) = sum(binary_mask_plane,"all");
            end

            plane_means = normalize(plane_means,"zscore");
            plane_sizes = normalize(plane_sizes,"zscore");
            scores = mean(horzcat(plane_means,plane_sizes),2);
            [~,I] = max(scores);

            principal_plane = I;

        end

        function [tiles, ssimval] = make_reg_channels_tiles(app)

            principal_plane = app.masks_stats{string(app.current_mask),"PrincipalPlane"};

            primary_reg_im = app.extract_ROI(app.primary_reg_channel_im,principal_plane);
            primary_reg_im = rescale(primary_reg_im,0,65535);
            
            vol = zeros(size(primary_reg_im,1),size(primary_reg_im,2),app.num_reg_channel_ims);
            ssimval = zeros(app.num_reg_channel_ims,1);
            vol(:,:,1) = primary_reg_im;
            ssimval(1) = 1;

            for i=1:app.num_reg_channel_ims-1
                secondary_reg_im = app.extract_ROI(app.secondary_reg_channel_ims{i},principal_plane);
                secondary_reg_im = rescale(secondary_reg_im,0,65535);
                ssimval(i+1) = ssim(secondary_reg_im,primary_reg_im,'Exponents',[0 1 1]);
                vol(:,:,i+1) = secondary_reg_im;
            end

            tiles = imtile(vol,BorderSize=[1 1], ...
                GridSize=[app.num_reg_rows app.num_reg_cols]);

        end

        function [mask_tiles, mask_tiles_alpha] = make_reg_channels_tiles_mask(app)

            principal_plane = app.masks_stats{string(app.current_mask),"PrincipalPlane"};

            mask_im = app.extract_ROI(app.masks_im,principal_plane);
            vol_mask = repmat(mask_im,1,1,app.num_reg_channel_ims);

            local_masks = unique(mask_im);
            map = repmat([0 0 1],max(local_masks)+1,1);
            map(app.current_mask+1,:) = [1 0 0];
            map(1,:) = [0 0 0];
            mask_tiles = imtile(vol_mask,map,BackgroundColor="black", ...
                BorderSize=[1 1],GridSize=[app.num_reg_rows app.num_reg_cols]);
            grayTile = rgb2gray(mask_tiles);
            mask_tiles_alpha = (grayTile > 0);

        end

        function tiles = make_bin_genes_tiles(app)
            
            principal_plane = app.masks_stats{string(app.current_mask),"PrincipalPlane"};

            primary_reg_im = app.extract_ROI(app.primary_reg_channel_im,principal_plane);
            primary_reg_im = rescale(primary_reg_im,0,65535);
            
            vol = zeros(size(primary_reg_im,1),size(primary_reg_im,2),app.num_bin_channel_ims+1);
            vol(:,:,1) = primary_reg_im;

            for i=1:app.num_bin_channel_ims
                bin_gene_im = app.extract_ROI(app.bin_channel_ims{i},principal_plane);
                bin_gene_im = rescale(bin_gene_im,0,65535);
                vol(:,:,i+1) = bin_gene_im;
            end

            tiles = imtile(vol,BorderSize=[1 1], ...
                GridSize=[app.num_bin_rows app.num_bin_cols]);

        end

        function [mask_tiles, mask_tiles_alpha] = make_bin_genes_tiles_mask(app)

            principal_plane = app.masks_stats{string(app.current_mask),"PrincipalPlane"};

            mask_im = app.extract_ROI(app.masks_im,principal_plane);
            vol_mask = repmat(mask_im,1,1,app.num_bin_channel_ims+1);

            local_masks = unique(mask_im);
            map = repmat([0 0 1],max(local_masks)+1,1);
            map(app.current_mask+1,:) = [1 0 0];
            map(1,:) = [0 0 0];
            mask_tiles = imtile(vol_mask,map,BackgroundColor="black", ...
                BorderSize=[1 1],GridSize=[app.num_bin_rows app.num_bin_cols]);
            grayTile = rgb2gray(mask_tiles);
            mask_tiles_alpha = (grayTile > 0);

        end

        function updateRegChannelsTable(app,ssimval)

            app.RegChannelTable.Data.("SSIM") = ssimval;
            ssim = app.RegChannelTable.Data.("SSIM")(2:end);
            ssim_thresh = cell2mat(app.RegChannelTable.Data.("SSIM Threshold")(2:end));
            app.RegChannelTable.Data.("Pass?")(2:end) = num2cell(ssim >= ssim_thresh);
            
            if all(cell2mat(app.RegChannelTable.Data.("Pass?")(2:end)))
                app.RegistrationChannelsPanel.BorderColor = [0.39,0.83,0.07]; % set green, pass
            else
                app.RegistrationChannelsPanel.BorderColor = [1.00,0.75,0]; % set yellow, at least one failed
            end

            for i=1:app.num_reg_channel_ims-1
                switch app.RegChannelTable.Data.("Pass?"){i+1}
                    case 0
                        addStyle(app.RegChannelTable,app.y_box,'cell',[i+1,4])
                    case 1
                        addStyle(app.RegChannelTable,app.g_box,'cell',[i+1,4])
                end
                app.RegChannelTable.Data.("Pass?"){i+1} = [];
            end

        end

        function initializeRegTiles(app)

            [tiles, ssimval] = app.make_reg_channels_tiles();
            [mask_tiles, mask_tiles_alpha] = app.make_reg_channels_tiles_mask();

            app.reg_channels_im = imshow(tiles,[0 65535],Parent=app.RegChannelAxes);
            app.reg_channels_im.HitTest = 'off';

            app.reg_channels_mask_im = imshow(mask_tiles,[0 65535],Parent=app.RegChannelAxes);
            app.reg_channels_mask_im.AlphaData = mask_tiles_alpha * app.mask_opacity;
            app.reg_channels_mask_im.HitTest = 'off';

            app.updateRegChannelsTable(ssimval)
        
        end

        function initializeBinTiles(app)

            tiles = app.make_bin_genes_tiles();
            [mask_tiles, mask_tiles_alpha] = app.make_bin_genes_tiles_mask();

            app.bin_channels_im = imshow(tiles,[0 65535],Parent=app.BinGeneAxes);
            app.bin_channels_im.ButtonDownFcn = createCallbackFcn(app, @BinGeneAxesButtonDown, true);

            app.bin_channels_mask_im = imshow(mask_tiles,[0 65535],Parent=app.BinGeneAxes);
            app.bin_channels_mask_im.AlphaData = mask_tiles_alpha * app.mask_opacity;
            app.bin_channels_mask_im.ButtonDownFcn = createCallbackFcn(app, @BinGeneAxesButtonDown, true);

        end

        function updateTiles(app)

            app.binTiles = app.make_bin_genes_tiles();
            [app.binMaskTiles, app.binMaskTilesAlpha] = app.make_bin_genes_tiles_mask();

            [app.regTiles, ssimval] = app.make_reg_channels_tiles();
            [app.regMaskTiles, app.regMaskTilesAlpha] = app.make_reg_channels_tiles_mask();

            app.bin_channels_im.CData = app.binTiles;
            app.bin_channels_mask_im.CData = app.binMaskTiles;
            app.bin_channels_mask_im.AlphaData = app.binMaskTilesAlpha * app.mask_opacity;

            app.reg_channels_im.CData = app.regTiles;
            app.reg_channels_mask_im.CData = app.regMaskTiles;
            app.reg_channels_mask_im.AlphaData = app.regMaskTilesAlpha * app.mask_opacity;

            app.updateRegChannelsTable(ssimval)

        end

        % set current mask to next mask in the table that has not been
        % assessed
        function set_next_mask(app)
            
            % find the first row (mask) that hasn't been assessed
            nextRow = find(~app.MasksTable.Data.("Assessed"),1,"first");

            if ~isempty(nextRow)
                app.MasksTable.Selection = nextRow;
                app.current_mask = app.MasksTable.Data.("Mask ID")(app.MasksTable.Selection);
                app.refresh()
            end
            
        end

        function checkManualMask(app)

            if ~isempty(app.manualMasksTable)

                if any(ismember(app.current_mask, app.manualMasksTable.('mask_id')))

                    app.MANUALMASKLabel.Visible = true;
                    idx = find(app.manualMasksTable.('mask_id') == app.current_mask);
                    row = app.manualMasksTable(idx,7:end);

                    genes = row.Properties.VariableNames;
                    for i=1:length(genes)
                        genes{i} = lower(genes{i}(1:end-3));
                    end

                    for i=1:length(genes)
                        app.bin_results{num2str(app.current_mask),genes{i}} = row{1,i};
                    end
                    
                end

            end

        end

        function refresh(app)

            app.calc_ROI_ind()
            app.updateTiles()

            % make sure all binary genes have a model to make predictions
            if ~any(cellfun(@isempty,app.bin_models))
                app.predict_bin_genes()
            end
            
            app.MANUALMASKLabel.Visible = false;
            app.checkManualMask()

            app.updateBinGeneTable()
            app.update_reg_text()
            app.update_bin_text()
            app.update_bin_readout_text()
            

        end

        function prepare_bin_models(app)
            
            app.bin_layers = [ ...
                image3dInputLayer([app.bin_image_xy app.bin_image_xy 3 1],Normalization="none")
                convolution3dLayer([3 3 3],16,Stride=1)
                reluLayer
                maxPooling3dLayer([3 3 1],Stride=1)
                fullyConnectedLayer(2)
                softmaxLayer];

        end

        function [images, targets] = prepare_training_data(app,gene_ind)

            gene = app.bin_genes{gene_ind};
            masks_to_train = app.masks(app.MasksTable.Data.("Result") == "good");
            images = zeros(app.bin_image_xy,app.bin_image_xy,3,1,length(masks_to_train));
            targets = app.bin_results{string(masks_to_train),gene};
            targets = horzcat(targets,~targets);
            
            gene_im = app.bin_channel_ims{gene_ind};

            for i=1:length(masks_to_train)

                app.current_mask = masks_to_train(i);
                principal_plane = app.masks_stats{string(app.current_mask),"PrincipalPlane"};
                app.calc_ROI_ind()

                mask_im = app.extract_ROI(app.masks_im,principal_plane);
                mask_im = (mask_im == app.current_mask);
                gene_im_ROI = app.extract_ROI(gene_im,principal_plane);
                gene_im_ROI = rescale(gene_im_ROI);
                primary_reg_im = app.extract_ROI(app.primary_reg_channel_im,principal_plane);
                primary_reg_im = rescale(primary_reg_im);
                
                images(:,:,1,1,i) = imresize(mask_im,[app.bin_image_xy app.bin_image_xy]);
                images(:,:,2,1,i) = imresize(gene_im_ROI,[app.bin_image_xy app.bin_image_xy]);
                images(:,:,3,1,i) = imresize(primary_reg_im,[app.bin_image_xy app.bin_image_xy]);

            end

        end

        function [images,targets] = oversampleData(~,images,targets)

            S = sum(targets,1);
            num_pos = S(1);
            num_neg = S(2);

            if num_pos == 0 || num_neg == 0
                return
            end

            deficit = num_pos - num_neg;

            if deficit == 0 % all set

                return

            elseif deficit > 0 % need more neg

                true_neg = (targets(:,2) == 1);
                idx = 1:size(targets,1);
                neg_idx = idx(true_neg);
                y = datasample(neg_idx,abs(deficit));
                
                append_images = images(:,:,:,:,y);
                append_targets = targets(y,:);
                images = cat(5,images,append_images);
                targets = cat(1,targets,append_targets);

                p = randperm(size(targets,1));
                images = images(:,:,:,:,p);
                targets = targets(p,:);

            elseif deficit < 0 % need more pos

                true_pos = (targets(:,1) == 1);
                idx = 1:size(targets,1);
                pos_idx = idx(true_pos);
                y = datasample(pos_idx,abs(deficit));
                
                append_images = images(:,:,:,:,y);
                append_targets = targets(y,:);
                images = cat(5,images,append_images);
                targets = cat(1,targets,append_targets);

                p = randperm(size(targets,1));
                images = images(:,:,:,:,p);
                targets = targets(p,:);
                
            end

        end

        function image = prepare_predict_data(app,gene_ind)
            
            image = zeros(app.bin_image_xy,app.bin_image_xy,3,1);
            gene_im = app.bin_channel_ims{gene_ind};
            principal_plane = app.masks_stats{string(app.current_mask),"PrincipalPlane"};
            
            mask_im = app.extract_ROI(app.masks_im,principal_plane);
            mask_im = (mask_im == app.current_mask);
            gene_im_ROI = app.extract_ROI(gene_im,principal_plane);
            gene_im_ROI = rescale(gene_im_ROI);
            primary_reg_im = app.extract_ROI(app.primary_reg_channel_im,principal_plane);
            primary_reg_im = rescale(primary_reg_im);
            
            image(:,:,1,1) = imresize(mask_im,[app.bin_image_xy app.bin_image_xy]);
            image(:,:,2,1) = imresize(gene_im_ROI,[app.bin_image_xy app.bin_image_xy]);
            image(:,:,3,1) = imresize(primary_reg_im,[app.bin_image_xy app.bin_image_xy]);

        end
        
        % train binarization CNNs on all available assessed data
        function train_bin(app)

            if sum(app.MasksTable.Data.("Result") == "good") == 0
                str = "No masks passed yet, pass at least one mask " + ...
                    "to train binarization models on.";
                app.MessageBoxTextArea.Value = str;
                return
            end

            app.prepare_bin_models()
            app.old_mask = app.current_mask;

            for gene_ind=1:length(app.bin_genes)

                if (app.TrainAllCheckBox.Value == false) & (~isempty(app.bin_models{gene_ind}))
                    continue
                end
                
                str = "Training model for gene " + app.bin_genes{gene_ind};
                app.MessageBoxTextArea.Value = str;

                [images,targets] = app.prepare_training_data(gene_ind);
                [images,targets] = app.oversampleData(images,targets);
                options = trainingOptions("adam");
                netTrained = trainnet(images,targets,app.bin_layers,"binary-crossentropy",options);
                app.bin_models{gene_ind} = netTrained;

            end
            
            app.current_mask = app.old_mask();
            app.refresh()
            app.updateBinModelsTable()

        end
        
        % use binarization CNNs to predict the binary readout of genes in
        % the current mask
        function predict_bin_genes(app)
            
            % Don't predict binary gene readouts if this mask has already
            % been signed off on
            if app.MasksTable.Data{app.MasksTable.Selection,"Assessed"} == 1
                return
            end

            for gene_ind=1:length(app.bin_genes)

                netTrained = app.bin_models{gene_ind};
                image = app.prepare_predict_data(gene_ind);
                output = predict(netTrained,image);
                app.BinGeneTable.Data.("Prediction Probability")(gene_ind) = max(output);

                if output(1) >= output(2)
                    app.bin_results{string(app.current_mask),app.bin_genes{gene_ind}} = 1;
                else
                    app.bin_results{string(app.current_mask),app.bin_genes{gene_ind}} = 0;
                end
                
            end

        end

        function updateBinGeneTable(app)
            
            bin_gene_vec = app.bin_results{string(app.current_mask),:};
            app.BinGeneTable.Data.("Binary Expression Readout") = bin_gene_vec';

            pred_prob = app.BinGeneTable.Data.("Prediction Probability");
            pred_prob_thresh = app.BinGeneTable.Data.("Prediction Probability Threshold");
            app.BinGeneTable.Data.("Pass?") = num2cell(pred_prob >= pred_prob_thresh);

            if all(cell2mat(app.BinGeneTable.Data.("Pass?")))
                app.BinaryGenesPanel.BorderColor = [0.39,0.83,0.07]; % set green, pass
            else
                app.BinaryGenesPanel.BorderColor = [1.00,0.75,0]; % set yellow, at least one failed
            end

            for i=1:length(app.bin_genes)
                switch app.BinGeneTable.Data.("Pass?"){i}
                    case 0
                        addStyle(app.BinGeneTable,app.y_box,'cell',[i,5])
                    case 1
                        addStyle(app.BinGeneTable,app.g_box,'cell',[i,5])
                end
                app.BinGeneTable.Data.("Pass?"){i} = [];
            end

        end

        function updateBinModelsTable(app)

            for i=1:app.num_bin_channel_ims
                if isempty(app.bin_models{i})
                    addStyle(app.BinModelsTable,app.r_box,'cell',[i,2])
                else
                    addStyle(app.BinModelsTable,app.g_box,'cell',[i,2])
                end
                app.BinModelsTable.Data.("Model Loaded?"){i} = [];
            end

        end

        function calc_rows_and_cols(app)

            app.num_reg_cols = ceil(app.num_reg_channel_ims / app.num_reg_rows);
            % app.num_reg_rows = ceil(app.num_reg_channel_ims / app.num_reg_cols);
            app.num_bin_cols = ceil( (app.num_bin_channel_ims + 1 ) / app.num_bin_rows );
            % app.num_bin_rows = ceil(app.num_bin_channel_ims / app.num_bin_cols);

        end

        function make_reg_text(app)
            
            % delete old registration text, if it exists
            if ~isempty(app.reg_text)
                for i=1:app.num_reg_channel_ims
                    delete(app.reg_text{i})
                end
            end

            app.reg_text = cell(app.num_reg_channel_ims,1);
            
            k = 1;
            for i=1:app.num_reg_rows
                for j=1:app.num_reg_cols
                    if k <= app.num_reg_channel_ims
                        x = (2*j - 1) / (app.num_reg_cols * 2); % black magic
                        y = 1 - ((i-1) / app.num_reg_rows) - 0.01; % black magic
                        app.reg_text{k} = text(app.RegChannelAxes,x,y,app.reg_chans{k}, ...
                            'HorizontalAlignment','center', ...
                            'Color','w', ...
                            'Units','Normalized', ...
                            'Interpreter','none');
                        app.reg_text{k}.VerticalAlignment = 'top';
                        app.reg_text{k}.FontSize = 14;
                        app.reg_text{k}.HitTest = 'off';
                        k = k + 1;
                    else
                        continue
                    end
                end
            end

        end

        function make_bin_text(app)
            
            % delete old registration text, if it exists
            if ~isempty(app.bin_text)
                for i=1:app.num_bin_channel_ims+1
                    delete(app.bin_text{i})
                end
            end

            app.bin_text = cell(app.num_bin_channel_ims+1,1);
            
            k = 1;

            for i=1:app.num_bin_rows

                for j=1:app.num_bin_cols

                    if k <= app.num_bin_channel_ims+1

                        x = (2*j - 1) / (app.num_bin_cols * 2); % black magic
                        y = 1 - ((i-1) / app.num_bin_rows) - 0.01; % black magic

                        if k == 1
                            app.bin_text{k} = text(app.BinGeneAxes,x,y,app.reg_chans{k}, ...
                            'HorizontalAlignment','center', ...
                            'Color','w', ...
                            'Units','Normalized', ...
                            'Interpreter','none');
                        else
                            app.bin_text{k} = text(app.BinGeneAxes,x,y,app.bin_genes{k-1}, ...
                            'HorizontalAlignment','center', ...
                            'Color','w', ...
                            'Units','Normalized', ...
                            'Interpreter','none');
                        end

                        app.bin_text{k}.VerticalAlignment = 'top';
                        app.bin_text{k}.FontSize = 14;
                        app.bin_text{k}.HitTest = 'off';
                        
                        k = k + 1;

                    else

                        continue

                    end
                end
            end

        end

        function make_bin_readout_text(app)
            
            % delete old registration text, if it exists
            if ~isempty(app.bin_readout_text)
                for i=1:app.num_bin_channel_ims
                    delete(app.bin_readout_text{i})
                end
            end

            app.bin_readout_text = cell(app.num_bin_channel_ims,1);
            
            k = 1;

            for i=1:app.num_bin_rows

                for j=1:app.num_bin_cols

                    if k <= (app.num_bin_channel_ims + 1)

                        x = (2*j - 1) / (app.num_bin_cols * 2); % black magic
                        y = 1 - ((i) / app.num_bin_rows) + 0.01; % black magic

                        if k == 1
                            k = k + 1;
                            continue
                        else
                            app.bin_readout_text{k-1} = text(app.BinGeneAxes,x,y, ...
                                num2str(app.BinGeneTable.Data.('Binary Expression Readout')(k-1)), ...
                                'HorizontalAlignment','center', ...
                                'Color','w', ...
                                'Units','Normalized', ...
                                'Interpreter','none');
                        end

                        app.bin_readout_text{k-1}.VerticalAlignment = 'bottom';
                        app.bin_readout_text{k-1}.FontSize = 24;
                        app.bin_readout_text{k-1}.FontWeight = 'bold';
                        app.bin_readout_text{k-1}.HitTest = 'off';
                        
                        k = k + 1;

                    else

                        continue

                    end
                end
            end

        end

        function update_reg_text(app)

            ssim = app.RegChannelTable.Data.("SSIM")(2:end);
            ssim_thresh = cell2mat(app.RegChannelTable.Data.("SSIM Threshold")(2:end));
            pass = num2cell(ssim >= ssim_thresh);

            for i=1:app.num_reg_channel_ims-1

                switch pass{i}
                    case 1
                        str = strcat(app.reg_chans{i+1},' ','✅');
                        c = 'g';
                    case 0
                        str = strcat(app.reg_chans{i+1},' ','⚠️');
                        c = 'y';
                end

                app.reg_text{i+1}.String = str;
                app.reg_text{i+1}.Color = c;

            end

        end

        function update_bin_text(app)

            pred_prob = app.BinGeneTable.Data.("Prediction Probability");
            pred_prob_thresh = app.BinGeneTable.Data.("Prediction Probability Threshold");
            pass = num2cell(pred_prob >= pred_prob_thresh);

            for i=1:app.num_bin_channel_ims

                switch pass{i}
                    case 1
                        str = strcat(app.bin_genes{i},' ','✅');
                        c = 'g';
                    case 0
                        str = strcat(app.bin_genes{i},' ','⚠️');
                        c = 'y';
                end

                app.bin_text{i+1}.String = str;
                app.bin_text{i+1}.Color = c;

            end

        end

        function update_bin_readout_text(app)

            for i=1:app.num_bin_channel_ims

                str = num2str(app.BinGeneTable.Data.('Binary Expression Readout')(i));
                
                switch str
                    case '1'
                        app.bin_readout_text{i}.Color = "g";
                    case '0'
                        app.bin_readout_text{i}.Color = "r";
                end

                app.bin_readout_text{i}.String = str;
                
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im_dir, f_masks_im)
            
            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            addpath(['./' 'assets'])

            movegui(app.UIFigure,'north')
            %axis(app.RegChannelAxes,"off")
            axis(app.RegChannelAxes,"image")
            hold(app.RegChannelAxes,"on")
            app.RegChannelAxes.Toolbar.Visible = 'off';
            %axis(app.BinGeneAxes,"off")
            axis(app.BinGeneAxes,"image")
            hold(app.BinGeneAxes,"on")
            app.BinGeneAxes.Toolbar.Visible = 'off';

            app.im_dir = im_dir;
            app.f_masks_im = f_masks_im;
            
            % get image file names
            listing = dir(app.im_dir);
            f_names = {listing.name};
            TF_im = contains(f_names,{'.tif','.tiff'},'IgnoreCase',true);
            im_files = f_names(TF_im);
            
            % get registration channel image file names
            TF_reg_channels = contains(im_files,app.reg_channels_tags,'IgnoreCase',true);
            app.f_reg_channels_ims = im_files(TF_reg_channels);
            app.num_reg_channel_ims = length(app.f_reg_channels_ims);
            
            % get primary registration channel image name
            TF_primary_reg_channel = contains(app.f_reg_channels_ims,app.primary_reg_channel_tag);
            app.f_primary_reg_channel_im = app.f_reg_channels_ims(TF_primary_reg_channel);
            app.f_primary_reg_channel_im = app.f_primary_reg_channel_im{1};

            % get secondary registration channel image names
            app.f_secondary_reg_channel_ims = app.f_reg_channels_ims(~TF_primary_reg_channel);
            
            % disgusting mess to determine the names of the binary channels
            TF_bin = ~TF_reg_channels;
            app.f_bin_channels_ims = im_files(TF_bin);
            [~,name,~] = fileparts(app.im_dir);

            [masksDir,~,~] = fileparts(app.f_masks_im);
            manual_masks_f = fullfile(masksDir,'manual_masks.txt');
            if isfile(manual_masks_f)
                app.manualMasksTable = readtable(manual_masks_f);
            end

            if isfile(fullfile(app.im_dir,strcat(name,'_bin_genes.txt')))

                app.bin_results = readtable(fullfile(app.im_dir,strcat(name,'_bin_genes.txt')), ...
                    ReadRowNames=true);
                bin_genes_temp = app.bin_results.Properties.VariableNames;
                TF_full = zeros(1,length(app.f_bin_channels_ims),'logical');
                for i=1:length(bin_genes_temp)
                    TF = contains(app.f_bin_channels_ims,bin_genes_temp{i},'IgnoreCase',true);
                    TF_full = TF_full | TF;
                end
                app.f_bin_channels_ims = app.f_bin_channels_ims(TF_full);

            else

                [indx,~] = listdlg('ListString',app.f_bin_channels_ims, ...
                    'SelectionMode','multiple', ...
                    'PromptString',{'Select genes to be binarized','(Ctrl+Click to select multiple)'});
                app.f_bin_channels_ims = app.f_bin_channels_ims(indx);

            end

            app.num_bin_channel_ims = length(app.f_bin_channels_ims);

            % load all images
            num_ims_to_load = app.num_reg_channel_ims + app.num_bin_channel_ims + 1;
            ims_loaded = 1;
            d = uiprogressdlg(app.UIFigure,'Title','Loading...', ...
                'Message',"Loading Masks...", ...
                'Value',ims_loaded/num_ims_to_load);

            app.masks_im = read_tiff(app.f_masks_im);
            
            ims_loaded = ims_loaded + 1;
            d.Message = 'Loading primary registration channel image...';
            d.Value = ims_loaded/num_ims_to_load;
            app.primary_reg_channel_im = read_tiff(fullfile(app.im_dir,app.f_primary_reg_channel_im));

            d.Message = 'Loading secondary registration channel images...';
            app.secondary_reg_channel_ims = cell(app.num_reg_channel_ims-1,1);
            for i=1:app.num_reg_channel_ims-1
                ims_loaded = ims_loaded + 1;
                d.Value = ims_loaded/num_ims_to_load;
                app.secondary_reg_channel_ims{i} = read_tiff(fullfile(app.im_dir,app.f_secondary_reg_channel_ims{i}));
            end

            d.Message = 'Loading binary channel images...';
            app.bin_channel_ims = cell(app.num_bin_channel_ims,1);
            for i=1:app.num_bin_channel_ims
                ims_loaded = ims_loaded + 1;
                d.Value = ims_loaded/num_ims_to_load;
                app.bin_channel_ims{i} = read_tiff(fullfile(app.im_dir,app.f_bin_channels_ims{i}));
            end
            close(d)

            % prepare masks stuff
            app.masks = unique(app.masks_im);
            app.masks(1) = [];
            app.num_masks = length(app.masks);
            app.masks_stats = regionprops3(app.masks_im,{"BoundingBox","Centroid"});
            A = table2array(app.masks_stats);
            TF = isnan(A);
            TF = any(TF,2);
            TF = ~TF;
            app.masks_stats = app.masks_stats(TF,:);
            app.masks_stats.Properties.RowNames = string(app.masks);

            app.im_size = size(app.primary_reg_channel_im);
            app.calc_rows_and_cols()
            
            % find principal planes of each ROI
            principal_planes = zeros(app.num_masks,1);
            for i=1:app.num_masks
                app.current_mask = app.masks(i);
                app.calc_ROI_ind();
                ROI = app.extract_ROI(app.primary_reg_channel_im);
                ROI_mask = app.extract_ROI(app.masks_im);
                principal_planes(i) = app.find_principal_plane(ROI,ROI_mask);
            end
            app.masks_stats = addvars(app.masks_stats,principal_planes,'After','Centroid','NewVariableNames',{'PrincipalPlane'});
            
            % prepare registration channels table
            [~,app.reg_chans,~] = cellfun(@fileparts,[ {app.f_primary_reg_channel_im} app.f_secondary_reg_channel_ims ],'UniformOutput',false);
            app.RegChannelTable.Data = {app.reg_chans{1},'N/A','N/A','N/A'};
            for i=1:app.num_reg_channel_ims-1
                app.RegChannelTable.Data = [app.RegChannelTable.Data;{app.reg_chans{i+1},0,0.1,0}];
            end
            app.RegChannelTable.Data = cell2table(app.RegChannelTable.Data);
            app.RegChannelTable.Data = renamevars(app.RegChannelTable.Data, ...
                ["Var1","Var2","Var3","Var4"], ...
                app.RegChannelTable.ColumnName);

            % prepare binary channels table

            if isfile(fullfile(app.im_dir,strcat(name,'_bin_genes.txt')))
                app.bin_genes = app.bin_results.Properties.VariableNames;
            else
                app.bin_genes = cell(1,length(app.f_bin_channels_ims));
                for i=1:app.num_bin_channel_ims
                    gene_name = app.f_bin_channels_ims{i};
                    gene_name = gene_name(4:end-7);
                    app.bin_genes{i} = gene_name;
                end
                app.bin_genes = lower(app.bin_genes);
            end

            app.BinGeneTable.Data = cell(app.num_bin_channel_ims,6);
            for i=1:app.num_bin_channel_ims
                gene_name = app.bin_genes{i};
                app.BinGeneTable.Data(i,:) = {gene_name,0,0,0.9,0,0};
            end
            app.BinGeneTable.Data = cell2table(app.BinGeneTable.Data);
            app.BinGeneTable.Data = renamevars(app.BinGeneTable.Data, ...
                ["Var1","Var2","Var3","Var4","Var5","Var6"], ...
                app.BinGeneTable.ColumnName);
            
            app.bin_models = cell(length(app.bin_genes),1);
            
            % prepare icons
            app.r_box = uistyle('BackgroundColor','red', ...
                'Icon',fullfile('./','assets','Cross_red_circle.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            app.g_box = uistyle('BackgroundColor','green', ...
                'Icon','success', ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            app.y_box = uistyle('BackgroundColor','yellow', ...
                'Icon',fullfile('./','assets','exclamation-warning-round-black-yellow-icon.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            
            masks_table = table;
            assessed = zeros(app.num_masks,1,'logical');
            results = repelem({'null'},app.num_masks,1);
            masks_table = addvars(masks_table,app.masks,assessed,results,'NewVariableNames',{'Mask ID','Assessed','Result'});
            masks_table = addvars(masks_table,ceil(app.masks_stats.BoundingBox(:,3)),'Before','Assessed','NewVariableNames',{'Z-plane'});
            masks_table = addvars(masks_table,app.masks_stats.BoundingBox(:,6),'Before','Assessed','NewVariableNames',{'Z-span'});
            masks_table = addvars(masks_table,app.masks_stats.('PrincipalPlane'),'After','Z-span','NewVariableNames',{'Principal Plane'});
            app.MasksTable.Data = masks_table;

            % prepare binarization models table
            app.BinModelsTable.Data = cell(app.num_bin_channel_ims,2);
            for i=1:app.num_bin_channel_ims
                gene_name = app.bin_genes{i};
                app.BinModelsTable.Data(i,:) = {gene_name,0};
            end
            app.BinModelsTable.Data = cell2table(app.BinModelsTable.Data);
            app.BinModelsTable.Data = renamevars(app.BinModelsTable.Data, ...
                ["Var1","Var2"], ...
                app.BinModelsTable.ColumnName);
            app.BinModelsTable.Data = convertvars(app.BinModelsTable.Data,'Model Loaded?','cell');

            app.MasksTable.Selection = 1;
            app.current_mask = app.masks(1);
            app.previous_masks = [app.masks(1)];
            
            app.calc_ROI_ind()
            app.initializeRegTiles()
            app.initializeBinTiles()
            app.updateBinModelsTable()

            app.UIFigure.WindowState = 'maximized';
            app.make_reg_text()
            app.update_reg_text()
            app.make_bin_text()
            app.update_bin_text()
            app.make_bin_readout_text()
            app.update_bin_readout_text()
            
            if isfile(fullfile(app.im_dir,strcat(name,'_final_qc_masks.txt')))
                app.LoadResultsButtonPushed()
            else
                % prepare binarization results table
                varTypes = repmat({'uint8'},length(app.bin_genes),1);
                app.bin_results = table('Size',[size(app.MasksTable.Data,1) length(app.bin_genes)], ...
                'VariableTypes',varTypes, ...
                'VariableNames',app.bin_genes);
                app.bin_results.Properties.RowNames = string(app.masks);
            end

            app.set_next_mask()
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            
            key = event.Key;
            
            switch key
                case 'z'
                    app.REJECTCELLZButtonPushed()
                case 'x'
                    app.MASKSONXButtonPushed()
                case 'c'
                    app.ACCEPTCELLCButtonPushed()
            end
            
        end

        % Button pushed function: REJECTCELLZButton
        function REJECTCELLZButtonPushed(app, event)
            app.MasksTable.Data.("Assessed")(app.MasksTable.Selection) = true;
            app.MasksTable.Data.("Result"){app.MasksTable.Selection} = 'bad';
            app.previous_masks = [app.previous_masks app.current_mask];
            app.set_next_mask()
            scroll(app.MasksTable,"row",app.MasksTable.Selection)
        end

        % Button pushed function: MASKSONXButton
        function MASKSONXButtonPushed(app, event)
            app.show_masks = ~app.show_masks;
            app.reg_channels_mask_im.Visible = app.show_masks;
            app.bin_channels_mask_im.Visible = app.show_masks;
        end

        % Button pushed function: ACCEPTCELLCButton
        function ACCEPTCELLCButtonPushed(app, event)
            app.MasksTable.Data.("Assessed")(app.MasksTable.Selection) = true;
            app.MasksTable.Data.("Result"){app.MasksTable.Selection} = 'good';
            app.bin_results{string(app.current_mask),:} = app.BinGeneTable.Data.("Binary Expression Readout")';
            app.previous_masks = [app.previous_masks app.current_mask];
            app.set_next_mask()
            scroll(app.MasksTable,"row",app.MasksTable.Selection)
        end

        % Selection changed function: MasksTable
        function MasksTableSelectionChanged(app, event)
            selection = app.MasksTable.Selection;
            app.current_mask = app.MasksTable.Data.("Mask ID")(selection);
            app.refresh()
        end

        % Cell edit callback: RegChannelTable
        function RegChannelTableCellEdit(app, event)
            app.updateTiles()
        end

        % Button pushed function: TrainbinarizationmodelsButton
        function TrainbinarizationmodelsButtonPushed(app, event)
            app.train_bin()
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            [~,name,~] = fileparts(app.im_dir);
            writetable(app.MasksTable.Data,fullfile(app.im_dir,strcat(name,'_final_qc_masks')))
            writetable(app.bin_results,fullfile(app.im_dir,strcat(name,'_bin_genes')),'WriteRowNames',true)
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            [~,name,~] = fileparts(app.im_dir);
            app.MasksTable.Data = readtable(fullfile(app.im_dir,strcat(name,'_final_qc_masks.txt')), ...
                VariableNamingRule="preserve");
            app.bin_results = readtable(fullfile(app.im_dir,strcat(name,'_bin_genes.txt')), ...
                ReadRowNames=true);
            app.set_next_mask()
        end

        % Cell edit callback: BinGeneTable
        function BinGeneTableCellEdit(app, event)
            app.bin_results{string(app.current_mask),:} = app.BinGeneTable.Data.("Binary Expression Readout")';       
        end

        % Button pushed function: SaveModelsButton
        function SaveModelsButtonPushed(app, event)
 
            if all(cellfun(@isempty,app.bin_models))
                str = "No trained models, nothing to save.";
                app.MessageBoxTextArea.Value = str;
                return
            end
            
            str = "Are you sure? This will overwrite any models for these " + ...
                "genes currently in the 'models' subdirectory of HCRprocess.";
            selection = uiconfirm(app.UIFigure,str,"Confirmation",'Options',{'Yes','Cancel'});

            switch selection
                case 'Yes'
                    for i=1:app.num_bin_channel_ims
                        if ~isempty(app.bin_models{i})
                            filename = fullfile(pwd,'models',strcat(app.bin_genes{i},'.mat'));
                            model_to_save = app.bin_models{i};
                            save(filename,'model_to_save')
                        end
                    end
                case 'Cancel'
                    return
            end

        end

        % Button pushed function: LoadModelsButton
        function LoadModelsButtonPushed(app, event)
            
            listing = dir(fullfile(pwd,'models'));
            f_names = {listing.name};
            TF_mat = contains(f_names,{'.mat'},'IgnoreCase',true);
            mat_files = f_names(TF_mat);

            model_found = zeros(app.num_bin_channel_ims,1,'logical');
            for i=1:app.num_bin_channel_ims
                if any(contains(mat_files,strcat(app.bin_genes{i},'.mat')))
                    model_found(i) = 1;
                end
            end

            if sum(model_found) == 0
                str = "No models found for these genes, none loaded.";
                app.MessageBoxTextArea.Value = str;
                return
            end

            for i=1:app.num_bin_channel_ims
                if model_found(i) == true
                    load(fullfile(pwd,'models',strcat(app.bin_genes{i},'.mat')),'model_to_save')
                    app.bin_models{i} = model_to_save;
                end
            end

            str = strcat('Models found and loaded for the following genes:', ...
                {' '},strjoin(string(app.bin_genes(model_found)),', '));
            app.MessageBoxTextArea.Value = str;

            app.updateBinModelsTable()

        end

        % Value changed function: RegChannelImPerRowSpinner
        function RegChannelImPerRowSpinnerValueChanged(app, event)
            app.calc_rows_and_cols()
            app.make_reg_text()
            app.make_bin_text()
            app.refresh()
        end

        % Value changed function: BinChannelImPerRowSpinner
        function BinChannelImPerRowSpinnerValueChanged(app, event)
            app.calc_rows_and_cols()
            app.make_reg_text()
            app.make_bin_text()
            app.refresh()
        end

        % Button down function: BinGeneAxes
        function BinGeneAxesButtonDown(app, event)
            
            im_height = size(event.Source.CData,1);
            im_width = size(event.Source.CData,2);

            tile_height = im_height / app.num_bin_rows;
            tile_width = im_width / app.num_bin_cols;

            hit_row_idx = ceil(event.IntersectionPoint(2) / tile_height);
            hit_col_idx = ceil(event.IntersectionPoint(1) / tile_width);
            hit_lin_idx = ((hit_row_idx - 1) * app.num_bin_cols) + hit_col_idx;
            
            % bit flip if a binary gene panel was clicked
            if hit_lin_idx > 1
                app.BinGeneTable.Data.('Binary Expression Readout')(hit_lin_idx - 1) = ~app.BinGeneTable.Data.('Binary Expression Readout')(hit_lin_idx - 1);
            else
                return
            end
            
            app.bin_results{string(app.current_mask),:} = app.BinGeneTable.Data.("Binary Expression Readout")';
            app.update_bin_readout_text()

        end

        % Button pushed function: SaveUncuratedButton
        function SaveUncuratedButtonPushed(app, event)
            
            message = 'Are you sure? This will save uncurated data and close the GUI, so go back and save curated data first if you have unsaved results.';
            title = 'Save uncurated data?';
            selection = uiconfirm(app.UIFigure, message, title, 'Icon', 'warning', 'Options', {'Yes','Cancel'});

            switch selection
                case 'Yes'
                    disp('Saving uncurated data...')
                case 'Cancel'
                    return
            end

            [filepath,~,~] = fileparts(app.f_masks_im);
            [~,name,~] = fileparts(app.im_dir);
            new_f_masks_im = strcat(name,'_cp_masks.tif');
            new_f_masks_im = fullfile(filepath,new_f_masks_im);
            app.masks_im = read_tiff(new_f_masks_im);

            % prepare masks stuff
            app.masks = unique(app.masks_im);
            app.masks(1) = [];
            app.num_masks = length(app.masks);
            app.masks_stats = regionprops3(app.masks_im,{"BoundingBox","Centroid"});
            A = table2array(app.masks_stats);
            TF = isnan(A);
            TF = any(TF,2);
            TF = ~TF;
            app.masks_stats = app.masks_stats(TF,:);
            app.masks_stats.Properties.RowNames = string(app.masks);
            
            % find principal planes of each ROI
            principal_planes = zeros(app.num_masks,1);
            for i=1:app.num_masks
                app.current_mask = app.masks(i);
                app.calc_ROI_ind();
                ROI = app.extract_ROI(app.primary_reg_channel_im);
                ROI_mask = app.extract_ROI(app.masks_im);
                principal_planes(i) = app.find_principal_plane(ROI,ROI_mask);
            end
            app.masks_stats = addvars(app.masks_stats,principal_planes,'After','Centroid','NewVariableNames',{'PrincipalPlane'});

            masks_table = table;
            assessed = ones(app.num_masks,1,'logical');
            results = repelem({'good'},app.num_masks,1);
            masks_table = addvars(masks_table,app.masks,assessed,results,'NewVariableNames',{'Mask ID','Assessed','Result'});
            masks_table = addvars(masks_table,ceil(app.masks_stats.BoundingBox(:,3)),'Before','Assessed','NewVariableNames',{'Z-plane'});
            masks_table = addvars(masks_table,app.masks_stats.BoundingBox(:,6),'Before','Assessed','NewVariableNames',{'Z-span'});
            masks_table = addvars(masks_table,app.masks_stats.('PrincipalPlane'),'After','Z-span','NewVariableNames',{'Principal Plane'});
            app.MasksTable.Data = masks_table;

            [~,name,~] = fileparts(app.im_dir);
            writetable(app.MasksTable.Data,fullfile(app.im_dir,strcat(name,'_uncurated_masks')))
            delete(app.UIFigure)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RegistrationChannelsPanel = uipanel(app.GridLayout);
            app.RegistrationChannelsPanel.Title = 'Registration Channels';
            app.RegChannelAxes = uiaxes(app.RegistrationChannelsPanel);
            app.RegChannelAxes.XTick = [];
            app.RegChannelAxes.YTick = [];
            app.RegChannelTable = uitable(app.RegistrationChannelsPanel);
            app.RegChannelTable.ColumnName = {'Image'; 'SSIM'; 'SSIM Threshold'; 'Pass?'};
            app.RegChannelTable.RowName = {};
            app.RegChannelTable.ColumnEditable = [false false true false];
            app.RegChannelTable.CellEditCallback = createCallbackFcn(app, @RegChannelTableCellEdit, true);
            app.MasksTable = uitable(app.GridLayout);
            app.MasksTable.ColumnName = {'Mask ID'; 'Z-plane'; 'Z-span'; 'Principal Plane'; 'Assessed'; 'Result'};
            app.MasksTable.RowName = {};
            app.MasksTable.SelectionType = 'row';
            app.MasksTable.SelectionChangedFcn = createCallbackFcn(app, @MasksTableSelectionChanged, true);
            app.BinaryGenesPanel = uipanel(app.GridLayout);
            app.BinaryGenesPanel.Title = 'Binary Genes';
            app.BinGeneAxes = uiaxes(app.BinaryGenesPanel);
            app.BinGeneAxes.XTick = [];
            app.BinGeneAxes.YTick = [];
            app.BinGeneAxes.ButtonDownFcn = createCallbackFcn(app, @BinGeneAxesButtonDown, true);
            app.BinGeneTable = uitable(app.BinaryGenesPanel);
            app.BinGeneTable.ColumnName = {'Gene'; 'Binary Expression Readout'; 'Prediction Probability'; 'Prediction Probability Threshold'; 'Pass?'; 'Photobleached?'};
            app.BinGeneTable.RowName = {};
            app.BinGeneTable.ColumnEditable = [false true false true false false];
            app.BinGeneTable.CellEditCallback = createCallbackFcn(app, @BinGeneTableCellEdit, true);
            app.MANUALMASKLabel = uilabel(app.BinaryGenesPanel);
            app.MANUALMASKLabel.Visible = 'off';
            app.MANUALMASKLabel.Text = 'MANUAL MASK';
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.REJECTCELLZButton = uibutton(app.ControlsPanel, 'push');
            app.REJECTCELLZButton.ButtonPushedFcn = createCallbackFcn(app, @REJECTCELLZButtonPushed, true);
            app.REJECTCELLZButton.Text = 'REJECT CELL [Z]';
            app.MASKSONXButton = uibutton(app.ControlsPanel, 'push');
            app.MASKSONXButton.ButtonPushedFcn = createCallbackFcn(app, @MASKSONXButtonPushed, true);
            app.MASKSONXButton.Text = 'MASKS ON [X]';
            app.ACCEPTCELLCButton = uibutton(app.ControlsPanel, 'push');
            app.ACCEPTCELLCButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTCELLCButtonPushed, true);
            app.ACCEPTCELLCButton.Text = 'ACCEPT CELL [C]';
            app.TrainbinarizationmodelsButton = uibutton(app.ControlsPanel, 'push');
            app.TrainbinarizationmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @TrainbinarizationmodelsButtonPushed, true);
            app.TrainbinarizationmodelsButton.Text = 'Train binarization models';
            app.MessageBoxTextAreaLabel = uilabel(app.ControlsPanel);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.ControlsPanel);
            app.SaveResultsButton = uibutton(app.ControlsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save results';
            app.LoadResultsButton = uibutton(app.ControlsPanel, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = 'Load results';
            app.BinModelsTable = uitable(app.ControlsPanel);
            app.BinModelsTable.ColumnName = {'Gene'; 'Model Loaded?'};
            app.BinModelsTable.RowName = {};
            app.TrainAllCheckBox = uicheckbox(app.ControlsPanel);
            app.TrainAllCheckBox.Text = 'Train a new model for every gene?';
            app.TrainAllCheckBox.Value = true;
            app.SaveModelsButton = uibutton(app.ControlsPanel, 'push');
            app.SaveModelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelsButtonPushed, true);
            app.SaveModelsButton.Text = 'Save models';
            app.LoadModelsButton = uibutton(app.ControlsPanel, 'push');
            app.LoadModelsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelsButtonPushed, true);
            app.LoadModelsButton.Text = 'Load models';
            app.rowsRegistrationChannelsLabel = uilabel(app.ControlsPanel);
            app.rowsRegistrationChannelsLabel.Text = {'# images per row'; '(Registration)'};
            app.RegChannelImPerRowSpinner = uispinner(app.ControlsPanel);
            app.RegChannelImPerRowSpinner.Limits = [1 Inf];
            app.RegChannelImPerRowSpinner.ValueChangedFcn = createCallbackFcn(app, @RegChannelImPerRowSpinnerValueChanged, true);
            app.RegChannelImPerRowSpinner.Value = 3;
            app.rowsBinaryLabel = uilabel(app.ControlsPanel);
            app.rowsBinaryLabel.Text = {'# images per row'; '(Binary)'};
            app.BinChannelImPerRowSpinner = uispinner(app.ControlsPanel);
            app.BinChannelImPerRowSpinner.Limits = [1 Inf];
            app.BinChannelImPerRowSpinner.ValueChangedFcn = createCallbackFcn(app, @BinChannelImPerRowSpinnerValueChanged, true);
            app.BinChannelImPerRowSpinner.Value = 3;
            app.SaveUncuratedButton = uibutton(app.ControlsPanel, 'push');
            app.SaveUncuratedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveUncuratedButtonPushed, true);
            app.SaveUncuratedButton.Text = {'Save uncurated'; 'data'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quality_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end