classdef setZero < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SkipButton             matlab.ui.control.Button
        ROIsLabel              matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        threshPanel            matlab.ui.container.Panel
        resetClimButton        matlab.ui.control.Button
        logitPanel             matlab.ui.container.Panel
        HistPanel              matlab.ui.container.Panel
        BoundariesCheckBox     matlab.ui.control.CheckBox
        nButton                matlab.ui.control.Button
        yButton                matlab.ui.control.Button
        FOVsizeEditField       matlab.ui.control.NumericEditField
        FOVsizeEditFieldLabel  matlab.ui.control.Label
        CLimSlider             matlab.ui.control.RangeSlider
        CLimSliderLabel        matlab.ui.control.Label
        ROIsPanel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        
        tiledLayout         % TiledChartLayout object for plotting ROIs
        histTiledLayout     % TiledChartLayout object for plotting intensity histogram
        logitTiledLayout    % TiledChartLayout object for plotting logistic regression
        threshTiledLayout   % TiledChartLayout object for plotting threshold history
        tileAxesArray       % array of axes for ROIs, 1 for each tile
        histTileAxes        % axes for intensity histogram
        logitTileAxes       % axes for logistic regression
        threshTileAxes      % axes for threshold history
        threshLine          % threshold history line
        logitText           % holds model not ready yet text
        threshText          % holds model not ready yet text
        histThreshLine      % xline on histogram that denotes current threshold
        logitAxElements     % holds elements plotted on logit axes
        imageArray          % array of images, 1 for each tile
        boundArray          % array of boundaries, 1 for each tile
        masks               % image with different integers corresponding to different cells
        im                  % intensity of image of gene to be zero-scaled
        im_f                % file name of the intensity image
        cell_ids            % list of all the cell_ids in the masks image
        cell_id             % current cell_id to be displayed
        MeanIntensity       % mean intensity of each ROI
        nROIs               % total number of ROIs
        X                   % X coord of each ROI
        Y                   % Y coord of each ROI
        Z                   % Z coord of each ROI
        rated               % logical masks of which ROIs have received a manual rating
        rating              % manual rating of ROIs that have been rated (initialized to for all)
        skipped             % logical masks of which ROIs were skipped (impossible to rate)
        mode                % mode for finding next ROI to rate, 'manual' or 'auto'
        threshHistory       % history of fitted threshold values
        ROI                 % current ROI being plotted
    end
    
    methods (Access = private)
        
        % populate ROI info (X, Y, Z, cell_id, rated, rating, nROIs, and 
        % mean intensity) and rank ROIs by mean intensity
        function pop_rank_ROIs(app)
            
            % extract and set cell ids, nROIs
            app.cell_ids = unique(app.masks);
            app.cell_ids = uint16(app.cell_ids);
            app.cell_ids(app.cell_ids == 0) = [];
            app.nROIs = length(app.cell_ids);

            % initialize rated and rating vector
            app.rated = zeros(app.nROIs,1,'logical');
            app.rating = zeros(app.nROIs,1,'logical');
            app.skipped = zeros(app.nROIs,1,'logical');
            
            % extract centroid and mean intensity of each ROI
            stats = regionprops(app.masks, app.im, ["Centroid", "MeanIntensity"]);
            stats = stats(app.cell_ids);

            % sort by mean intensity and set mean intensity and cell ids
            [B,I] = sort([stats.MeanIntensity]);
            app.MeanIntensity = B;
            app.cell_ids = app.cell_ids(I);
            
            % set each cell's X, Y, and Z
            Centroid = {stats.Centroid};
            app.X = zeros(app.nROIs,1);
            app.Y = zeros(app.nROIs,1);
            app.Z = zeros(app.nROIs,1);

            for i = 1:length(Centroid)
                app.X(i) = round(Centroid{i}(1));
                app.Y(i) = round(Centroid{i}(2));
                app.Z(i) = round(Centroid{i}(3));
            end

            app.X = uint16(app.X(I));
            app.Y = uint16(app.Y(I));
            app.Z = uint16(app.Z(I));
            
        end
        
        function drawROI(app)
            
            idx = find(app.cell_ids == app.cell_id);

            FOV = app.FOVsizeEditField.Value;
            x_idx = app.X(idx)-FOV/2 : app.X(idx)+FOV/2;
            y_idx = app.Y(idx)-FOV/2 : app.Y(idx)+FOV/2;
            app.ROI = app.im(y_idx,x_idx,app.Z(idx));
            app.imageArray(1).CData = app.ROI;

            if ~all(app.ROI == 0)
                colorLims = prctile(app.ROI, [0, 100],'all');
                clim(app.tileAxesArray(1),colorLims);
            else
                clim(app.tileAxesArray(1),[0, 1])
            end
            
            if app.BoundariesCheckBox.Value == true
                delete(app.boundArray(1)) 
                ROI_contour = app.masks(y_idx,x_idx,app.Z(idx));
                ROI_contour = (ROI_contour == app.cell_id);
                B = bwboundaries(ROI_contour,'TraceStyle','pixeledge');
                boundary = B{1};
                app.boundArray(1) = plot(app.tileAxesArray(1),boundary(:,2), boundary(:,1), 'r', 'LineWidth', 1);
            else
                delete(app.boundArray(1)) 
            end

            app.update_ROIsLabel()

        end
        
        % find idx of ROI associated with a given percentile of mean intensity
        function idx = find_idx_p(app, p)

            idx = round(p*0.01*app.nROIs);
            
            % if that ROI has already been rated, find the next closest
            if app.rated(idx) == true
                choices = 1:app.nROIs;
                choices = choices(~app.rated);
                idx = min(abs(choices-idx));
            end
            
        end
        
        function set_next_cell_id(app)

            switch app.mode

                case 'manual'

                    idx = find(app.cell_ids == app.cell_id);
                    previous_p = (idx/app.nROIs) * 100;
                    ratings = app.rating(app.rated);

                    if sum(ratings) == 0  % nothing has yet been rated 1
                        p = ((100 - previous_p) / 2) + previous_p;
                        idx = app.find_idx_p(p);
                        app.cell_id = app.cell_ids(idx);
                        app.drawROI()
                    elseif sum(ratings) > 0 % nothing has yet been rated 0 
                        p = previous_p / 2;
                        idx = app.find_idx_p(p);
                        app.cell_id = app.cell_ids(idx);
                        app.drawROI()
                    end

                case 'auto'
                    
                    % fit logistic regression
                    var = app.MeanIntensity(and(app.rated,~app.skipped));
                    y = app.rating(and(app.rated,~app.skipped));
                    logitCoef = glmfit(var,y,'binomial','logit');
                    var_to_eval = linspace(app.MeanIntensity(1),app.MeanIntensity(end),1000);
                    logitFit = glmval(logitCoef,var_to_eval,'logit');
                    
                    % plot logistic regression
                    for i = 1:5
                        delete(app.logitAxElements{i})
                    end
                    app.logitAxElements{1} = yline(app.logitTileAxes,0,"--",Layer='bottom');
                    app.logitAxElements{2} = yline(app.logitTileAxes,1,"--",Layer='bottom');
                    app.logitAxElements{3} = plot(app.logitTileAxes,var_to_eval,logitFit,'b');
                    app.logitAxElements{4} = scatter(app.logitTileAxes,var(y == 0),y(y == 0),100,'rx');
                    app.logitAxElements{5} = scatter(app.logitTileAxes,var(y == 1),y(y == 1),50,'go',"filled");

                    % find evaluated intensity closest to 50% probability
                    [~,I] = min(abs(logitFit-0.5)); 
                    targetIntensity = var_to_eval(I);
                    app.threshHistory = [app.threshHistory; targetIntensity];
                    delete(app.threshLine)
                    app.threshLine = plot(app.threshTileAxes,1:length(app.threshHistory),app.threshHistory,'b');
                    delete(app.histThreshLine)
                    app.histThreshLine = xline(app.histTileAxes,targetIntensity,'--');
                    
                    % ratio of positive ratings to negative ratings
                    nPos = sum(y == 1);
                    nNeg = sum(y == 0);
                    posNegRatio = nPos / nNeg; 
                    
                    % more than twice as many positive than negative
                    if posNegRatio > 2

                        % find evaluated intensity closest to 0% probability
                        [~,I] = min(abs(logitFit-0)); 
                        targetIntensity = var_to_eval(I);
                    
                    % more than twice as many negative than positive
                    elseif posNegRatio < 0.5

                        % find evaluated intensity closest to 100% probability
                        [~,I] = min(abs(logitFit-1)); 
                        targetIntensity = var_to_eval(I);

                    end
                        
                    % find real intensity closest to target intensity
                    [~,I] = min(abs(app.MeanIntensity - targetIntensity)); 
                    app.cell_id = app.cell_ids(I);

                    % if that ROI has already been rated, find the next closest
                    if app.rated(I) == true
                        choices = 1:app.nROIs;
                        choices = choices(~app.rated);
                        [~,I] = min(abs(choices-I));
                        app.cell_id = app.cell_ids(choices(I));
                    end
                    
                    app.drawROI()

            end
        end
        
        function check_mode(app)

            ratings = app.rating(app.rated);
            
            if range(ratings) == 0 % every rating up to now is the same
                app.mode = 'manual';
            else % at least one rating of 1 and one rating of 0
                delete(app.logitText)
                delete(app.threshText)
                app.mode = 'auto';
            end

        end
        
        function update_ROIsLabel(app)
            
            num_rated = sum(app.rated);
            text = strcat("ROIs rated: ",num2str(num_rated),"/",num2str(app.nROIs));
            app.ROIsLabel.Text = text;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, rois)
            
            % set up paths
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            if nargin < 2
                app.masks = read_tiff('s04_with_overlay_cp_masks.tif');
                app.masks = squeeze(app.masks);
                app.im_f = 'r2_Meis2594.tif';
                app.im = read_tiff(app.im_f);
                app.im = squeeze(app.im);
            end
            
            % disp("Subtracting image background...")
            % bg = pyrunfile("estimateBackground.py","background",data=app.im);
            % bg = double(bg);
            % 
            % app.im = app.im - bg;
            app.im = round(app.im);
            app.im(app.im < 0) = 0;
            app.im = uint16(app.im);
            disp("Background subtraction complete")

            app.im = padarray(app.im, [200 200], 0);
            app.masks = padarray(app.masks, [200 200], 0);

            app.tiledLayout = tiledlayout(app.ROIsPanel,1,1,'TileSpacing','tight','Padding','tight');
            app.tileAxesArray = gobjects(1,1); % Preallocate an array of graphics objects
            app.imageArray = gobjects(1,1); % Preallocate an array of graphics objects
            app.boundArray = gobjects(1,1); % Preallocate an array of graphics objects

            app.tileAxesArray(1) = nexttile(app.tiledLayout);
            colormap(app.tileAxesArray(1),'gray'); % set the colormap to gray
            hold(app.tileAxesArray(1),'on'); % set axes hold state to on (new plots do not reset axes properties)
            axis(app.tileAxesArray(1),'image'); % set axis limits and scaling to image style
            axis(app.tileAxesArray(1),'off'); % turn off axes lines and background visibility

            app.imageArray(1) = imagesc(app.tileAxesArray(1), zeros(app.FOVsizeEditField.Value,app.FOVsizeEditField.Value));
      
            app.pop_rank_ROIs()
            app.mode = 'manual';
            
            % setting up histogram plotting
            app.histTiledLayout = tiledlayout(app.HistPanel,1,1,'TileSpacing','tight','Padding','tight');
            app.histTileAxes = nexttile(app.histTiledLayout);
            hold(app.histTileAxes,'on'); % set axes hold state to on (new plots do not reset axes properties)
            histogram(app.histTileAxes,app.MeanIntensity,100,'EdgeAlpha',0);
            xlim(app.histTileAxes,[0 inf])
            xlabel(app.histTileAxes,'ROI Mean Intensity')
            ylabel(app.histTileAxes, 'Frequency')

            % setting up logistic regression plotting
            app.logitTiledLayout = tiledlayout(app.logitPanel,1,1,'TileSpacing','tight','Padding','tight');
            app.logitTileAxes = nexttile(app.logitTiledLayout);
            hold(app.logitTileAxes,'on'); % set axes hold state to on (new plots do not reset axes properties)
            xlabel(app.logitTileAxes,'ROI Mean Intensity')
            ylabel(app.logitTileAxes, 'Rating')
            xlim(app.logitTileAxes,[app.MeanIntensity(1) app.MeanIntensity(end)])
            ylim(app.logitTileAxes,[-0.2 1.2])
            app.logitText = text(app.logitTileAxes,0.5,0.5,['Not enough manually rated ROIs for' newline 'logistic regression'], ...
                                'Units','normalized','HorizontalAlignment','center','FontSize',8);
            app.logitAxElements = cell(5,1);

            % setting up threshold history plotting
            app.threshTiledLayout = tiledlayout(app.threshPanel,1,1,'TileSpacing','tight','Padding','tight');
            app.threshTileAxes = nexttile(app.threshTiledLayout);
            hold(app.threshTileAxes,'on'); % set axes hold state to on (new plots do not reset axes properties)
            app.threshText = text(app.threshTileAxes,0.5,0.5,['Not enough manually rated ROIs for' newline 'logistic regression'], ...
                                'Units','normalized','HorizontalAlignment','center','FontSize',8);
            xlabel(app.threshTileAxes,'Iterations')
            ylabel(app.threshTileAxes,'Threshold Mean Intensity')
            xlim(app.threshTileAxes,[1 inf])
            ylim(app.threshTileAxes,[0 inf])
            app.threshHistory = [];

            idx = app.find_idx_p(50);
            app.cell_id = app.cell_ids(idx);
            app.drawROI()
            app.update_ROIsLabel()

        end

        % Value changed function: BoundariesCheckBox
        function BoundariesCheckBoxValueChanged(app, event)
            app.drawROI()
        end

        % Button pushed function: yButton
        function yButtonPushed(app, event)
            idx = find(app.cell_ids == app.cell_id);
            app.rated(idx) = true;
            app.rating(idx) = true;

            if strcmp(app.mode,'manual')
                app.check_mode()
            end
            
            app.set_next_cell_id()
        end

        % Button pushed function: nButton
        function nButtonPushed(app, event)
            idx = find(app.cell_ids == app.cell_id);
            app.rated(idx) = true;
            app.rating(idx) = false;

            if strcmp(app.mode,'manual')
                app.check_mode()
            end
  
            app.set_next_cell_id()
        end

        % Value changed function: CLimSlider
        function CLimSliderValueChanged(app, event)
            value = app.CLimSlider.Value;
            if ~all(app.ROI == 0)
                colorLims = prctile(app.ROI, value,'all');
                clim(app.tileAxesArray(1),colorLims);
            else
                clim(app.tileAxesArray(1),[0, 1])
            end
            
        end

        % Button pushed function: resetClimButton
        function resetClimButtonPushed(app, event)
            if ~all(app.ROI == 0)
                colorLims = prctile(app.ROI, [0, 100],'all');
                clim(app.tileAxesArray(1),colorLims);
            else
                clim(app.tileAxesArray(1),[0, 1])
            end

            app.CLimSlider.Value = [0, 100];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % fit logistic regression
            var = app.MeanIntensity(and(app.rated,~app.skipped));
            y = app.rating(and(app.rated,~app.skipped));
            logitCoef = glmfit(var,y,'binomial','logit');
            logitFit = glmval(logitCoef,app.MeanIntensity,'logit');
            scores = (logitFit > 0.5);
            A = horzcat(app.cell_ids,scores);
            filename = split(app.im_f,'.');
            filename = filename{1};
            filename = strcat(filename, '.txt');
            writematrix(A,filename)
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            idx = find(app.cell_ids == app.cell_id);
            app.rated(idx) = true;
            app.skipped(idx) = true;

            if strcmp(app.mode,'manual')
                app.check_mode()
            end
  
            app.set_next_cell_id()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ROIsPanel = uipanel(app.UIFigure);
            app.CLimSliderLabel = uilabel(app.UIFigure);
            app.CLimSliderLabel.Text = 'CLim:';
            app.CLimSlider = uislider(app.UIFigure, 'range');
            app.CLimSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.CLimSlider.ValueChangedFcn = createCallbackFcn(app, @CLimSliderValueChanged, true);
            app.CLimSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.CLimSlider.Value = [0.1 99.9];
            app.FOVsizeEditFieldLabel = uilabel(app.UIFigure);
            app.FOVsizeEditFieldLabel.Text = 'FOV size:';
            app.FOVsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVsizeEditField.Value = 100;
            app.yButton = uibutton(app.UIFigure, 'push');
            app.yButton.ButtonPushedFcn = createCallbackFcn(app, @yButtonPushed, true);
            app.yButton.Text = '+ (y)';
            app.nButton = uibutton(app.UIFigure, 'push');
            app.nButton.ButtonPushedFcn = createCallbackFcn(app, @nButtonPushed, true);
            app.nButton.Text = '- (n)';
            app.BoundariesCheckBox = uicheckbox(app.UIFigure);
            app.BoundariesCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundariesCheckBoxValueChanged, true);
            app.BoundariesCheckBox.Text = 'Display cell boundaries';
            app.BoundariesCheckBox.Value = true;
            app.HistPanel = uipanel(app.UIFigure);
            app.logitPanel = uipanel(app.UIFigure);
            app.resetClimButton = uibutton(app.UIFigure, 'push');
            app.resetClimButton.ButtonPushedFcn = createCallbackFcn(app, @resetClimButtonPushed, true);
            app.resetClimButton.Text = 'Reset CLim';
            app.threshPanel = uipanel(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ROIsLabel = uilabel(app.UIFigure);
            app.ROIsLabel.Text = 'ROIs rated: ';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setZero(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end